classdef showtraces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        closeButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            global showtracesAxes imageData
            
            app.MainApp = MainApp;
            showtracesAxes = app.UIAxes;
            imageData = MainApp.ImageData;
            draggable(app.MainApp.HRectangle,'endfcn',@updatetraces)
            
        end

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            UIFigureCloseRequest(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global showtracesAxes imageData
            if isvalid(app.MainApp)
                delete(app.MainApp.HRectangle)
            end
            imageData = [];
            showtracesAxes = [];
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'fluorescence intensity')
            xlabel(app.UIAxes, 'frames')
            ylabel(app.UIAxes, 'intensity')
            zlabel(app.UIAxes, 'Z')
            app.closeButton = uibutton(app.UIFigure, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Text = 'close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = showtraces(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end