classdef detect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        detectFigure                    matlab.ui.Figure
        DETECTLabel                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        loadimagesTab                   matlab.ui.container.Tab
        saveROIButton                   matlab.ui.control.Button
        usesavedROIButton               matlab.ui.control.Button
        filenameEditField               matlab.ui.control.EditField
        filenameEditFieldLabel          matlab.ui.control.Label
        typeoffilesDropDown             matlab.ui.control.DropDown
        typeoffilesDropDownLabel        matlab.ui.control.Label
        saveasaviButton                 matlab.ui.control.Button
        bitsEditField                   matlab.ui.control.NumericEditField
        bitsEditFieldLabel              matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        adjustcontrastButton            matlab.ui.control.Button
        acceptROIButton                 matlab.ui.control.Button
        previewButton                   matlab.ui.control.Button
        framesEditField                 matlab.ui.control.EditField
        framesEditFieldLabel            matlab.ui.control.Label
        useallframesCheckBox            matlab.ui.control.CheckBox
        framerateEditField              matlab.ui.control.NumericEditField
        framerateEditFieldLabel         matlab.ui.control.Label
        clearLoadButton                 matlab.ui.control.Button
        addROIButton                    matlab.ui.control.Button
        folderlocationEditField         matlab.ui.control.EditField
        folderlocationEditFieldLabel    matlab.ui.control.Label
        foldernameEditField             matlab.ui.control.EditField
        foldernameEditFieldLabel        matlab.ui.control.Label
        chooseButton                    matlab.ui.control.Button
        UIAxesLoad                      matlab.ui.control.UIAxes
        motioncorrectionTab             matlab.ui.container.Tab
        correctedMCfolderEditField      matlab.ui.control.EditField
        originalMCfolderEditField       matlab.ui.control.EditField
        chooseMCfolderButton_2          matlab.ui.control.Button
        originalfilesListBox            matlab.ui.control.ListBox
        originalfilesListBoxLabel       matlab.ui.control.Label
        chooseMCfolderButton            matlab.ui.control.Button
        playvideosButton                matlab.ui.control.Button
        correctedfilesListBox           matlab.ui.control.ListBox
        correctedfilesListBoxLabel      matlab.ui.control.Label
        warpparametersPanel             matlab.ui.container.Panel
        Info_22                         matlab.ui.control.Button
        Info_21                         matlab.ui.control.Button
        Info_20                         matlab.ui.control.Button
        blocksizeEditField              matlab.ui.control.NumericEditField
        blocksizeEditFieldLabel         matlab.ui.control.Label
        movingaveragesizeEditField      matlab.ui.control.NumericEditField
        movingaveragesizeEditFieldLabel  matlab.ui.control.Label
        affineradiusEditField           matlab.ui.control.NumericEditField
        affineradiusEditFieldLabel      matlab.ui.control.Label
        rigidparametersPanel            matlab.ui.container.Panel
        Info_19                         matlab.ui.control.Button
        Info_18                         matlab.ui.control.Button
        Info_17                         matlab.ui.control.Button
        Info_16                         matlab.ui.control.Button
        Info_15                         matlab.ui.control.Button
        fftdenoiseDropDown              matlab.ui.control.DropDown
        fftdenoiseDropDownLabel         matlab.ui.control.Label
        centralfractionEditField        matlab.ui.control.NumericEditField
        centralfractionEditFieldLabel   matlab.ui.control.Label
        thresholdEditField              matlab.ui.control.NumericEditField
        thresholdEditFieldLabel         matlab.ui.control.Label
        normalizationradiusEditField    matlab.ui.control.NumericEditField
        normalizationradiusEditFieldLabel  matlab.ui.control.Label
        normalizationmethodDropDown     matlab.ui.control.DropDown
        normalizationmethodDropDownLabel  matlab.ui.control.Label
        warpcorrectionDropDown          matlab.ui.control.DropDown
        warpcorrectionDropDownLabel     matlab.ui.control.Label
        correctedLabel                  matlab.ui.control.Label
        originalLabel                   matlab.ui.control.Label
        acceptcorrectfilesButton        matlab.ui.control.Button
        runmotioncorrectionButton       matlab.ui.control.Button
        motioncorrectionEditField       matlab.ui.control.EditField
        specifyavifolderButton          matlab.ui.control.Button
        UIAxesMC1                       matlab.ui.control.UIAxes
        UIAxesMC2                       matlab.ui.control.UIAxes
        imageregistrationTab            matlab.ui.container.Tab
        calculateimageregistrationPanel  matlab.ui.container.Panel
        registrationresultsEditField    matlab.ui.control.EditField
        calculateregistrationButton     matlab.ui.control.Button
        targetfolderEditField           matlab.ui.control.EditField
        referencefolderEditField        matlab.ui.control.EditField
        choosetargetfolderButton        matlab.ui.control.Button
        choosereferencefolderButton     matlab.ui.control.Button
        applyregistrationparametersPanel  matlab.ui.container.Panel
        framerateregistrationEditField  matlab.ui.control.NumericEditField
        framerateEditField_2Label       matlab.ui.control.Label
        filetoberegisteredEditBox       matlab.ui.control.EditField
        specifyfiletoberegisteredButton  matlab.ui.control.Button
        runregistrationalgorithmButton  matlab.ui.control.Button
        registrationparametersEditField  matlab.ui.control.EditField
        loadregistrationparametersButton  matlab.ui.control.Button
        initialconditionsTab            matlab.ui.container.Tab
        Info_30                         matlab.ui.control.Button
        Info_29                         matlab.ui.control.Button
        Info_12                         matlab.ui.control.Button
        Info_11                         matlab.ui.control.Button
        Info_10                         matlab.ui.control.Button
        Info_9                          matlab.ui.control.Button
        Info_8                          matlab.ui.control.Button
        Info_7                          matlab.ui.control.Button
        Info_6                          matlab.ui.control.Button
        Info_5                          matlab.ui.control.Button
        Info_4                          matlab.ui.control.Button
        Info_3                          matlab.ui.control.Button
        Info_2                          matlab.ui.control.Button
        Info_1                          matlab.ui.control.Button
        loadparametersButton            matlab.ui.control.Button
        saveparametersButton            matlab.ui.control.Button
        SNRthresholdDropDown            matlab.ui.control.DropDown
        SNRthresholdDropDownLabel       matlab.ui.control.Label
        backgroundcomponentsDropDown    matlab.ui.control.DropDown
        backgroundcomponentsDropDownLabel  matlab.ui.control.Label
        mergingthresholdEditField       matlab.ui.control.NumericEditField
        mergingthresholdEditFieldLabel  matlab.ui.control.Label
        fudgefactorEditField            matlab.ui.control.NumericEditField
        fudgefactorEditFieldLabel       matlab.ui.control.Label
        temporaliterationsDropDown      matlab.ui.control.DropDown
        temporaliterationsDropDownLabel  matlab.ui.control.Label
        deconvolutionmethodDropDown     matlab.ui.control.DropDown
        deconvolutionmethodDropDownLabel  matlab.ui.control.Label
        ellipseexpansionfactorDropDown  matlab.ui.control.DropDown
        ellipseexpansionfactorDropDownLabel  matlab.ui.control.Label
        searchmethodDropDown            matlab.ui.control.DropDown
        searchmethodDropDownLabel       matlab.ui.control.Label
        initializationmethodDropDown    matlab.ui.control.DropDown
        initializationmethodLabel       matlab.ui.control.Label
        temporaldownsamplingDropDown    matlab.ui.control.DropDown
        temporaldownsamplingLabel       matlab.ui.control.Label
        spatialdownsamplingDropDown     matlab.ui.control.DropDown
        spatialdownsamplingLabel        matlab.ui.control.Label
        pDropDown                       matlab.ui.control.DropDown
        orderofARdynamicsLabel          matlab.ui.control.Label
        typicaldiameterpixelsEditField  matlab.ui.control.NumericEditField
        typicaldiameterpixelsEditFieldLabel  matlab.ui.control.Label
        estimatednumberEditField        matlab.ui.control.NumericEditField
        estimatednumberEditFieldLabel   matlab.ui.control.Label
        avifolderEditField              matlab.ui.control.EditField
        avifolderEditFieldLabel         matlab.ui.control.Label
        avifilenameEditField            matlab.ui.control.EditField
        avifilenameLabel                matlab.ui.control.Label
        chooseaviButton                 matlab.ui.control.Button
        UIAxesInitial                   matlab.ui.control.UIAxes
        CNMFalgorithmTab                matlab.ui.container.Tab
        choosedefaultCNMFfileButton     matlab.ui.control.Button
        applytootherAVIfilesButton      matlab.ui.control.Button
        CNMFRunTimeEditField            matlab.ui.control.NumericEditField
        TimesecLabel                    matlab.ui.control.Label
        PercentcompleteLabel            matlab.ui.control.Label
        CNMFGuage                       matlab.ui.control.Gauge
        GaugeLabel                      matlab.ui.control.Label
        classifyneuronsLabel            matlab.ui.control.Label
        saveButton                      matlab.ui.control.Button
        resetButton                     matlab.ui.control.Button
        constructtracesLabel            matlab.ui.control.Label
        updatetemporalcomponentsIILabel  matlab.ui.control.Label
        updatespatialcomponentsIILabel  matlab.ui.control.Label
        updatetemporalcomponentsILabel  matlab.ui.control.Label
        updatespatialcomponentsILabel   matlab.ui.control.Label
        initializecomponentsLabel       matlab.ui.control.Label
        preprocessdataLabel             matlab.ui.control.Label
        loadimagesLabel                 matlab.ui.control.Label
        detectionparametersfileEditField  matlab.ui.control.EditField
        detectionparametersfileEditFieldLabel  matlab.ui.control.Label
        avifileEditField                matlab.ui.control.EditField
        avifileEditFieldLabel           matlab.ui.control.Label
        chooseparametersfileButton      matlab.ui.control.Button
        chooseavifileButton             matlab.ui.control.Button
        runButton                       matlab.ui.control.Button
        refineresultsTab                matlab.ui.container.Tab
        clicktoselectSwitch             matlab.ui.control.Switch
        clicktoselectSwitchLabel        matlab.ui.control.Label
        acceptmanualcellButton          matlab.ui.control.Button
        placeacellmanuallyButton        matlab.ui.control.Button
        qualitycontrolPanel             matlab.ui.container.Panel
        pixelsLabel                     matlab.ui.control.Label
        bordersizeEditField             matlab.ui.control.NumericEditField
        bordersizeEditFieldLabel        matlab.ui.control.Label
        secLabel                        matlab.ui.control.Label
        lowesssmoothingEditField        matlab.ui.control.NumericEditField
        lowesssmoothingEditFieldLabel   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        mintransientsizeEditField       matlab.ui.control.NumericEditField
        mintransientsizeEditFieldLabel  matlab.ui.control.Label
        minnumtransientsEditField       matlab.ui.control.NumericEditField
        minnumtransientsEditFieldLabel  matlab.ui.control.Label
        minLabel                        matlab.ui.control.Label
        applyQCCheckBox                 matlab.ui.control.CheckBox
        maxbleachrateEditField          matlab.ui.control.NumericEditField
        maxbleachrateEditFieldLabel     matlab.ui.control.Label
        resultsfilenameEditField        matlab.ui.control.EditField
        filenameEditField_2Label        matlab.ui.control.Label
        resetButton_2                   matlab.ui.control.Button
        savechangesButton_2             matlab.ui.control.Button
        refineButtonGroup               matlab.ui.container.ButtonGroup
        rejectButton                    matlab.ui.control.ToggleButton
        acceptButton                    matlab.ui.control.ToggleButton
        cellnumberDropDown1             matlab.ui.control.Spinner
        cellnumberLabel                 matlab.ui.control.Label
        loadresultsButton               matlab.ui.control.Button
        UIAxesRefine2                   matlab.ui.control.UIAxes
        UIAxesRefine1                   matlab.ui.control.UIAxes
        examineresultsTab               matlab.ui.container.Tab
        zscoreviewButton                matlab.ui.control.StateButton
        resultsfilename2EditField       matlab.ui.control.EditField
        filenameEditField_2Label_2      matlab.ui.control.Label
        examineresults                  matlab.ui.control.Button
        UIAxesExamine2                  matlab.ui.control.UIAxes
        UIAxesExamine1                  matlab.ui.control.UIAxes
        basicanalysisTab                matlab.ui.container.Tab
        meanvaluesLabel                 matlab.ui.control.Label
        transientITIEditField           matlab.ui.control.NumericEditField
        intertransientintervalEditFieldLabel  matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        tracesTab                       matlab.ui.container.Tab
        UIAxesBasic                     matlab.ui.control.UIAxes
        histogramsTab                   matlab.ui.container.Tab
        UIAxesITI                       matlab.ui.control.UIAxes
        UIAxesWidth                     matlab.ui.control.UIAxes
        UIAxesAmplitude                 matlab.ui.control.UIAxes
        UIAxesNumber                    matlab.ui.control.UIAxes
        transientwidthEditField         matlab.ui.control.NumericEditField
        transientwidthEditFieldLabel    matlab.ui.control.Label
        transientamplitudeEditField     matlab.ui.control.NumericEditField
        transientamplitudeEditFieldLabel  matlab.ui.control.Label
        transientnumberEditField        matlab.ui.control.NumericEditField
        transientnumberEditFieldLabel   matlab.ui.control.Label
        displaytraceSpinner             matlab.ui.control.Spinner
        displaytraceSpinnerLabel        matlab.ui.control.Label
        savechangesButton_4             matlab.ui.control.Button
        minthresholdEditField           matlab.ui.control.NumericEditField
        minthresholdEditFieldLabel      matlab.ui.control.Label
        detectionthresholdDropDown      matlab.ui.control.DropDown
        detectionthresholdDropDownLabel  matlab.ui.control.Label
        basicanalysisfilenameEditField  matlab.ui.control.EditField
        filenameEditField_2Label_8      matlab.ui.control.Label
        loadanalysisresults             matlab.ui.control.Button
        analyzeallButton                matlab.ui.control.Button
        pairwisecorrelationsTab         matlab.ui.container.Tab
        zoomwindowButton_3              matlab.ui.control.Button
        PairwiseSlider                  matlab.ui.control.Slider
        cutoffLabel_2                   matlab.ui.control.Label
        pairwisefilename                matlab.ui.control.EditField
        filenameEditField_2Label_4      matlab.ui.control.Label
        saveButton_3                    matlab.ui.control.Button
        runPairwiseButton               matlab.ui.control.Button
        loadpairwisefileButton          matlab.ui.control.Button
        UIAxesPairwise3                 matlab.ui.control.UIAxes
        UIAxesPairwise2                 matlab.ui.control.UIAxes
        UIAxesPairwise1                 matlab.ui.control.UIAxes
        crosscorrelationTab             matlab.ui.container.Tab
        calculatecrosscorrelationButton  matlab.ui.control.Button
        neuron2ListBox                  matlab.ui.control.ListBox
        neuron2ListBoxLabel             matlab.ui.control.Label
        zoomwindowButton_4              matlab.ui.control.Button
        saveButton_5                    matlab.ui.control.Button
        emdfilename_2                   matlab.ui.control.EditField
        filenameEditField_2Label_7      matlab.ui.control.Label
        loadfileButton_2                matlab.ui.control.Button
        neuron1ListBox                  matlab.ui.control.ListBox
        neuron1ListBoxLabel             matlab.ui.control.Label
        UIAxesCross2                    matlab.ui.control.UIAxes
        UIAxesCross1                    matlab.ui.control.UIAxes
        EMDclusteringTab                matlab.ui.container.Tab
        zoomwindowButton_5              matlab.ui.control.Button
        saveButton_2                    matlab.ui.control.Button
        EMDSlider                       matlab.ui.control.Slider
        cutoffLabel                     matlab.ui.control.Label
        runEMDButton                    matlab.ui.control.Button
        emdfilename                     matlab.ui.control.EditField
        filenameEditField_2Label_3      matlab.ui.control.Label
        loadfileButton                  matlab.ui.control.Button
        UIAxesEMD3                      matlab.ui.control.UIAxes
        UIAxesEMD2                      matlab.ui.control.UIAxes
        UIAxesEMD1                      matlab.ui.control.UIAxes
        waterfallplotsTab               matlab.ui.container.Tab
        zoomwindowButton                matlab.ui.control.Button
        classificationtypeDropDown      matlab.ui.control.DropDown
        classificationtypeDropDownLabel  matlab.ui.control.Label
        waterfallfilename               matlab.ui.control.EditField
        filenameEditField_2Label_5      matlab.ui.control.Label
        loadwaterfallButton             matlab.ui.control.Button
        UIAxesWaterfall2                matlab.ui.control.UIAxes
        UIAxesWaterfall1                matlab.ui.control.UIAxes
        spikeestimationTab              matlab.ui.container.Tab
        zoomwindowButton_2              matlab.ui.control.Button
        spiketrainmetricsPanel          matlab.ui.container.Panel
        timescaleEditField              matlab.ui.control.NumericEditField
        timescaleEditFieldLabel         matlab.ui.control.Label
        runspiketrainmetricButton       matlab.ui.control.Button
        metricDropDown                  matlab.ui.control.DropDown
        metricDropDownLabel             matlab.ui.control.Label
        saveButton_4                    matlab.ui.control.Button
        MLSpikePanel                    matlab.ui.container.Panel
        Info_31                         matlab.ui.control.Button
        autoMLButton                    matlab.ui.control.Button
        Info_28                         matlab.ui.control.Button
        Info_27                         matlab.ui.control.Button
        baselinemaxEditField            matlab.ui.control.NumericEditField
        baselinemaxEditFieldLabel       matlab.ui.control.Label
        baselineminEditField            matlab.ui.control.NumericEditField
        baselineminEditFieldLabel       matlab.ui.control.Label
        runMLSpikeButton                matlab.ui.control.Button
        Info_26                         matlab.ui.control.Button
        Info_25                         matlab.ui.control.Button
        Info_24                         matlab.ui.control.Button
        Info_23                         matlab.ui.control.Button
        nonlinearityDropDown            matlab.ui.control.DropDown
        nonlinearityDropDownLabel       matlab.ui.control.Label
        noisesigmaEditField             matlab.ui.control.NumericEditField
        noisesigmaEditFieldLabel        matlab.ui.control.Label
        decaytimetauEditField           matlab.ui.control.NumericEditField
        decaytimetauEditFieldLabel      matlab.ui.control.Label
        AmplitudeAEditField             matlab.ui.control.NumericEditField
        AmplitudeAEditFieldLabel        matlab.ui.control.Label
        inferredfilename                matlab.ui.control.EditField
        filenameEditField_2Label_6      matlab.ui.control.Label
        loadinferredButton              matlab.ui.control.Button
        UIAxesSpikes3                   matlab.ui.control.UIAxes
        UIAxesSpikes2                   matlab.ui.control.UIAxes
        UIAxesSpikes1                   matlab.ui.control.UIAxes
        exportTab                       matlab.ui.container.Tab
        savedirectoryLabel              matlab.ui.control.Label
        savedirectoryEditField          matlab.ui.control.EditField
        analysisPanel                   matlab.ui.container.Panel
        exporttoCSVButton               matlab.ui.control.Button
        exporttoEXCELButton             matlab.ui.control.Button
        exporttoMATButton               matlab.ui.control.Button
        figuresPanel                    matlab.ui.container.Panel
        exporttoPNGButton               matlab.ui.control.Button
        exporttoEMFButton               matlab.ui.control.Button
        exporttoTIFFButton              matlab.ui.control.Button
    end


    properties (Access = private)
        SelectedPath                    
        nImages                         
        Frames                          
        FramesField
        ROIpresent
        MeanImage
        ROInumber
        Mask
        ROI
        DetectionParameters
        AddedNeurons
        NeuronToAdd
        Fs
        NewNeuroF
        NeuronToSubtract
        NeuronSubtractIdx 
        OutOriginal
        IsTiffStacked
        SelectedFile
        TrialNumIdx
        Truncate
        AcceptROI
        NumberOfTraces
        HighlightedNeuron
        SelectedNeurons 
        EMD 
        Pairwise
        PairwiseColors
        EMDColors
        ROICenter
        ROIHandle
        StopPlayback
        FileMeanTarget
        FileMaxTarget
        FileMeanReference
        FileMaxReference
        FailedQC
        SpikeEstimate
        VictorPurpura
        VanRossum
        FTemp
        RefinePlotHandle
        MainAppFolder
        ROIHandle2
        parML
        Transients
    end

    properties (Access = public)
        HRectangle
        ImageData
        WaterfallColors
        Out
        ZoomWindow = [];
        LeafOrder
        InfoApp
        DetectFolder
    end


    methods (Access = private)

        function [] = enableLoadButtons1(app)
            app.framerateEditField.Enable = true;
            app.framerateEditFieldLabel.Enable = true;
            app.useallframesCheckBox.Enable = true;
            app.framerateEditFieldLabel.Enable = true;
            app.framesEditField.Enable = false;
            app.framesEditFieldLabel.Enable = true;
            app.previewButton.Enable = true;
            app.clearLoadButton.Enable = true;
        end

        function [] = enableLoadButtons2(app,onoff)
            % onoff = true or false (boolean)
            app.saveasaviButton.Enable = onoff;
            app.addROIButton.Enable = onoff;
            app.acceptROIButton.Enable = onoff;
            app.minEditField.Enable = onoff;
            app.minEditFieldLabel.Enable = onoff;
            app.maxEditField.Enable = onoff;
            app.maxEditFieldLabel.Enable = onoff;
            app.adjustcontrastButton.Enable = onoff;
            app.usesavedROIButton.Enable = onoff;
            app.saveROIButton.Enable = onoff;
        end
        
        function [] = setlabelcolorstoblack(app)
            
            app.loadimagesLabel.FontColor = [0 0 0];
            app.preprocessdataLabel.FontColor = [0 0 0];
            app.initializecomponentsLabel.FontColor = [0 0 0];
            app.updatespatialcomponentsILabel.FontColor = [0 0 0];
            app.updatetemporalcomponentsILabel.FontColor = [0 0 0];
            app.classifyneuronsLabel.FontColor = [0 0 0];
            app.updatespatialcomponentsIILabel.FontColor = [0 0 0];
            app.updatetemporalcomponentsIILabel.FontColor = [0 0 0];
            app.constructtracesLabel.FontColor = [0 0 0];            
            drawnow
            
        end
        
        function [] = neuronadded(app,source,~)
            mask = createMask(source);
            nFrames = size(app.ImageData,3);
            F_raw = zeros(1,nFrames);
            
            for jj = 1:nFrames
                img1 = single(app.ImageData(:,:,jj));
                img1 = mask.*img1;
                F_raw(jj) = mean(img1(:));
            end
            F_raw = calculatedeltaFoverF(app,F_raw);
            plot(app.UIAxesRefine2,app.Out.t,F_raw)

            
        end
        
        function [] = loadavifile(app)
            
            vidObj = VideoReader(app.Out.avifile);
            app.ImageData = zeros(vidObj.Height,vidObj.Width,vidObj.NumFrames,'single');
            ii = 0;
            jj = 0;
            h = waitbar(0,'loading video ... please wait');
            hIncrement = floor(vidObj.NumFrames/10);
            while hasFrame(vidObj)
                ii = ii + 1;
                jj = jj + 1;
                img = readFrame(vidObj);
                img = single(img(:,:,1));
                app.ImageData(:,:,ii) = img;
                if jj==hIncrement
                    waitbar(ii/vidObj.NumFrames,h);
                    jj = 0;
                end
            end
            delete(h)
            app.Fs = vidObj.FrameRate;
            delete(vidObj)

        end
        
        function [y] = movesubtract(app,roi)
           
            circleCenter = roi.Center;
            foo = app.Out.ROI_center - circleCenter;
            foo = foo(:,1).^2 + foo(:,2).^2;
            [~,y] = min(foo);
            roi.Center = app.Out.ROI_center(y,:);
            neuronadded(app,roi)
            app.NeuronSubtractIdx = y;
            
        end
        
        function [] = truncatemeanimage(app)
            
            enableLoadButtons2(app,false)
            app.saveasaviButton.Enable = true;
            r = any(app.MeanImage,1);
            r = find(r);
            c = any(app.MeanImage,2);
            c = find(c);
            app.ImageData = app.ImageData(c(1):c(end),:,:);
            app.ImageData = app.ImageData(:,r(1):r(end),:);
            img = mean(app.ImageData,3);
            app.ImageData = app.ImageData - app.minEditField.Value;
            app.ImageData = app.ImageData/(app.maxEditField.Value - app.minEditField.Value);
            app.ImageData(app.ImageData<0) = 0;
            app.ImageData(app.ImageData>1) = 1;
            img = img - app.minEditField.Value;
            img = img / (app.maxEditField.Value-app.minEditField.Value);
            img(img<0) = 0;
            img(img>1) = 1;
            app.MeanImage = img;
            imshow(app.MeanImage,'Parent',app.UIAxesLoad)
            app.Truncate.r1 = r(1);
            app.Truncate.r2 = r(end);
            app.Truncate.c1 = c(1);
            app.Truncate.c2 = c(end);

        end
        
        function []= plotcontours(app,ax)
            if isempty(app.Out), return, end
            cla(ax)
            imshow(app.Out.meanImage,'Parent',ax)
            hold(ax,"on")
            if size(app.Out.ROI_center,1)>0
                app.ROICenter = plot(ax,app.Out.ROI_center(:,1),app.Out.ROI_center(:,2),'go','markersize',6,'markerfacecolor','g');
                app.cellnumberDropDown1.Value = 1;
                if ax==app.UIAxesRefine1
                    app.RefinePlotHandle = app.ROICenter;
                end
            end
            CC = app.Out.CC;
            nROI = numel(CC);
            col = parula(nROI);
            for ii = 1:nROI
                idx = find(CC{ii}(1,2:end)<1);
                CC{ii}(:,idx+1) = CC{ii}(:,idx+2);
                hh = fill(ax,CC{ii}(1,2:end),CC{ii}(2,2:end),col(ii,:),'FaceAlpha',0.2);
                hh.EdgeColor = hh.FaceColor;
                hh.EdgeAlpha = hh.FaceAlpha;
            end
        end
        
        function [] = plotemdlinkage(app,value)
            
            Z = linkage(app.EMD,'average');
            D = pdist(app.EMD);
            leafOrder = optimalleaforder(Z,D);
            if exist('value','Var')
                cutoff = value;
            else
                cutoff = median(Z(:,3));
                app.EMDSlider.Limits = [0,ceil(max(Z(:,3)))];
                app.EMDSlider.Value = cutoff;
            end
            
            axes(app.UIAxesEMD2)
            [H,~,M] = dendrogram(app.UIAxesEMD2,Z,0,'Reorder',leafOrder,'ColorThreshold',cutoff);
            linesColor = cell2mat(get(H,'Color')); % get lines color;
            app.EMDColors= zeros(numel(M),3);
            for ii = 1:numel(M)
                [iRow,~] = find(Z==ii);
                col = linesColor(iRow,:);
                plot(app.UIAxesEMD1,app.Out.ROI_center(ii,1),app.Out.ROI_center(ii,2),'o','Color',col,'markersize',6,'markerfacecolor',col)
                app.EMDColors(ii,:) = col;
            end

            axes(app.UIAxesEMD3)
            cla(app.UIAxesEMD3)
            r = app.EMD;
            r = r(leafOrder,leafOrder);
            labels = string(leafOrder);
            n = size(r, 1);
            y = triu(repmat(n+1, n, n) - (1:n)') + 0.5;
            x = triu(repmat(1:n, n, 1)) + 0.5;
            x(x == 0.5) = NaN;
            % cols = repmat(app.EMDColors(leafOrder,:),24,1);
            sz = max((200/max(r(:)))*abs(r(:)),0.1);
            scatter(x(:), y(:),sz,r(:),'filled', 'MarkerFaceAlpha', 0.6)

            % enclose markers in a grid
            xl = [1:n+1;repmat(n+1, 1, n+1)];
            xl = [xl(:, 1), xl(:, 1:end-1)];
            yl = repmat(n+1:-1:1, 2, 1);
            line(xl, yl, 'color', 'k') % horizontal lines
            line(yl, xl, 'color', 'k') % vertical lines
            % show labels
            text((1:n)-0.5, (n:-1:1) + 0.5, labels, 'HorizontalAlignment', 'right')
            text((1:n) + 0.5, repmat(n + 1.5, n, 1), labels, ...
                'HorizontalAlignment', 'right', 'Rotation', 270)
            h = app.UIAxesEMD3;
            h.Visible = 'off';
            axis(h, 'equal')
            colorbar(h)
            colormap(h,'parula')

            app.classificationtypeDropDown.Value = 'EMD';
            plotwaterfall(app)
            
        end

        function [] = plotpairwiselinkage(app,value)

            Z = linkage(app.Pairwise,'average');
            D = pdist(app.Pairwise);
            leafOrder = optimalleaforder(Z,D);
            if exist('value','Var')
                cutoff = value;
            else
                app.PairwiseSlider.Limits = [0, ceil(max(Z(:,3)))];
                cutoff = round(app.PairwiseSlider.Limits(2)/2);
                app.PairwiseSlider.Value = cutoff;
            end

            axes(app.UIAxesPairwise2)
            [H,~,M] = dendrogram(Z,0,'Reorder',leafOrder,'ColorThreshold',cutoff);
            linesColor = cell2mat(get(H,'Color')); % get lines color;
            app.PairwiseColors = zeros(numel(M),3);
            for ii = 1:numel(M)
                [iRow,~] = find(Z==ii);
                col = linesColor(iRow,:);
                plot(app.UIAxesPairwise1,app.Out.ROI_center(ii,1),app.Out.ROI_center(ii,2),'o','Color',col,'markersize',6,'markerfacecolor',col)
                app.PairwiseColors(ii,:) = col;
            end

            axes(app.UIAxesPairwise3)
            cla(app.UIAxesPairwise3)
            r = app.Pairwise;
            r = r(leafOrder,leafOrder);
            labels = string(leafOrder);
            n = size(r, 1);
            y = triu(repmat(n+1, n, n) - (1:n)') + 0.5;
            x = triu(repmat(1:n, n, 1)) + 0.5;
            x(x == 0.5) = NaN;
            sz = max((200/max(abs(r(:))))*abs(r(:)),0.1);
            scatter(x(:), y(:),sz, r(:),'filled', 'MarkerFaceAlpha', 0.6)

            % enclose markers in a grid
            xl = [1:n+1;repmat(n+1, 1, n+1)];
            xl = [xl(:, 1), xl(:, 1:end-1)];
            yl = repmat(n+1:-1:1, 2, 1);
            line(xl, yl, 'color', 'k') % horizontal lines
            line(yl, xl, 'color', 'k') % vertical lines
            % show labels
            text((1:n)-0.5, (n:-1:1) + 0.5, labels, 'HorizontalAlignment', 'right')
            text((1:n) + 0.5, repmat(n + 1.5, n, 1), labels, ...
                'HorizontalAlignment', 'right', 'Rotation', 270)
            h = app.UIAxesPairwise3;
            h.Visible = 'off';
            axis(h, 'equal')
            colorbar(h)
            colormap(h,'parula')

            app.classificationtypeDropDown.Value = 'pairwise correlation';
            plotwaterfall(app)

        end
     
        function [] = plotwaterfall(app)
            
            type1 = app.classificationtypeDropDown.Value;
            N = size(app.Out.F_raw,1);
            try
                switch type1
                    case 'none'
                        leafOrder = 1:N;
                    case 'pairwise correlation'
                        data = app.Out.Pairwise;
                    case 'EMD'
                        data = app.Out.EMD;
                end
            catch
                disp('Run pairwise or EMD before asking for a waterfall plot.')
                app.classificationtypeDropDown.Value = 'none';
                type1 = 'none';
            end

            if ~strcmp(type1,'none')
                Z = linkage(data,'average');
                D = pdist(data);
                leafOrder = optimalleaforder(Z,D);
            end

            Y = app.Out.F_raw;
            Y(Y<0) = 0;
            t = (1:length(Y))/app.Out.fps;
            p = waterfall(app.UIAxesWaterfall2,t,1:N,Y);
            switch type1
                case 'none'
                    rgb = parula(size(app.Out.ROI_center,1));
                    rgb = repmat(reshape(rgb, [1 N 3]), [size(p.Faces,2) 1 1]);
                    p.CData = rgb;
                case 'pairwise correlation'
                    if isempty(app.PairwiseColors)
                        plotpairwiselinkage(app)
                    end
                    rgb = repmat(reshape(app.PairwiseColors(leafOrder,:), [1 N 3]), [size(p.Faces,2) 1 1]);
                    p.CData = rgb;
                case 'EMD'
                    if isempty(app.EMDColors)
                        plotemdlinkage(app)
                    end
                    rgb = repmat(reshape(app.EMDColors(leafOrder,:), [1 N 3]), [size(p.Faces,2) 1 1]);
                    p.CData = rgb;
            end
            
            for ii = 1:N
                col = rgb(1,ii,:);
                plot(app.UIAxesWaterfall1,app.Out.ROI_center(leafOrder(ii),1),app.Out.ROI_center(leafOrder(ii),2),'o','Color',col,'markersize',6,'markerfacecolor',col)
            end

            app.WaterfallColors = squeeze(rgb(1,:,:));
            app.LeafOrder = leafOrder;

        end
        
        function [] = plotexamineresults(app,~,~)
            
            ax1 = app.UIAxesExamine1;
            ax2 = app.UIAxesExamine2;
            h = app.ROIHandle;
            zscore = app.zscoreviewButton.Value;

            xRange = floor([h.Center(1)-h.SemiAxes(1),h.Center(1)+h.SemiAxes(1)]);
            yRange = floor([h.Center(2)-h.SemiAxes(2),h.Center(2)+h.SemiAxes(2)]);

            rCtr = app.Out.ROI_center;
            idx1 = find(rCtr(:,1)>xRange(1) & rCtr(:,1)<xRange(2));
            idx2 = rCtr(idx1,2)>yRange(1) & rCtr(idx1,2)<yRange(2);
            idx = idx1(idx2);

            cla(ax2)
            hold(ax2,'on')
            app.SelectedNeurons = [];
            plot(ax1,rCtr(:,1),rCtr(:,2),'go','markersize',6,'markerfacecolor','g')
            clut = parula(numel(idx));
            for ii = 1:numel(idx)
                app.SelectedNeurons(ii) = plot(ax1,rCtr(idx(ii),1),rCtr(idx(ii),2),'o','markersize',6,'Color',clut(ii,:),'MarkerFaceColor',clut(ii,:));
                if zscore
                    foo = (app.Out.F_raw(idx(ii),:) - mean(app.Out.F_raw(idx(ii),:)))/std(app.Out.F_raw(idx(ii),:));
                    plot(ax2,app.Out.t,foo+(ii-1)*4,'color',clut(ii,:))
                else
                    plot(ax2,app.Out.t,app.Out.F_raw(idx(ii),:)+(ii-1)*0.1,'color',clut(ii,:))
                end
            end
            axis(ax2,'auto')

        end
       
        function [ops]= motioncorrection(app,filePath,optionsMC)

            saveDir = convertavitostackedtiff(app,filePath);

            ops.source_path = saveDir;
            ops.save_path = [saveDir,filesep,'corrected']; 
            ops.n_ch = 1;
            ops.align_ch = 1;
            ops.save_ch = 1;
            ops.run_rigid_mc = 1;
            ops.run_affine_wc = optionsMC.runwarp;
            ops.downsample_frame_num = 50;
            cluster_properties = parcluster('local');
            ops.worker_num = cluster_properties.NumWorkers;
            ops.worker_num = 4;
            ops.rigid_norm_method = optionsMC.norm_method;
            ops.rigid_norm_radius = optionsMC.norm_radius;
            ops.rigid_template_tiffstack_num = 7;
            ops.rigid_template_block_num = 3;
            ops.rigid_template_threshold = optionsMC.threshold;
            ops.rigid_template_center_frac = optionsMC.central_fraction;
            ops.rigid_template_fftdenoise = optionsMC.fftdenoise;
            ops.transform = 'affine';
            ops.affine_norm_radius = optionsMC.affine_radius;
            ops.warp_pyramid_levels = 1;
            ops.warp_pyramid_iterations = 50;
            ops.warp_template_tiffstack_num = 7;
            ops.warp_movave_tiffstack_num = optionsMC.moving_average;
            ops.warp_blocksize = optionsMC.warp_blocksize;
            ops.warp_overlap_pix_frac = 0.15;
            ops.edge_remove_pix = 0;
            ops.n_split4warpinit = 6;
            ops.affinematrix_rho_threshold = 0.5;
            ops.affinematrix_medfilt_tiffstack_num = 1;
            ops.affinematrix_abssum_jump_threshold = 10;
            ops.network_temp_copy = 0;

            hWait1 = msgbox('Running PatchWarp transformation','PLEASE WAIT','warn');
            patchwarp(ops)
            delete(hWait1)

        end
        
        function saveDir = convertavitostackedtiff(app,filePath)

            saveDir = [filePath,filesep,'motion_correction'];
            if ~exist(saveDir,"dir")
                mkdir(saveDir)
            end
            ff = dir([filePath,filesep,'*.avi']);
            originalAVIfile = ff(1).name;
            
            hWait = waitbar(0,'Converting AVI to stacked TIFF ...','Name','Please wait','WindowStyle','modal');
            hWait.Position(2) = hWait.Position(2)+100;
            for ii = 1:numel(ff)
                fileName0 = ff(ii).name;
                app.Out.avifile = [filePath,filesep,fileName0];
                loadavifile(app)
                fileName1 = [saveDir,filesep,fileName0(1:end-4),'.tiff'];
                t = Tiff(fileName1,'w');
                tagstruct.ImageLength = size(app.ImageData,1); % vertical
                tagstruct.ImageWidth = size(app.ImageData,2); % horizontal
                tagstruct.Photometric = Tiff.Photometric.MinIsBlack;
                tagstruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
                tagstruct.BitsPerSample = 8;
                tagstruct.SamplesPerPixel = 1;
                tagstruct.Software = 'MATLAB';
                nFrames = size(app.ImageData,3);
                for jj = 1:nFrames
                    setTag(t,tagstruct)
                    write(t,uint8(app.ImageData(:,:,jj)))
                    writeDirectory(t)
                end
                close(t)
                waitbar(ii/numel(ff),hWait);
            end
            app.Out.avifile = originalAVIfile;
            delete(hWait)
        end
       
        function [] = populateMClistbox(app,arg1)

            if arg1==0
                filePathOriginal = app.originalMCfolderEditField.Value;
                filePathCorrected = app.correctedMCfolderEditField.Value;
            elseif arg1==1
                filePathOriginal = app.originalMCfolderEditField.Value;
                filePathCorrected = [];
            else
                filePathCorrected = app.correctedMCfolderEditField.Value;
                filePathOriginal = [];
            end

            if ~isempty(filePathCorrected)
                filePathAffine = [filePathCorrected,filesep,'pre_warp'];
                filePathWrap = [filePathCorrected,filesep,'post_warp'];
                if isfolder(filePathAffine)
                    ff = dir([filePathAffine,filesep,'*.tif*']);
                    if ~isempty(ff)
                        ff1 = {ff.name};
                    end
                end
                if isfolder(filePathWrap)
                    ff = dir([filePathWrap,filesep,'*.tif*']);
                    if ~isempty(ff)
                        ff = {ff.name};
                        ff1 = [ff1,ff];
                    end
                end
                app.correctedfilesListBox.Items = ff1;
                app.correctedfilesListBox.Value = ff1{1};
            end

            if ~isempty(filePathOriginal)
                ff = dir([filePathOriginal,filesep,'*.tif*']);
                if ~isempty(ff)
                    ff1 = {ff.name};
                end
                app.originalfilesListBox.Items = ff1;
                app.originalfilesListBox.Value = ff1{1};
            end

            app.playvideosButton.Text = 'play videos';
            app.StopPlayback = true;

        end
        
        function [] = testforQC(app)
            
            nROI = size(app.Out.F_raw,1);
            failQC = false(nROI,1);
            
            maxSlope = app.maxbleachrateEditField.Value;
            minNumTransients = app.minnumtransientsEditField.Value;
            dt = 1/app.Out.fps;
            t = (1:size(app.Out.F_raw,2))*dt - dt;
            warning off
            for ii = 1:nROI
                f = app.Out.F_raw(ii,:);
                p = polyfit(t,f,1);
                slope = 60*abs(p(1)); % percent/min
                if slope>maxSlope
                    failQC(ii) = true;
                    continue
                end
                boo = floor(app.lowesssmoothingEditField.Value);
                if boo>0
                    foo = smooth(t,f,boo,'lowess');
                    f(:) = f(:) - foo(:);
                    f(end) = f(end-1);
                    ff = sort(f);
                    ff = ff(floor(0.05*numel(ff)));
                    f = f - ff;
                    app.Out.F_raw(ii,:) = f(:)';
                end
                f = (f - mean(f))/std(f); % z-score normalized
                [~,g] = findpeaks(f,'MinPeakHeight',app.mintransientsizeEditField.Value);
                if numel(g)<minNumTransients
                    failQC(ii) = true;
                    continue
                end
                h = app.Out.ROI_center(ii,:);
                borderSize = app.bordersizeEditField.Value;
                height = size(app.Out.meanImage,1);
                width = size(app.Out.meanImage,2);
                if (h(2)<borderSize) || (h(2)>(height-borderSize)) || (h(1)<borderSize) || (h(1)>(width-borderSize))
                    failQC(ii) = true;
                end
            end
            warning on

            app.FailedQC = failQC;
            app.AcceptROI(app.FailedQC) = false;
            
        end

        function [] = plotspikeestimation(app,fr,dt)

            nNeurons = numel(fr);
            meanFR = zeros(nNeurons,1);
            for ii = 1:nNeurons
                meanFR(ii) = mean(numel(fr{ii}));
            end
            rgb = parula(nNeurons);
            cla(app.UIAxesSpikes2)
            hold(app.UIAxesSpikes2,'on')
            for ii = 1:nNeurons
                spikePos = fr{ii}*dt;
                if isempty(spikePos), continue, end
                plot(app.UIAxesSpikes2, spikePos, ii, 'Color', rgb(ii,:), 'MarkerFaceColor', rgb(ii,:), 'Marker', 'o', 'MarkerSize',6)
            end
            ylim([0 nNeurons+1]);
            xlim([0 nNeurons+1]);

            plotcontours(app,app.UIAxesSpikes1)
            hold(app.UIAxesSpikes1,'on')
            for ii = 1:nNeurons
                col = rgb(ii,:);
                plot(app.UIAxesSpikes1,app.Out.ROI_center(ii,1),app.Out.ROI_center(ii,2),'o','Color',col,'markersize',6,'markerfacecolor',col)
            end

        end
        
        function [] = populatecrosscorrelationlistbox(app)
            nNeurons = size(app.Out.F_raw,1);
            fStr = cellstr(num2str((1:nNeurons)'));
            app.neuron1ListBox.Items = fStr;
            app.neuron1ListBox.Value = fStr{1};
            app.neuron2ListBox.Items = fStr;
            app.neuron2ListBox.Value = fStr{1};
        end
        
        function [] = plotcrosscorrelation(app)
            dt = 1/app.Out.fps;

            idx1 = sort(str2double(app.neuron1ListBox.Value));
            n1 = numel(idx1);
            idx2 = sort(str2double(app.neuron2ListBox.Value));
            n2 = numel(idx2);
         
            cla(app.UIAxesCross2)
            hold(app.UIAxesCross2,'on')
            kk = 1;
            if n1>0 && n2>0
                for ii = 1:n1
                    for jj= 1:n2
                        [c,lags] = xcorr(app.Out.F_raw(idx1(ii),:),app.Out.F_raw(idx2(jj),:));
                        plot(app.UIAxesCross2, lags/app.Out.fps, c)
                        lStr{kk} = [num2str(idx1(ii)),' X ',num2str(idx2(jj))];
                        kk = kk + 1;
                    end
                end
            end
            axes(app.UIAxesCross2)
            legend('boxoff')
            ll = legend(lStr,'Location','Northeast');
            ll.FontSize = 10;
            ll.FontWeight = 'bold';

            cla(app.UIAxesCross1)
            hold(app.UIAxesCross1,'on')
            t = (1:length(app.Out.F_raw))*dt - dt;
            idx = unique([idx1(:);idx2(:)]);
            for ii = 1:numel(idx)
                plot(app.UIAxesCross1, t,app.Out.F_raw(idx(ii),:)+(numel(idx)-ii)*0.4);
            end
            axes(app.UIAxesCross1)
            axis auto
            yy = ylim;
            dy = yy(2)-yy(1);
            yy2 = yy(2) + dy*0.2;
            ylim([yy(1) yy2])
            legend('boxoff')
            ll = legend(num2str(idx),'Location','Northeast');
            ll.FontSize = 10;
            ll.FontWeight = 'bold';

        end
        
        function [detectFolder] = makefolders(app)
            if ispc
                userProfile = getenv('USERPROFILE');
            else
                userProfile = getenv('HOME');
            end
            myDocumentsFolder = [userProfile,filesep,'Documents'];
            detectFolder = [myDocumentsFolder,filesep,'detect_folder'];
            if ~isfolder(detectFolder)
                mkdir(detectFolder)
            end
            aviFileFolder = [detectFolder,filesep,'AVI_files'];
            if ~isfolder(aviFileFolder)
                mkdir(aviFileFolder)
            end
            detectionParametersFolder = [detectFolder,filesep,'detection_parameters'];
            if ~isfolder(detectionParametersFolder)
                mkdir(detectionParametersFolder)
            end
            cnmfOutputFolder = [detectFolder,filesep,'CNMF_outputs'];
            if ~isfolder(cnmfOutputFolder)
                mkdir(cnmfOutputFolder)
            end
            app.DetectFolder = detectFolder;
            app.savedirectoryEditField.Value = aviFileFolder;
            app.MainAppFolder = fileparts(which('detect.mlapp'));

        end

        
        function [fileNameCombined] = combineAVIfilesintoone(app,fileName,filePath)

            vid = VideoReader([filePath,fileName{1}]);
            hSize = vid.Width;
            vSize = vid.Height;
            sizesOkay = true;
            for ii = 2:numel(fileName)
                vid = VideoReader([filePath,fileName{ii}]);
                if vid.Width~=hSize || vid.Heigh~=vSize
                    sizesOkay = false;
                end
            end
            if ~sizesOkay
                uialert(app.detectFigure,'All AVI files must be the same size. Make sure you used the same ROI on each.','DIFFERENT SIZES');
                fileNameCombined = 0;
                return
            end
            fileNameCombined = [fileName{1}(1:end-4),'COMBINED.avi'];
            hWait = waitbar(0,'Saving images ...','Name','Please wait','WindowStyle','modal');
            vidWrite = VideoWriter([filePath,fileNameCombined],'Uncompressed AVI');
            vidWrite.FrameRate = vid.FrameRate;
            open(vidWrite)
            N = numel(fileName);
            for jj = 1:N
                vid = VideoReader([filePath,fileName{jj}]);
                for kk = 1:vid.NumFrames
                    img = read(vid,kk);
                    writeVideo(vidWrite,img)
                    if rem(kk,50)==0
                        waitbar((jj-1)/N + kk/vid.NumFrames, hWait, 'Saving images ...')
                    end
                end
            end
            close(vidWrite)
            delete(hWait)
        end

        function [ISI,SPIKE] = calculatekreuz(~,R)
            N = size(R,1) - 2; % ignore the ends
            spikes1 = find(R(:,1));
            spikes2 = find(R(:,2));
            I = zeros(N,1);
            S = zeros(N,1);
            for ii = 1:N
                t = ii + 1;
                s1P = spikes1(spikes1<=t);
                s1F = spikes1(spikes1>t);
                s2P = spikes2(spikes2<=t);
                s2F = spikes2(spikes2>t);
                t1P = max(s1P);
                t1F = min(s1F);
                t2P = max(s2P);
                t2F = min(s2F);
                nu1 = t1F - t1P;
                nu2 = t2F - t2P;
                deltat1P = min(abs(t1P-t2P),abs(t1P-t2F));
                deltat1F = min(abs(t1F-t2P),abs(t1F-t2F));
                deltat2P = min(abs(t2P-t1P),abs(t2P-t1F));
                deltat2F = min(abs(t2F-t1P),abs(t2F-t1F));
                x1P = t - t1P;
                x1F = t1F - t;
                x2P = t - t2P;
                x2F = t2F - t;
                S1 = (deltat1P*x1F + deltat1F*x1P)/nu1;
                S2 = (deltat2P*x2F + deltat2F*x2P)/nu2;
                if nu1<=nu2
                    I(ii) = (nu1/nu2) - 1;
                else
                    I(ii) = 1 - (nu2/nu1);
                end
                S_num = S1*nu2 + S2*nu1;
                S_den = (nu1 + nu2)^2/2;
                S(ii) = S_num/S_den;
            end
            ISI = mean(abs(I));
            SPIKE = mean(S);
        end
        
        function dF_F = calculatedeltaFoverF(~,F)
            baseline = smooth(F,length(F));
            dF_F = (F(:)-baseline(:))./baseline(:);
        end

        
        function results = removebaseline(~,data,fps)

            thirtyseconds = round(30*fps);
            baseline = smooth(data,thirtyseconds);
            results = data - baseline(:)';
            
        end
        
        function [] = displayanalysistrace(app)
            traceNo = app.displaytraceSpinner.Value;
            F = app.Out.F_raw;
            t = app.Out.t;
            locs = app.Transients.Locations;
            cla(app.UIAxesBasic)
            plot(app.UIAxesBasic,t,F(traceNo,:))
            hold(app.UIAxesBasic,"on")
            plot(app.UIAxesBasic,t(locs{traceNo}),F(traceNo,locs{traceNo}),'ro','markerfacecolor','r')
            axis(app.UIAxesBasic,'tight')
            app.transientnumberEditField.Value = numel(locs{traceNo});
            app.transientamplitudeEditField.Value = mean(app.Transients.Prominence{traceNo});
            app.transientwidthEditField.Value = mean(app.Transients.Widths{traceNo});
            app.transientITIEditField.Value = app.Transients.ITI(traceNo);

        end
        
        function [] = analyzecalciumtransients(app)
            
            t = app.Out.t;
            dt = mode(diff(t));
            smoothWindow = round(0.5/dt); % 0.5 sec smoothing window
            F = smooth(app.Out.F_raw,smoothWindow);
            locs = app.Transients.Locations;
            wids = app.Transients.Widths;
            hts = app.Transients.Prominence;
            ITI = zeros(numel(locs),1);
            for ii = 1:numel(locs)
                if ~isempty(locs{ii})
                    ITI(ii) = mean(diff(locs{ii}))*dt;
                end
            end
            app.Transients.ITI = ITI;
            for ii = 1:numel(locs)
                N(ii) = numel(locs{ii});
            end
            histogram(app.UIAxesNumber,N)
            A = cell2mat(hts);
            histogram(app.UIAxesAmplitude,A)
            W = cell2mat(wids);
            histogram(app.UIAxesWidth,W)
            histogram(app.UIAxesITI,ITI)

        end
    end

    methods (Access = public)

        function [] = changelimits(app,src,~)
            contrastMax = str2num(src.Children(1).Children(3).Children.Children(2).Children.Children(2).Children(2).String); %#ok<*ST2NM>
            contrastMin = str2num(src.Children(1).Children(3).Children.Children(2).Children.Children(2).Children(5).String);
            maxIntensity = 2^app.bitsEditField.Value - 1;
            app.maxEditField.Value = round(contrastMax*maxIntensity);
            app.minEditField.Value = round(contrastMin*maxIntensity);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ax)
            cla(app.UIAxesLoad)
            app.ROIpresent = false;
            app.ROInumber = 0;
            app.ROI = [];
            app.ImageData = [];
            app.Out = [];
            app.AddedNeurons = [];
            enableLoadButtons2(app,false)
            app.previewButton.Enable = false;
            app.filenameEditField.Enable = false;
            app.foldernameEditField.Enable = false;
            app.folderlocationEditField.Enable = false;
            detectFolder = makefolders(app);
            app.SelectedPath = detectFolder;
            addpath(genpath([app.MainAppFolder,filesep,'info_text']))


        end

        % Button pushed function: chooseButton
        function chooseButtonPushed(app, event)
            fileType = app.typeoffilesDropDown.Value;

            switch fileType
                case "individual tiffs"
                    app.IsTiffStacked = false;
                    newPath = uigetdir(app.SelectedPath,'Choose a directory');
                    figure(app.detectFigure)
                    if newPath
                        app.SelectedPath = newPath;
                        app.SelectedFile = [];
                        app.filenameEditField.Value = '';
                        boo = dir([newPath,filesep,'*.TIF']);
                        app.nImages = numel(boo);
                        boo = dir([app.SelectedPath,filesep,'*.TIF']); % a list of all the files ending with the suffix .TIF
                        app.nImages = numel(boo);
                        imageInfo = imfinfo([app.SelectedPath,filesep,boo(1).name],'TIF');
                        app.bitsEditField.Value = imageInfo.BitsPerSample(1);
                    else
                        return
                    end
                case "stacked tiff"
                    app.IsTiffStacked = true;
                    [fileName,newPath] = uigetfile({'*.tif;*.tiff'},'Pick a graphics file');
                    figure(app.detectFigure)
                    if fileName
                        app.SelectedPath = newPath(1:end-1);
                        app.SelectedFile = fileName;
                        app.filenameEditField.Enable = true;
                        app.filenameEditField.Value = fileName;
                        imageInfo = imfinfo([newPath,filesep,fileName]);
                        app.nImages = numel(imageInfo);
                        imageInfo = imageInfo(1);
                        app.bitsEditField.Value = imageInfo.BitsPerSample(1);
                    else
                        return
                    end
                otherwise
                    app.IsTiffStacked = true;
                    [fileName,newPath] = uigetfile({'*.mp4;*.avi'},'Pick a graphics file');
                    figure(app.detectFigure)
                    if fileName
                        app.SelectedPath = newPath(1:end-1);
                        app.SelectedFile = fileName;
                        app.filenameEditField.Enable = true;
                        app.filenameEditField.Value = fileName;
                        vid = VideoReader([newPath,filesep,fileName]);
                        app.nImages = vid.NumFrames;
                        img = readFrame(vid);
                        if isa(img,'uint8')
                            app.bitsEditField.Value = 8;
                        else
                            app.bitsEditField.Value = 16;
                        end
                        app.framerateEditField.Value = vid.FrameRate;
                    end
            end            

            foo = strfind(app.SelectedPath,filesep);
            folderName = app.SelectedPath(foo(end)+1:end);
            locationName = app.SelectedPath(1:foo(end)-1);
            app.foldernameEditField.Enable = true;
            app.foldernameEditField.Value = folderName;
            app.folderlocationEditField.Enable = true;
            app.folderlocationEditField.Value = locationName;
            app.useallframesCheckBox.Value = 1;
            app.framesEditField.Value = ['1:',num2str(app.nImages)];
            app.FramesField = app.framesEditField.Value;
            app.Frames = 1:app.nImages;
            enableLoadButtons1(app)
            app.ROIpresent = false;
            app.ROInumber = 0;
            app.bitsEditField.Enable = true;
            app.bitsEditFieldLabel.Enable = true;
            enableLoadButtons2(app,false)
            app.previewButton.Enable = true;

        end

        % Value changed function: framesEditField
        function framesEditFieldValueChanged(app, event)
            value = app.framesEditField.Value;
            frames = str2num(value);
            frames = sort(frames);
            frames = round(frames);
            if frames(1)<1 || frames(end)>app.nImages
                errordlg(['Frames must be between 1 and ',num2str(app.nImages)],'Check frames field');
                app.framesEditField.Value = app.FramesField;
                return
            end
            app.FramesField = app.framesEditField.Value;
            app.Frames = frames;
        end

        % Value changed function: useallframesCheckBox
        function useallframesCheckBoxValueChanged(app, event)
            value = app.useallframesCheckBox.Value;
            if value
                app.framesEditField.Value = ['1:',num2str(app.nImages)];
                app.FramesField = app.framesEditField.Value;
                app.Frames = 1:app.nImages;
                app.framesEditField.Enable = false;
            else
                app.framesEditField.Enable = true;
            end
        end

        % Button pushed function: previewButton
        function previewButtonPushed(app, event)
            app.previewButton.Enable = false;
            drawnow

            fileType = app.typeoffilesDropDown.Value;

            folderLocation = app.folderlocationEditField.Value;
            folderName = app.foldernameEditField.Value;
            app.SelectedPath = [folderLocation,filesep,folderName];

            if strcmp(fileType,'individual tiffs')

                foo = dir([app.SelectedPath,filesep,'*.TIF']); % a list of all the files ending with the suffix .TIF
                
                trialNum = zeros(app.nImages,1);
                for ii = 1:app.nImages
                    trialNum(ii) = foo(ii).datenum;
                end
                [~,trialNumIdx] = sort(trialNum);
                baz = imread([app.SelectedPath,filesep,foo(1).name],'tif');
                app.TrialNumIdx = trialNumIdx;

            elseif strcmp(fileType,'stacked tiff')

                baz = imread([app.SelectedPath,filesep,app.SelectedFile],'tif',1);
            
            else

                vid = VideoReader([app.SelectedPath,filesep,app.SelectedFile]);
                baz = read(vid,1);

            end

            vPixels = size(baz,1);
            hPixels = size(baz,2);
            nFrames = numel(app.Frames);
            if nFrames >= 40 % use only ~20 frames, evenly spaced, for average
                spacing = ceil(nFrames/20);
                N = ceil(nFrames/spacing);
            else
                spacing = 1;
                N = nFrames;
            end

            if app.bitsEditField.Value==8
                app.ImageData = zeros(vPixels,hPixels,N,'uint8');
            else
                app.ImageData = zeros(vPixels,hPixels,N,'uint16');
            end

            kk = 1;
            if strcmp(fileType,'individual tiffs')
                for jj = 1:spacing:nFrames
                    idx = app.Frames(jj);
                    app.ImageData(:,:,kk) = im2gray(imread([app.SelectedPath,filesep,foo(trialNumIdx(idx)).name],'tif'));
                    kk = kk + 1;
                end
            elseif strcmp(fileType,'stacked tiff')
                for jj = 1:spacing:nFrames
                    idx = app.Frames(jj);
                    img = imread([app.SelectedPath,filesep,app.SelectedFile],'tif',idx);
                    img = img - min(img(:));
                    app.ImageData(:,:,kk) = img;
                    kk = kk + 1;
                end
            else
                for jj = 1:spacing:nFrames
                    idx = app.Frames(jj);
                    img = read(vid,idx);
                    img = rgb2gray(img);
                    app.ImageData(:,:,kk) = img;                    
                    kk = kk + 1;
                end
            end

            app.ImageData = double(app.ImageData);
            maxIntensity = 2^app.bitsEditField.Value - 1;
            app.MeanImage = mean(app.ImageData,3);

            lowerLimit = 0.001; % the lowest 0.1% of pixels are noise
            upperLimit = 0.999; % so are the highest 0.1%
            img1 = app.MeanImage;
            [m1, n1] = size(img1);
            goo = sort(reshape(img1,m1*n1,1)); % sort pixels from lowest intensity to highest intensity
            app.minEditField.Value = goo(max(ceil(lowerLimit*m1*n1),1));
            app.maxEditField.Value = goo(ceil(upperLimit*m1*n1));

            imshow(app.MeanImage/maxIntensity,'Parent',app.UIAxesLoad)
            minEditFieldValueChanged(app)

            enableLoadButtons2(app,true)

            app.Mask = ones(size(app.MeanImage));

            
        end

        % Button pushed function: clearLoadButton
        function clearLoadButtonPushed(app, event)
            enableLoadButtons2(app,false)
            app.previewButton.Enable = true;
            app.ROIpresent = false;
            app.Mask = [];
            app.minEditField.Value = 0;
            app.maxEditField.Value = 2^app.bitsEditField.Value - 1;
            img1 = 0 * app.ImageData(:,:,1);
            imshow(img1,'Parent',app.UIAxesLoad)
        end

        % Button pushed function: adjustcontrastButton
        function adjustcontrastButtonPushed(app, event)

            maxIntensity = 2^app.bitsEditField.Value - 1;
            imshow(app.MeanImage/maxIntensity,'Parent',app.UIAxesLoad)
            htool = imcontrast(app.UIAxesLoad);
            htool.WindowButtonUpFcn = @app.changelimits;

        end

        % Button pushed function: addROIButton
        function addROIButtonPushed(app, event)
            roi = drawpolygon(app.UIAxesLoad);
            app.ROInumber = app.ROInumber + 1;
            app.ROI{app.ROInumber} = roi;
            app.ROIpresent = true;
            app.acceptROIButton.Enable = true;

        end

        % Value changed function: maxEditField, minEditField
        function minEditFieldValueChanged(app, event)
            img = app.MeanImage;
            img(img<app.minEditField.Value) = 0;
            img(img>=app.maxEditField.Value) = 2^app.bitsEditField.Value - 1;
            img = img - app.minEditField.Value;
            img(img<0) = 0;
            img = img/(app.maxEditField.Value-app.minEditField.Value);
            img(img>1) = 1;
            imshow(img,'Parent',app.UIAxesLoad)
    
        end

        % Button pushed function: acceptROIButton
        function acceptROIButtonPushed(app, event)
            mask = zeros(size(app.MeanImage));
            if app.ROIpresent
                for ii = 1:app.ROInumber
                    if ishandle(app.ROI{ii})
                        b = createMask(app.ROI{ii});
                        mask = mask + b;
                    end
                end
                mask(mask>0) = 1;
                mask(mask<=0) = 0;
            else
                return
            end
            app.Mask = mask;

            for ii = 1:size(app.ImageData,3)
                app.ImageData(:,:,ii) = app.ImageData(:,:,ii) .* app.Mask;
            end
            app.MeanImage = mean(app.ImageData,3);
            img = app.MeanImage;
            img = img - app.minEditField.Value;
            img = img / (app.maxEditField.Value-app.minEditField.Value);
            img(img<0) = 0;
            img(img>1) = 1;
            imshow(img,'Parent',app.UIAxesLoad)
            
        end

        % Button pushed function: saveasaviButton
        function saveasaviButtonPushed(app, event)
            aviFilesFolder = [app.DetectFolder,filesep,'AVI_files'];
            [fileName,filePath] = uiputfile([aviFilesFolder,filesep,'*.avi'],...
                'Enter filename to save parameters',[aviFilesFolder,filesep,'myFile.avi']);
            if fileName==0
                disp('No filename entered. No AVI file saved.')
                return
            end
            truncatemeanimage(app)            
            fileName1 = [filePath,fileName];
            v = VideoWriter(fileName1,'Uncompressed AVI');
            v.FrameRate = app.framerateEditField.Value;
            open(v)
            hWait = waitbar(0,'Saving images ...','Name','Please wait','WindowStyle','modal');
            tiffFiles = dir([app.SelectedPath,filesep,'*.TIF*']); % a list of all the files ending with the suffix .TIF
            nFrames = numel(app.Frames);
            fileType = app.typeoffilesDropDown.Value;
            if strcmp(fileType,"mp4") || strcmp(fileType,"avi")
                v1 = VideoReader([app.SelectedPath,filesep,app.SelectedFile]);
            end
            for ii = 1:nFrames
                idx = app.Frames(ii);
                if strcmp(fileType,"individual tiffs")
                    img = imread([app.SelectedPath,filesep,tiffFiles(app.TrialNumIdx(idx)).name],'tif');
                elseif strcmp(fileType,"stacked tiff")
                    img = imread([app.SelectedPath,filesep,app.SelectedFile],'tif',idx);
                    img = img - min(img(:));
                else
                    img = read(v1,idx);
                    img = rgb2gray(img);
                end
                img = double(img);
                img = img .* app.Mask;
                img = img(app.Truncate.c1:app.Truncate.c2,:,:);
                img = img(:,app.Truncate.r1:app.Truncate.r2,:);
                img = img - app.minEditField.Value;
                img = img/(app.maxEditField.Value - app.minEditField.Value);
                img(img<0) = 0;
                img(img>1) = 1;               
                writeVideo(v,img)
                if rem(ii,50)==0
                    waitbar(ii/nFrames, hWait, 'Saving images ...')
                end
            end
            close(v)

            app.SelectedPath = app.DetectFolder;
            delete(hWait)
            app.saveasaviButton.Enable = false;
            app.avifilenameEditField.Value = fileName;
            app.avifolderEditField.Value = filePath;
            app.avifileEditField.Value = [filePath,fileName];
            app.motioncorrectionEditField.Value = filePath;


        end

        % Button pushed function: chooseaviButton
        function chooseaviButtonPushed(app, event)
            [newFile,newPath] = uigetfile([app.SelectedPath,filesep,'*avi'],'Pick an AVI file');
            figure(app.detectFigure)
            if newFile==0
                return
            end
            app.avifilenameEditField.Value = newFile;
            app.avifolderEditField.Value = newPath;
            vidObj = VideoReader([newPath,newFile]);
            if vidObj.NumFrames > 50
                img = read(vidObj,[1 50]);
            else
                img = read(vidObj,[1 vidObj.NumFrames]);
            end
            img = single(img);
            img = mean(img,4)/max(img(:));
            app.MeanImage = img;
            imshow(app.MeanImage,'Parent',app.UIAxesInitial)
            axis(app.UIAxesInitial,'on', 'image');
            figure(app.detectFigure)


        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                case 'initial conditions'
                    if isempty(app.MeanImage)
                        return
                    else
                        cla(app.UIAxesInitial)
                        imshow(app.MeanImage,'Parent',app.UIAxesInitial)
                        axis(app.UIAxesInitial,'on','image')
                    end
                case 'CNMF algorithm'
                    setlabelcolorstoblack(app)
                case 'refine results'
                    disableDefaultInteractivity(app.UIAxesRefine1)
                    if isempty(app.Out), return, end
                    app.clicktoselectSwitch.Value = 'Off';
                case 'examine results'
                    if isempty(app.Out), return, end
                    app.clicktoselectSwitch.Value = 'Off';
                otherwise
                    app.clicktoselectSwitch.Value = 'Off';
            end

        end

        % Value changed function: SNRthresholdDropDown, 
        % ...and 13 other components
        function estimatednumberEditFieldValueChanged(app, event)
            app.DetectionParameters.nROI = app.estimatednumberEditField.Value;
            app.DetectionParameters.sizeROI = app.typicaldiameterpixelsEditField.Value;
            app.DetectionParameters.p = str2double(app.pDropDown.Value);
            app.DetectionParameters.spatialDownsampling = str2double(app.spatialdownsamplingDropDown.Value);
            app.DetectionParameters.temporalDownsampling = str2double(app.temporaldownsamplingDropDown.Value);
            app.DetectionParameters.initializationMethod = app.initializationmethodDropDown.Value;
            app.DetectionParameters.searchMethod = app.searchmethodDropDown.Value;
            app.DetectionParameters.ellipseExpansion = str2double(app.ellipseexpansionfactorDropDown.Value);
            app.DetectionParameters.deconvolutionMethod = app.deconvolutionmethodDropDown.Value;
            app.DetectionParameters.temporalIterations = str2double(app.temporaliterationsDropDown.Value);
            app.DetectionParameters.fudgeFactor = app.fudgefactorEditField.Value;
            app.DetectionParameters.mergingThreshold = app.mergingthresholdEditField.Value;
            app.DetectionParameters.backgroundComponents = str2double(app.backgroundcomponentsDropDown.Value);
            app.DetectionParameters.snrThreshold = str2double(app.SNRthresholdDropDown.Value);

        end

        % Button pushed function: saveparametersButton
        function saveparametersButtonPushed(app, event)
            estimatednumberEditFieldValueChanged(app);
            detectionParameters = app.DetectionParameters;
            parametersFolder  = [app.DetectFolder,filesep,'detection_parameters'];
            [fileName,filePath] = uiputfile([parametersFolder,filesep,'*.mat'], 'Enter filename to save parameters',[parametersFolder,filesep,'detectionParameters.mat']);
            if fileName
                save([filePath,fileName],'-struct','detectionParameters')
            end
            app.detectionparametersfileEditField.Value = [filePath,fileName];
        end

        % Button pushed function: loadparametersButton
        function loadparametersButtonPushed(app, event)
            parametersFolder  = [app.DetectFolder,filesep,'detection_parameters'];
            [fileName,filePath] = uigetfile([parametersFolder,filesep,'*mat'],'Choose a parameters file');
            figure(app.detectFigure)
            if fileName==0
                return
            end
            load([filePath,fileName]); %#ok<LOAD>
            app.estimatednumberEditField.Value = nROI;
            app.typicaldiameterpixelsEditField.Value = sizeROI;
            app.pDropDown.Value = num2str(p);
            app.spatialdownsamplingDropDown.Value = num2str(spatialDownsampling);
            app.temporaldownsamplingDropDown.Value = num2str(temporalDownsampling);
            app.initializationmethodDropDown.Value = initializationMethod;
            app.searchmethodDropDown.Value = searchMethod;
            app.ellipseexpansionfactorDropDown.Value = num2str(ellipseExpansion);
            app.deconvolutionmethodDropDown.Value = deconvolutionMethod;
            app.temporaliterationsDropDown.Value = num2str(temporalIterations);
            app.fudgefactorEditField.Value = fudgeFactor;
            app.mergingthresholdEditField.Value = mergingThreshold;
            app.backgroundcomponentsDropDown.Value = num2str(backgroundComponents);
            app.SNRthresholdDropDown.Value = num2str(snrThreshold);
            estimatednumberEditFieldValueChanged(app);

        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            if ~exist(app.avifileEditField.Value,'file') || ~exist(app.detectionparametersfileEditField.Value,'file')
                errordlg('Choose an AVI file and a detection parameters file before running','Error dialog','modal')
                return
            end

            addpath(genpath([app.MainAppFolder,filesep,'CNMF_extra']))
            addpath(genpath([app.MainAppFolder,filesep,'CNMF_software']))

            load(app.detectionparametersfileEditField.Value);

            foo = VideoReader(app.avifileEditField.Value);
            fps = foo.FrameRate;

            setlabelcolorstoblack(app)

            app.loadimagesLabel.FontColor = [0 1 0]; drawnow

            app.CNMFGuage.Value = 0;
            app.CNMFRunTimeEditField.Value = 0;

            runTime0 = datetime("now");

            % load images
            imData = read_file_modified(app.avifileEditField.Value);
            imData = single(imData); % the CNMF software expects the images to be single precision
            [d1,d2,nFrames] = size(imData); % size of the images
            d = floor(d1*d2); % total number of pixels
            app.CNMFGuage.Value = 5;

            app.loadimagesLabel.FontColor = [0 0 1];
            app.preprocessdataLabel.FontColor = [0 1 0]; drawnow

            % set options
            boo = split(deconvolutionMethod); %#ok<NODEF> 
            if strcmpi(boo{1},'constrained')
                deconvolutionMethod = 'constrained_foopsi';
            elseif strcmpi(boo{1},'mcem')
                deconvolutionMethod = 'MCEM_foopsi';
            else
                deconvolutionMethod = boo{1};
            end
            if strcmpi(boo{end},'cvx')
                convexMethod = 'cvx';
            else
                convexMethod = 'splg1';
            end
            options = CNMFSetParms(...                                     % Check CNMFSetParms.m for more details
                'd1', d1, 'd2', d2, ...                                    % Dimensions of datasets
                'ssub', spatialDownsampling, ...                           % Spatial downsampling
                'tsub', temporalDownsampling, ...                          % Temporal downsampling
                'init_method', initializationMethod, ...                   % Initialization method
                'search_method', searchMethod, ...                         % Search locations when updating spatial components
                'dist', ellipseExpansion, ...                              % Expansion factor of ellipse
                'deconv_method',deconvolutionMethod,...                    % Activity deconvolution method
                'method',convexMethod,...                                  % Method for solving convex problems
                'temporal_iter', temporalIterations, ...                   % Number of block-coordinate descent steps
                'fudge_factor', fudgeFactor, ...                           % Bias correction for autoregression coefficients
                'merge_thr', mergingThreshold, ...                         % Merging threshold
                'p', p, ...                                                % Order of AR dynamics
                'nb', backgroundComponents, ...                            % Number of background components
                'min_SNR', snrThreshold, ...                               % Minimum SNR threshold
                'bas_nonneg', 0);                                          % Allow a negative baseline

            [P,imData] = preprocess_data(imData,p,options);

            app.preprocessdataLabel.FontColor = [0 0 1];
            app.initializecomponentsLabel.FontColor = [0 1 0]; drawnow
            app.CNMFGuage.Value = 10;
            app.CNMFRunTimeEditField.Value = seconds(datetime("now")-runTime0);
            drawnow

            % run CNMF algorithm
            warning off
            imData = imData(:,:,1:nFrames);

            [Ain,Cin,bin,fin,center] = initialize_components(imData,nROI,round(sizeROI/2),options,P);

            app.initializecomponentsLabel.FontColor = [0 0 1];
            app.updatespatialcomponentsILabel.FontColor = [0 1 0]; drawnow
            app.CNMFGuage.Value = 40;
            app.CNMFRunTimeEditField.Value = seconds(datetime("now")-runTime0);
            drawnow

            Cn = correlation_image(imData);
            Yr = reshape(imData, d, nFrames);
            [A, b, Cin] = update_spatial_components(Yr, Cin, fin, [Ain, bin], P, options);

            app.updatespatialcomponentsILabel.FontColor = [0 0 1];
            app.updatetemporalcomponentsILabel.FontColor = [0 1 0]; drawnow
            app.CNMFGuage.Value = 50;
            app.CNMFRunTimeEditField.Value = seconds(datetime("now")-runTime0);
            drawnow
            
            P.p = 0; % Turn off autoregression dynamics temporarily for speed
            [C, f, P, S, YrA] = update_temporal_components(Yr, A, b, Cin, fin, P, options);

            app.updatetemporalcomponentsILabel.FontColor = [0 0 1];
            app.classifyneuronsLabel.FontColor = [0 1 0]; drawnow
            app.CNMFGuage.Value = 60;
            app.CNMFRunTimeEditField.Value = seconds(datetime("now")-runTime0);
            drawnow

            app.classifyneuronsLabel.FontColor = [0 0 1];
            app.updatespatialcomponentsIILabel.FontColor = [0 1 0]; drawnow
            app.CNMFGuage.Value = 75;
            app.CNMFRunTimeEditField.Value = seconds(datetime("now")-runTime0);
            drawnow

            [Am, Cm, ~, ~, Pm, ~] = merge_components(Yr, A, b, C, f, P, S, options);
            Pm.p = p; % Restore autoregression dynamics value
            [A2, b2, C2] = update_spatial_components(Yr, Cm, f, [Am, b], Pm, options);
            
            app.updatespatialcomponentsIILabel.FontColor = [0 0 1];
            app.updatetemporalcomponentsIILabel.FontColor = [0 1 0]; drawnow
            app.CNMFGuage.Value = 85;
            app.CNMFRunTimeEditField.Value = seconds(datetime("now")-runTime0);
            drawnow

            [C2, f2, P2, S2, ~] = update_temporal_components(Yr, A2, b2, C2, f, Pm, options);

            app.updatetemporalcomponentsIILabel.FontColor = [0 0 1];
            app.constructtracesLabel.FontColor = [0 1 0]; drawnow
            app.CNMFGuage.Value = 90;
            app.CNMFRunTimeEditField.Value = seconds(datetime("now")-runTime0);
            drawnow

            [A_or, C_or, S_or, ~] = order_ROIs(A2, C2, S2, P2); % order ROIs

            [F_raw, F_inferred] = constructtraces(Yr, A_or, C_or, b2, f2, options);
            S_deconv = S_or;
            spatial_comp = A_or;
            ROI_center = zeros(size(spatial_comp, 2), 2);
            for cur_ROI = 1:size(spatial_comp, 2)
                single_ROI = full(reshape(spatial_comp(:, cur_ROI), size(Cn, 1), size(Cn, 2)));
                s = regionprops(true(size(single_ROI)), single_ROI, 'WeightedCentroid');
                ROI_center(cur_ROI, :) = s.WeightedCentroid;
            end

            warning on
            
            rmpath(genpath([app.MainAppFolder,filesep,'CNMF_extra']))
            rmpath(genpath([app.MainAppFolder,filesep,'CNMF_software']))


            app.constructtracesLabel.FontColor = [0 0 1]; drawnow
            app.CNMFGuage.Value = 95;
            app.CNMFRunTimeEditField.Value = seconds(datetime("now")-runTime0);
            drawnow

            F_raw(abs(F_raw)==Inf) = NaN;
            F_inferred(abs(F_inferred)==Inf) = NaN;

            out.Cn = Cn;
            out.F_raw = F_raw;
            out.F_inferred = F_inferred;
            out.ROI_center = ROI_center;
            out.fps = fps;
            out.YrA = YrA;
            out.center = center;
            out.S_deconv = S_deconv;
            out.t = (1:size(out.F_raw,2))/out.fps;
            out.spatial_comp = spatial_comp;
            out.avifile = app.avifileEditField.Value;
            out.meanImage = app.MeanImage;
            app.ImageData = imData;
            app.Fs = fps;

            ax = app.UIAxesRefine1;
            hWait = waitbar(0,'Plotting contours ...','Name','Please wait','WindowStyle','modal');
            d1 = size(Cn,1);
            d2 = size(Cn,2);
            nROI1 = size(spatial_comp,2);
            for ii = 1:nROI1
                A = full(reshape(spatial_comp(:,ii),d1,d2));
                A = medfilt2(A,[5,5]);
                A = A(:);
                [temp,ind] = sort(A.^2,'ascend');
                temp = cumsum(temp);
                ff = find(temp > (1-0.99)*temp(end),1,'first');
                if ~isempty(ff)
                    X = contour(ax,reshape(A,d1,d2),[0,0]+A(ind(ff)),'color','b','linewidth', 1);
                    mX = X(1,:) - mean(X(1,:));
                    out.CC{ii} = X(:,mX>-100);
                    fp = find(A >= A(ind(ff)));
                    [i,j] = ind2sub([d1,d2],fp);
                    out.CR{ii,1} = [i,j]';
                    out.CR{ii,2} = A(fp)';
                end
                out.F_raw(ii,:) = removebaseline(app,out.F_raw(ii,:),fps); % remove baseline drift
                if rem(ii,5)==0
                    waitbar(ii/nROI1, hWait, 'Calculating contours ...')
                end
            end
            app.AcceptROI = true(nROI1,1);
            delete(hWait)

            idxDelete = [];
            for jj = 1:size(out.CC,2)
                if isempty(out.CC{jj})
                    idxDelete(end+1) = jj;
                end
            end
            out.CC(idxDelete) = [];
            out.CR(idxDelete) = [];
            out.F_inferred(idxDelete,:) = [];
            out.F_raw(idxDelete,:) = [];
            out.ROI_center(idxDelete,:) = [];
            out.S_deconv(idxDelete,:) = [];
            out.spatial_comp(:,idxDelete) = [];


            setlabelcolorstoblack(app)
            app.Out = out;
            cnmfOutputsFolder  = [app.DetectFolder,filesep,'CNMF_outputs'];
            fileName = 'outCNMF.mat';
            save([cnmfOutputsFolder,filesep,fileName],'-struct','out')
            app.CNMFGuage.Value = 100;
            app.CNMFRunTimeEditField.Value = seconds(datetime("now")-runTime0);
            drawnow

            app.resultsfilenameEditField.Value = [cnmfOutputsFolder,filesep,fileName];
            app.applyQCCheckBox.Value = 0;
            app.FailedQC = false(nROI1,1);


            imshow(app.Out.meanImage,'Parent',app.UIAxesRefine1)
            hold(app.UIAxesRefine1,'on')
            if size(app.Out.ROI_center,1)>0
                plotcontours(app,app.UIAxesRefine1)
                app.HighlightedNeuron.Value = plot(app.UIAxesRefine1,app.Out.ROI_center(1,1),app.Out.ROI_center(1,2),'bo','markersize',12,'markerfacecolor','b');
                plot(app.UIAxesRefine2,app.Out.t,app.Out.F_raw(1,:),'b')
                app.cellnumberDropDown1.Value = 1;
                cellnumberDropDownValueChanged(app)
                app.AcceptROI = true(size(app.Out.ROI_center,1),1);
            end


        end

        % Button pushed function: chooseavifileButton
        function chooseavifileButtonPushed(app, event)
            aviFilesFolder  = [app.DetectFolder,filesep,'AVI_files'];
            [fileName,filePath] = uigetfile([aviFilesFolder,filesep,'*.avi'],'Choose','Choose one or more AVI files',...
                'MultiSelect','on');
            figure(app.detectFigure)
            if iscell(fileName)
                fileName = combineAVIfilesintoone(app,fileName,filePath);
            elseif fileName==0
                return
            end
            app.avifileEditField.Value = [filePath,fileName];
            if strcmp(fileName(end-2:end),'avi')
                vidObj = VideoReader([filePath,fileName]);
                if vidObj.NumFrames > 50
                    img = read(vidObj,[1 50]);
                else
                    img = read(vidObj,[1 vidObj.NumFrames]);
                end
                img = single(img);
                img = mean(img,4)/max(img(:));
                app.MeanImage = img;
            end
            app.CNMFGuage.Value = 0;
            
        end

        % Button pushed function: chooseparametersfileButton
        function chooseparametersfileButtonPushed(app, event)
            parametersFolder  = [app.DetectFolder,filesep,'detection_parameters'];
            [fileName,filePath] = uigetfile([parametersFolder,filesep,'*mat'],'Choose a detection parameters file');
            figure(app.detectFigure)
            if fileName==0
                return
            end
            foo = load([filePath,fileName]);
            if ~isfield(foo,'nROI')
                errordlg('This is not a valid detection parameters file','Error dialog','modal')
                return
            end
            app.detectionparametersfileEditField.Value = [filePath,fileName];
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            setlabelcolorstoblack(app)
            app.CNMFRunTimeEditField.Value = 0;

        end

        % Button pushed function: saveButton, saveButton_2, saveButton_3, 
        % ...and 2 other components
        function saveButtonPushed(app, event)
            cnmfOutputsFolder  = [app.DetectFolder,filesep,'CNMF_outputs'];
            [fileName,filePath] = uiputfile([cnmfOutputsFolder,filesep,'*.mat'], 'Enter filename to save results',[cnmfOutputsFolder,filesep,'outCNMF.mat']);
            out = app.Out;
            if fileName
                save([filePath,fileName],'-struct','out')
            end

        end

        % Value changed function: cellnumberDropDown1
        function cellnumberDropDownValueChanged(app, event)
            value = round(app.cellnumberDropDown1.Value);
            if isempty(app.Out), return, end
            value = max(0,value);
            N = size(app.Out.ROI_center,1);
            value = min(value,N);
            app.cellnumberDropDown1.Value = value;
            delete(app.HighlightedNeuron.Value)
            if app.AcceptROI(value)
                plot(app.UIAxesRefine1,app.Out.ROI_center(value,1),app.Out.ROI_center(value,2),'go','markersize',6,'markerfacecolor','g');
            else
                plot(app.UIAxesRefine1,app.Out.ROI_center(value,1),app.Out.ROI_center(value,2),'ro','markersize',6,'markerfacecolor','r');
            end
            app.HighlightedNeuron.Value = plot(app.UIAxesRefine1,app.Out.ROI_center(value,1),app.Out.ROI_center(value,2),'bo','markersize',12,'markerfacecolor','b');
            plot(app.UIAxesRefine2,app.Out.t,app.Out.F_raw(value,:),'b')
            axis(app.UIAxesRefine2,'tight')
            if app.AcceptROI(value)
                app.acceptButton.Value = true;
            else
                app.rejectButton.Value = true;
            end


        end

        % Button pushed function: loadresultsButton
        function loadresultsButtonPushed(app, event)
            cnmfOutputsFolder  = [app.DetectFolder,filesep,'CNMF_outputs'];
            [fileName,filePath] = uigetfile([cnmfOutputsFolder,filesep,'*mat'],'Choose a results file');
            figure(app.detectFigure)
            if fileName==0
                return
            end
            out = load([filePath,fileName]);
            app.Out = out;
            app.FTemp = app.Out.F_raw;
            cla(app.UIAxesRefine1)
            try
                imshow(app.Out.meanImage,'Parent',app.UIAxesRefine1)
                hold(app.UIAxesRefine1,'on')
                if size(app.Out.ROI_center,1)>0
                    plotcontours(app,app.UIAxesRefine1)
                    app.HighlightedNeuron.Value = plot(app.UIAxesRefine1,app.Out.ROI_center(1,1),app.Out.ROI_center(1,2),'bo','markersize',12,'markerfacecolor','b');
                    plot(app.UIAxesRefine2,app.Out.t,app.Out.F_raw(1,:),'b')
                    app.cellnumberDropDown1.Value = 1;
                    app.AcceptROI = true(size(app.Out.ROI_center,1),1);
                end
            catch
                disp('not a valid results file')
            end
            axis(app.UIAxesRefine2,'tight')
            app.resultsfilenameEditField.Value = fileName;
            app.FailedQC = zeros(size(app.Out.ROI_center,1));


        end

        % Value changed function: typeoffilesDropDown
        function typeoffilesDropDownValueChanged(app, event)
            value = app.typeoffilesDropDown.Value;
            if strcmp(value,'individual tiffs')
                app.filenameEditField.Enable = false;
                app.filenameEditField.Value = '';
            end

        end

        % Selection changed function: refineButtonGroup
        function refineButtonGroupSelectionChanged(app, event)
            selectedButton = app.refineButtonGroup.SelectedObject;
            txt = selectedButton.Text;
            cellNo = app.cellnumberDropDown1.Value;
            switch txt
                case 'reject'
                    app.AcceptROI(cellNo) = false;
                    plot(app.UIAxesRefine1,app.Out.ROI_center(cellNo,1),app.Out.ROI_center(cellNo,2),'ro','markersize',6,'markerfacecolor','r');
                    app.HighlightedNeuron.Color = 'r';
                otherwise
                    app.AcceptROI(cellNo) = true;
                    plot(app.UIAxesRefine1,app.Out.ROI_center(cellNo,1),app.Out.ROI_center(cellNo,2),'go','markersize',6,'markerfacecolor','g');
                    app.HighlightedNeuron.Color = 'g';
            end


        end

        % Button pushed function: resetButton_2
        function resetButton_2Pushed(app, event)
            app.AcceptROI(:) = true;
            app.RefinePlotHandle = plot(app.UIAxesRefine1,app.Out.ROI_center(:,1),app.Out.ROI_center(:,2),'go','markersize',6,'markerfacecolor','g');
            cellnumberDropDownValueChanged(app)

        end

        % Button pushed function: savechangesButton_2, savechangesButton_4
        function savechangesButtonPushed(app, event)
            
        % delete unaccepted ROIs
        idx = app.AcceptROI;
        out = app.Out;
        
        app.Out.F_raw = out.F_raw(idx,:);
        app.Out.F_inferred = out.F_inferred(idx,:);
        app.Out.ROI_center = out.ROI_center(idx,:);
        % app.Out.S_deconv = out.S_deconv(idx,:);
        % app.Out.spatial_comp = out.spatial_comp(:,idx);
        app.Out.CC = out.CC(idx);
        % app.Out.CR = out.CR(idx);
        if isfield(app.Out,'Pairwise')
            app.Out = rmfield(app.Out,'Pairwise');
        end
        if isfield(app.Out,'EMD')
            app.Out = rmfield(app.Out,'EMD');
        end

        saveButtonPushed(app)  
        plotcontours(app,app.UIAxesRefine1)
        
        end

        % Button pushed function: examineresults
        function examineresultsPushed(app, event)
            cnmfOutputsFolder  = [app.DetectFolder,filesep,'CNMF_outputs'];
            [fileName,filePath] = uigetfile([cnmfOutputsFolder,filesep,'*mat'],'Choose a results file');
            figure(app.detectFigure)
            if fileName==0
                return
            end
            app.resultsfilename2EditField.Value = [filePath,fileName];
            out = load([filePath,fileName]);
            app.Out = out;
            cla(app.UIAxesExamine1)

            try
                imshow(app.Out.meanImage,'Parent',app.UIAxesExamine1)
                hold(app.UIAxesExamine1,'on')
                if size(app.Out.ROI_center,1)>0
                    plot(app.UIAxesExamine1,app.Out.ROI_center(:,1),app.Out.ROI_center(:,2),'go','markersize',6,'markerfacecolor','g')
                end
                plotcontours(app,app.UIAxesExamine1)
                disableDefaultInteractivity(app.UIAxesExamine1)
            catch
                disp('not a valid results file')
            end
            
            app.NumberOfTraces = 0;
            cla(app.UIAxesExamine2)
            app.SelectedNeurons = [];

            app.resultsfilenameEditField.Value = fileName;

            cPt = round(size(app.Out.Cn)/2);
            width = round(size(app.Out.Cn)/8);
            app.ROIHandle = drawellipse(app.UIAxesExamine1,'Center',cPt([2 1]),'SemiAxes',width([2 1]));
            addlistener(app.ROIHandle,'ROIMoved',@app.plotexamineresults);
            plotexamineresults(app)


        end

        % Button pushed function: runEMDButton
        function runEMDButtonPushed(app, event)

            addpath(genpath([app.MainAppFolder,filesep,'emd']))

            if ~isfield(app.Out,'F_raw')
                disp('load a results file first')
                return
            end

            app.Out.EMD = [];

            N = size(app.Out.F_raw,1);
            z = nchoosek(1:N,2);
            fval = zeros(numel(z,1),1);

            app.Fs = app.Out.fps;
            L = size(app.Out.F_raw,2);
            foo = app.Fs/L;
            if foo < 0.05
                L = round(app.Fs/0.05);
            end
            f = app.Fs*(0:(L/2))/L;
            f = f(:);

            Ps = zeros(round(L/2)+1,N);
            for ii = 1:N
                x = app.Out.F_raw(ii,:);
                x = x - min(x);
                x = x/max(x);
                Y = fft(x);
                P2 = abs(Y/L);
                P1 = P2(1:round(L/2)+1);
                P1(2:end-1) = 2*P1(2:end-1);
                Ps(1:length(P1),ii) = P1(:);
            end

            lowFreq = find(f>0.1);
            highFreq = find(f<app.Fs/2);
            f = f(lowFreq(1):highFreq(end));
            Ps = Ps(lowFreq(1):highFreq(end),:);

            hWait = waitbar(0,'Calculating EMD ...','Name','Please wait','WindowStyle','modal');
            Nz = size(z,1);

            if isempty(ver('parallel'))

                for ii =  1:Nz
                    x1 = Ps(:,z(ii,1));
                    x2 = Ps(:,z(ii,2));
                    [~,fval(ii)] = emd(f,f,x1,x2,@gdf);

                    if rem(ii,10)==0
                        waitbar(ii/Nz, hWait, 'Calculating EMD ...')
                    end
                end

            else

                z1 = z(:,1);
                z2 = z(:,2);
                parfor ii = 1:Nz
                    x1 = Ps(:,z1(ii)); %#ok<PFBNS>
                    x2 = Ps(:,z2(ii));
                    [~,fval(ii)] = emd(f,f,x1,x2,@gdf);
                end

            end

            for jj = 1:size(z,1)
                B(z(jj,1),z(jj,2)) = fval(jj); %#ok<*AGROW>
                B(z(jj,2),z(jj,1)) = fval(jj);
            end
            delete(hWait)

            app.EMD = B;
            app.Out.EMD = app.EMD;
            saveButtonPushed(app)

            plotemdlinkage(app)

            rmpath(genpath([app.MainAppFolder,filesep,'emd']))


        end

        % Value changed function: EMDSlider
        function EMDSliderValueChanged(app, event)
            value = app.EMDSlider.Value;
            plotemdlinkage(app,value)


        end

        % Button pushed function: loadfileButton, loadfileButton_2, 
        % ...and 3 other components
        function loadpairwisefileButtonPushed(app, event)
            cnmfOutputsFolder  = [app.DetectFolder,filesep,'CNMF_outputs'];
            [fileName,filePath] = uigetfile([cnmfOutputsFolder,filesep,'*mat'],'Choose a results file');
            figure(app.detectFigure)
            if fileName==0
                return
            end
            app.Out = load([filePath,fileName]);

            ax = {app.UIAxesPairwise1, app.UIAxesEMD1, app.UIAxesWaterfall1, app.UIAxesSpikes1};
            for ii = 1:numel(ax)
                try
                    plotcontours(app,ax{ii})
                    disableDefaultInteractivity(ax{ii})
                catch
                    disp('not a valid results file')
                end
            end
            app.pairwisefilename.Value = fileName(1:end-4);
            app.emdfilename.Value = fileName(1:end-4);
            app.waterfallfilename.Value = fileName(1:end-4);
            app.inferredfilename.Value = fileName(1:end-4);

            cla(app.UIAxesPairwise2)
            cla(app.UIAxesEMD2)
            cla(app.UIAxesWaterfall2)

            analysisPresent = false;

            if isfield(app.Out,'Pairwise')
                app.Pairwise = app.Out.Pairwise;
                plotpairwiselinkage(app)
                analysisPresent = true;
            else
                runPairwiseButtonPushed(app)
                plotpairwiselinkage(app);
            end

            if isfield(app.Out,'EMD')
                app.EMD = app.Out.EMD;
                plotemdlinkage(app)
                analysisPresent = true;
            end

            if isfield(app.Out,'F_raw')
               populatecrosscorrelationlistbox(app) 
               plotcrosscorrelation(app)
            end

            if ~analysisPresent
                app.classificationtypeDropDown.Value = 'none';
                plotwaterfall(app)
            end
        
            app.savedirectoryEditField.Value = filePath;
            
        end

        % Button pushed function: runPairwiseButton
        function runPairwiseButtonPushed(app, event)
            if ~isfield(app.Out,'F_raw')
                disp('load a results file first')
                return
            end

            app.Out.Pairwise = [];

            B = corr(app.Out.F_raw');

            app.Pairwise = B;
            app.Out.Pairwise = app.Pairwise;
            % saveButtonPushed(app)

            plotpairwiselinkage(app)

        end

        % Value changed function: PairwiseSlider
        function PairwiseSliderValueChanged(app, event)
            value = app.PairwiseSlider.Value;
            try
                plotpairwiselinkage(app,value)
            catch
                runPairwiseButtonPushed(app)
                plotpairwiselinkage(app,value);
            end
            
        end

        % Value changed function: classificationtypeDropDown
        function classificationtypeDropDownValueChanged(app, event)
            plotwaterfall(app)
        end

        % Button pushed function: zoomwindowButton, zoomwindowButton_2, 
        % ...and 3 other components
        function zoomwindowButtonPushed(app, event)

            if ~isempty(app.ZoomWindow)
                delete(app.ZoomWindow)
            end
            if strcmp(event.Source.Tag,'waterfall') || strcmp(event.Source.Tag,'pairwise') || strcmp(event.Source.Tag,'emd') || strcmp(event.Source.Tag,'crosscorrelation')
                if strcmp(event.Source.Tag,'pairwise') || strcmp(event.Source.Tag,'crosscorrelation')
                    app.classificationtypeDropDown.Value = 'pairwise correlation';
                    plotwaterfall(app)
                elseif strcmp(event.Source.Tag,'emd')
                    app.classificationtypeDropDown.Value = 'EMD';
                    plotwaterfall(app)
                end
                app.ZoomWindow = zoomwindow(app);
            else
                app.ZoomWindow = zoomwindow1(app);
            end

        end

        % Button pushed function: Info_1, Info_10, Info_11, Info_12, 
        % ...and 25 other components
        function info_ButtonPushed(app, event)
            ff = event.Source.Tag;
            if ~isempty(app.InfoApp) && isvalid(app.InfoApp)
                fillininfotext(app.InfoApp,ff)
            else
                app.InfoApp = informationparameters(app,ff);
            end
                

        end

        % Button pushed function: specifyavifolderButton
        function specifyavifolderButtonPushed(app, event)
            filePath = uigetdir(app.SelectedPath,'Choose folder containing AVI files');
            figure(app.detectFigure)
            if filePath==0
                return
            end
            app.SelectedPath = filePath;
            app.motioncorrectionEditField.Value = filePath;

            
        end

        % Button pushed function: runmotioncorrectionButton
        function runmotioncorrectionButtonPushed(app, event)
            filePath = app.motioncorrectionEditField.Value;
 
            optionsMC.norm_method = app.normalizationmethodDropDown.Value;
            optionsMC.norm_radius = app.normalizationradiusEditField.Value;
            optionsMC.threshold = app.thresholdEditField.Value;
            optionsMC.central_fraction = app.centralfractionEditField.Value;
            optionsMC.fftdenoise = app.fftdenoiseDropDown.Value;
            optionsMC.runwarp = app.warpcorrectionDropDown.Value;
            if strcmp(optionsMC.runwarp,'Yes')
                optionsMC.runwarp = 1;
            else
                optionsMC.runwarp = 0;
            end
            if strcmp(optionsMC.fftdenoise,'true')
                optionsMC.fftdenoise = 1;
            else
                optionsMC.fftdenoise = 0;
            end
            optionsMC.affine_radius = app.affineradiusEditField.Value;
            optionsMC.moving_average = app.movingaveragesizeEditField.Value;
            optionsMC.warp_blocksize = app.blocksizeEditField.Value;

            addpath(genpath([app.MainAppFolder,filesep,'PatchWarp']))
            motioncorrection(app,filePath,optionsMC);
            rmpath(genpath([app.MainAppFolder,filesep,'PatchWarp']))

            app.originalMCfolderEditField.Value = [filePath,filesep,'motion_correction'];
            app.correctedMCfolderEditField.Value = [filePath,filesep,'motion_correction',filesep,'corrected'];
            populateMClistbox(app,0)


        end

        % Button pushed function: usesavedROIButton
        function usesavedROIButtonPushed(app, event)
            aviFilesFolder  = [app.DetectFolder,filesep,'AVI_files'];
            [fileName,filePath] = uigetfile([aviFilesFolder,filesep,'*.mat'],...
                'Enter ROI file to apply');
            figure(app.detectFigure)
            if fileName==0
                return
            end
            load([filePath,filesep,fileName],'parsROI')
            app.Mask = parsROI.Mask;
            app.minEditField.Value = parsROI.minEditField;
            app.maxEditField.Value = parsROI.maxEditField;
            for ii = 1:size(app.ImageData,3)
                app.ImageData(:,:,ii) = app.ImageData(:,:,ii) .* app.Mask;
            end
            app.MeanImage = mean(app.ImageData,3);
            img = app.MeanImage;
            img = img - app.minEditField.Value;
            img = img / (app.maxEditField.Value-app.minEditField.Value);
            img(img<0) = 0;
            img(img>1) = 1;
            imshow(img,'Parent',app.UIAxesLoad)


        end

        % Button pushed function: saveROIButton
        function saveROIButtonPushed(app, event)
            parsROI.Mask = app.Mask;
            parsROI.minEditField = app.minEditField.Value;
            parsROI.maxEditField = app.maxEditField.Value;
            aviFilesFolder  = [app.DetectFolder,filesep,'AVI_files'];
            [fileName,filePath] = uiputfile([aviFilesFolder,filesep,'*.mat'], 'Enter filename to save ROI',[aviFilesFolder,filesep,'roiFile.mat']);
            save([filePath,filesep,fileName],"parsROI")


        end

        % Value changed function: fftdenoiseDropDown
        function fftdenoiseDropDownValueChanged(app, event)
            value = app.fftdenoiseDropDown.Value;
            if strcmp(value,'true')
                app.normalizationmethodDropDown.Value = 'local';
            end

        end

        % Value changed function: normalizationmethodDropDown
        function normalizationmethodDropDownValueChanged(app, event)
            value = app.normalizationmethodDropDown.Value;
            if strcmp(value,'rank')
                app.fftdenoiseDropDown.Value = 'false';
            end
            
        end

        % Button pushed function: chooseMCfolderButton
        function chooseMCfolderButtonPushed(app, event)
            filePath = uigetdir(app.SelectedPath,'Select folder with corrected files'); % "corrected" folder
            figure(app.detectFigure)
            if filePath ~= 0
                app.correctedMCfolderEditField.Value = filePath;
                populateMClistbox(app,2)
            end

        end

        % Button pushed function: playvideosButton
        function playvideosButtonPushed(app, event)
            if strcmp(app.playvideosButton.Text,'play videos')
                app.playvideosButton.Text = 'stop videos';
                app.StopPlayback = false;
            else
                app.playvideosButton.Text = 'play videos';
                app.StopPlayback = true;
            end
            
            boo = app.correctedMCfolderEditField.Value;
            foo = app.correctedfilesListBox.Value;
            if contains(foo,'warped')
                correctedFile = [boo,filesep,'post_warp',filesep,foo];
            else
                correctedFile = [boo,filesep,'pre_warp',filesep,foo];
            end

            boo = app.originalMCfolderEditField.Value;
            foo = app.originalfilesListBox.Value;
            originalFile = [boo,filesep,foo];
            info = imfinfo(originalFile);
            nFrames = numel(info);
            set(app.UIAxesMC1,'Visible','off')
            set(app.UIAxesMC2,'Visible','off')
            colormap(app.UIAxesMC1,'gray')
            colormap(app.UIAxesMC2,'gray')     
            for ii = 1:nFrames
                img1 = imread(originalFile,ii);
                img2 = imread(correctedFile,ii);
                image(app.UIAxesMC1,img1)
                image(app.UIAxesMC2,img2)
                drawnow
                pause(0.1)
                if app.StopPlayback
                    break
                end
            end
            app.playvideosButton.Text = 'play videos';

        end

        % Button pushed function: acceptcorrectfilesButton
        function acceptcorrectfilesButtonPushed(app, event)
            
            filePath0 = app.motioncorrectionEditField.Value;
            filePath0 = [filePath0,filesep,'motion_correction'];
            filePath1 = [filePath0,filesep,'corrected'];
            if isfolder([filePath1,filesep,'post_warp'])
                filePath1 = [filePath1,filesep,'post_warp'];
            else
                filePath1 = [filePath1,filesep,'pre_warp'];
            end
            filePath2 = uigetdir(filePath0,'Choose a folder in which to save corrected files');
            figure(app.detectFigure)
            if filePath2 == 0
                return
            end
            app.SelectedPath = filePath2;   
            ff = dir([filePath1,filesep,'*.tif*']);
            app.SelectedFile = [filePath1,filesep,ff(1).name];
            info = imfinfo([filePath1,filesep,ff(1).name]);
            nFrames = numel(info);
            nTotal = nFrames * numel(ff);
            hWait = waitbar(0,'Saving images ...','Name','Please wait','WindowStyle','modal');
            kk = 0;
            for ii = 1:numel(ff)
                fileName1 = [filePath1,filesep,ff(ii).name];
                info = imfinfo(fileName1);
                nFrames = numel(info);
                foo = strfind(ff(ii).name,'.');
                foo = ff(ii).name(1:foo);
                fileName2 = [filePath2,filesep,foo,'avi'];                
                v = VideoWriter(fileName2,'Uncompressed AVI'); 
                if isempty(app.Fs)
                    fps = inputdlg('Please specify the frame rate (fps)');
                    app.Fs = str2double(fps);
                end
                v.FrameRate = app.Fs;
                open(v)
                for jj = 1:nFrames
                    kk = kk + 1;
                    img = imread(fileName1,jj);
                    img = single(img);
                    img = img - min(img(:));
                    img = img/(max(img(:)-min(img(:))));
                    writeVideo(v,img)
                    if rem(kk,100)==0
                        waitbar(kk/nTotal, hWait, 'Saving images ...')
                    end
                end
                close(v)
                delete(v)
            end
            delete(hWait)


        end

        % Button pushed function: choosereferencefolderButton
        function choosereferencefolderButtonPushed(app, event)
            filePath = uigetdir(app.SelectedPath,'Select folder containing reference files');
            figure(app.detectFigure)
            if filePath == 0
                return
            end
            ff = dir([filePath,filesep,'*.tif*']);
            for ii = 1:numel(ff)
                if contains(ff(ii).name,'mean')
                    app.FileMeanReference = [filePath,filesep,ff(ii).name];
                end
                if contains(ff(ii).name,'max')
                    app.FileMaxReference = [filePath,filesep,ff(ii).name];
                end
            end
            if or(isempty(app.FileMeanReference),isempty(app.FileMaxReference))
                warndlg('No appropriate reference files found. Select again.')
                return
            end
            app.referencefolderEditField.Value = filePath;


        end

        % Button pushed function: choosetargetfolderButton
        function choosetargetfolderButtonPushed(app, event)
            filePath = uigetdir(app.SelectedPath,'Select folder containing target files');
            figure(app.detectFigure)
            if filePath == 0 
                return
            end
            ff = dir([filePath,filesep,'*.tif*']);
            for ii = 1:numel(ff)
                if contains(ff(ii).name,'mean')
                    app.FileMeanTarget = [filePath,filesep,ff(ii).name];
                end
                if contains(ff(ii).name,'max')
                    app.FileMaxTarget = [filePath,filesep,ff(ii).name];
                end
            end
            if or(isempty(app.FileMeanTarget),isempty(app.FileMaxTarget))
                warndlg('No appropriate target files found. Select again.')
                return
            end
            app.targetfolderEditField.Value = filePath;


        end

        % Button pushed function: calculateregistrationButton
        function calculateregistrationButtonPushed(app, event)
            

            [fileName,filePath] = uiputfile([app.SelectedPath,filesep,'*.mat'], 'Enter file name under which to save results', [app.SelectedPath,filesep,'regFile.mat']);
            if fileName == 0
                return
            end
            try
                image1_mean = double(imread(app.FileMeanReference));
                image1_max = double(imread(app.FileMaxReference));
                image2_mean = double(imread(app.FileMeanTarget));
                image2_max = double(imread(app.FileMaxTarget));
            catch
                disp('Specify mean and max projection files first.')
            end

            image1_mean = 1000*reshape(normalize(image1_mean(:), 'range'), size(image1_mean));
            image2_mean = 1000*reshape(normalize(image2_mean(:), 'range'), size(image2_mean));
            image1_max = 1000*reshape(normalize(image1_max(:), 'range'), size(image1_max));
            image2_max = 1000*reshape(normalize(image2_max(:), 'range'), size(image2_max));
            image1_all = cat(3, image1_mean, image1_max);
            image2_all = cat(3, image2_mean, image2_max);
            transform1 = 'euclidean';
            transform2 = 'affine';
            warp_blocksize = 6;
            warp_overlap_pix_frac = 0.15;
            norm_radius = 32;     % Set to 0 when the signals are sparse or the result does not look good.
            addpath(genpath([app.MainAppFolder,filesep,'PatchWarp']))
            patchwarp_results = patchwarp_across_sessions(image1_all, image2_all, transform1, transform2, warp_blocksize, warp_overlap_pix_frac, norm_radius);
            rmpath(genpath([app.MainAppFolder,filesep,'PatchWarp']))
            save([filePath,filesep,fileName],'patchwarp_results')
            app.registrationresultsEditField.Value = [filePath,fileName];


        end

        % Button pushed function: loadregistrationparametersButton
        function loadregistrationparametersButtonPushed(app, event)
            [fileName,filePath] = uigetfile([app.SelectedPath,filesep,'*.mat'], 'Specify file with registration parameters', 'regFile.mat');
            figure(app.detectFigure)
            if fileName==0
                return
            end
            app.registrationparametersEditField.Value = [filePath,filesep,fileName];


        end

        % Button pushed function: specifyfiletoberegisteredButton
        function specifyfiletoberegisteredButtonPushed(app, event)
            [fileName,filePath] = uigetfile(fullfile(app.SelectedPath,filesep,'*.tif;*.tiff'),'Select TIF file to be registered');
            figure(app.detectFigure)
            if fileName == 0
                return
            end
            app.filetoberegisteredEditBox.Value = [filePath,filesep,fileName];


        end

        % Button pushed function: runregistrationalgorithmButton
        function runregistrationalgorithmButtonPushed(app, event)
            addpath(genpath([app.MainAppFolder,filesep,'PatchWarp']))
            second_affine = 1;
            fileName = app.filetoberegisteredEditBox.Value;
            input_images = double(read_tiff(fileName));
            load(app.registrationparametersEditField.Value,'patchwarp_results');
            [~, registeredImages] = patchwarp_across_sessions_apply(input_images, patchwarp_results, second_affine);

            foo = strfind(fileName,'.');
            fileName1 = [fileName(1:foo),'avi'];
            v = VideoWriter(fileName1,'Uncompressed AVI');
            v.FrameRate = app.framerateregistrationEditField.Value;
            maxRegisteredImages = max(registeredImages(:));
            registeredImages = registeredImages/maxRegisteredImages;
            registeredImages(registeredImages<0) = 0;
            open(v)
            for ii = 1:size(registeredImages,3)
                writeVideo(v,registeredImages(:,:,ii))
            end
            close(v)
            rmpath(genpath([app.MainAppFolder,filesep,'PatchWarp']))


        end

        % Value changed function: applyQCCheckBox, bordersizeEditField, 
        % ...and 4 other components
        function applyQCCheckBoxValueChanged(app, event)
            value = app.applyQCCheckBox.Value;
            nROI = size(app.Out.F_raw,1);
            if ~value
                app.FailedQC = false(nROI,1);
                app.Out.F_raw = app.FTemp;
            else
                app.FTemp = app.Out.F_raw;
                testforQC(app);
            end
            app.AcceptROI(app.FailedQC) = false;
            app.AcceptROI(~app.FailedQC) = true;

            for ii = 1:nROI
                if app.AcceptROI(ii)
                    plot(app.UIAxesRefine1,app.Out.ROI_center(ii,1),app.Out.ROI_center(ii,2),'go','markersize',6,'markerfacecolor','g')
                else
                    plot(app.UIAxesRefine1,app.Out.ROI_center(ii,1),app.Out.ROI_center(ii,2),'ro','markersize',6,'markerfacecolor','r')
                end
            end
            cla(app.UIAxesRefine2)
            plot(app.UIAxesRefine2,app.Out.t,app.Out.F_raw(1,:),'b')
            axis(app.UIAxesRefine2,'tight')
            app.cellnumberDropDown1.Value = 1;
            idx = app.cellnumberDropDown1.Value;
            if app.AcceptROI(idx)
                app.acceptButton.Value = true;
            else
                app.rejectButton.Value = true;
            end


        end

        % Value changed function: nonlinearityDropDown
        function nonlinearityDropDownValueChanged(app, event)
            value = app.nonlinearityDropDown.Value;
            foo = load('defaultParML.mat','parML');
            switch value
                case 'GCaMP6s'
                    par = foo.parML.GCaMP6s;
                case 'GCaMP6f'
                    par = foo.parML.GCaMP6f;
                case 'OGB'
                    par = foo.parML.OGBl;
                case 'Cal520'
                    par = foo.parML.Cal520;
                otherwise % Manual
                    % use entries in the GUI
            end
            if ~strcmp(value,'Manual')
                app.AmplitudeAEditField.Value = par.a;
                app.decaytimetauEditField.Value = par.tau;
                app.baselineminEditField.Value = par.F0(1);
                app.baselinemaxEditField.Value = par.F0(2);
            end
            app.parML = par;
        end

        % Button pushed function: runMLSpikeButton
        function runMLSpikeButtonPushed(app, event)
            
            addpath(genpath([app.MainAppFolder,filesep,'spikes']))
            addpath(genpath([app.MainAppFolder,filesep,'brick']))

            A = app.AmplitudeAEditField.Value;
            tau = app.decaytimetauEditField.Value;
            sigma = app.noisesigmaEditField.Value;
            F0_min = app.baselineminEditField.Value;
            F0_max = app.baselinemaxEditField.Value;
            par.dt = 1/app.Out.fps;
            par.a = A;
            par.tau = tau;
            par.finetune.sigma = sigma;
            par.F0 = [F0_min F0_max];
            cla(app.UIAxesSpikes2)
            cla(app.UIAxesSpikes3)
            nCells = size(app.Out.F_raw,1);
            dt = 1/app.Out.fps;
            hWait = waitbar(0,'Estimating spike times ...','Name','Please wait','WindowStyle','modal');
            app.parML = par;
            for ii = 1:nCells
                fr = app.Out.F_raw(ii,:);
                spk{ii} = spk_est(fr,par); 
                waitbar(ii/nCells,hWait,'Estimating spike times ...')
            end
            app.SpikeEstimate = spk;
            app.Out.spikeEstimate = spk;
            
            plotspikeestimation(app,spk,dt)
            disableDefaultInteractivity(app.UIAxesSpikes1)


            drawnow
            
            delete(hWait)

            rmpath(genpath([app.MainAppFolder,filesep,'spikes']))
            rmpath(genpath([app.MainAppFolder,filesep,'brick']))


        end

        % Button pushed function: runspiketrainmetricButton
        function runspiketrainmetricButtonPushed(app, event)

            spk = app.SpikeEstimate;
            tau = app.timescaleEditField.Value;
            dist = zeros(numel(spk),numel(spk));
            distanceType = app.metricDropDown.Value;

            switch distanceType
               
                case 'Victor & Purpura'
                    %%% http://www-users.med.cornell.edu/~jdvicto/spkdm.html
                    %%% adapted by code from Daniel S. Reich and Jonathan Victor
                    for ii = 1:numel(spk)
                        for jj = 1:numel(spk)
                            sp1 = spk{ii};
                            sp2 = spk{jj};
                            nspi=length(sp1);
                            nspj=length(sp2);
                            if tau==0
                                dist(ii,jj)=abs(nspi-nspj);
                                continue
                            elseif tau==Inf
                                dist(ii,jj) =nspi+nspj;
                                continue
                            end

                            scr=zeros(nspi+1,nspj+1);
                            scr(:,1)=(0:nspi)';
                            scr(1,:)=(0:nspj);
                            if nspi && nspj
                                for i=2:nspi+1
                                    for j=2:nspj+1
                                        scr(i,j)=min([scr(i-1,j)+1 scr(i,j-1)+1 scr(i-1,j-1)+tau*abs(sp1(i-1)-sp2(j-1))]);
                                    end
                                end
                            end
                            dist(ii,jj) =scr(nspi+1,nspj+1);
                        end
                    end
                    app.VictorPurpura = dist;

                case 'Van Rossum'
                    %%% Van Rossum spike distance metric, Neural Computation (2001)
                    dt = 1/app.Out.fps;
                    t = (1:length(app.Out.F_raw))*dt - dt;
                    e1 = exp(-t/tau);
                    s1 = zeros(length(t),1);
                    for ii = 1:numel(spk)
                        for jj = 1:numel(spk)
                            sp1 = s1;
                            if ~isempty(spk{ii})
                                sp1(ceil(spk{ii}*dt)) = 1;
                            end
                            sp2 = s1;
                            if ~isempty(spk{jj})
                                sp2(ceil(spk{jj}*dt)) = 1;
                            end
                            conSpk1 = conv(sp1,e1,'same');
                            conSpk2 = conv(sp2,e1,'same');
                            dist(ii,jj) = sqrt((2/tau)*trapz((conSpk1-conSpk2).^2)*dt);

                        end
                    end
                    app.VanRossum = dist;

                case 'ISI'
                    %%% ISI-distance Kreuz
                    dt = 1/app.Out.fps;
                    for ii = 1:numel(spk)
                        for jj = 1:numel(spk)
                            R = zeros(size(app.Out.F_raw,2),2);
                            R(round(spk{ii}/dt),1) = 1;
                            R(round(spk{jj}/dt),2) = 1;
                            R(1,:) = 1;
                            R(end,:) = 1;
                            dist(ii,jj) = calculatekreuz(app,R);
                        end
                    end
                 case 'SPIKE'
                    %%% SPIKE-distance Kreuz
                    dt = 1/app.Out.fps;
                    for ii = 1:numel(spk)
                        for jj = 1:numel(spk)
                            R = zeros(size(app.Out.F_raw,2),2);
                            R(round(spk{ii}/dt),1) = 1;
                            R(round(spk{jj}/dt),2) = 1;
                            R(1,:) = 1;
                            R(end,:) = 1;
                            [~,dist(ii,jj)] = calculatekreuz(app,R);
                        end
                    end
            end

            dist = flipud(dist);
            axes(app.UIAxesSpikes3)
            cla(app.UIAxesSpikes3)
            imagesc(dist)
            colormap(app.UIAxesSpikes3, 'parula')
            axis(app.UIAxesSpikes3,'tight')
            nNeurons = numel(spk);
            app.UIAxesSpikes3.YTick = 1:nNeurons;
            app.UIAxesSpikes3.YTickLabel = nNeurons:-1:1;

        end

        % Button pushed function: autoMLButton
        function autoMLButtonPushed(app, event)
            % automatically estimate good parameters for these data

            addpath(genpath([app.MainAppFolder,filesep,'spikes']))
            addpath(genpath([app.MainAppFolder,filesep,'brick']))
            dt = 1/app.Out.fps;
            [tau,amp,sigmaest] = spk_autocalibration(app.Out.F_raw',dt);
            app.AmplitudeAEditField.Value = amp*100;
            app.decaytimetauEditField.Value = tau;
            app.noisesigmaEditField.Value = sigmaest/10;
            rmpath(genpath([app.MainAppFolder,filesep,'spikes']))
            rmpath(genpath([app.MainAppFolder,filesep,'brick']))

        end

        % Button pushed function: exporttoEMFButton, exporttoPNGButton, 
        % ...and 1 other component
        function exporttoFIGButtonPushed(app, event)
             tag = event.Source.Tag;

             names = {  'Cross1';
                        'Cross2';
                        'EMD1';
                        'EMD2';
                        'EMD3';
                        'Spikes1';
                        'Spikes2';
                        'Spikes3';
                        'Pairwise1';
                        'Pairwise2';
                        'Pairwise3';
                        'Waterfall1';
                        'Waterfall2'};
             filePath = uigetdir(app.SelectedPath,'Choose folder in which to save files');
             figure(app.detectFigure)
             app.savedirectoryEditField.Value = filePath;
             hWait = waitbar(0,'Saving files ... please wait');
             for ii = 1:numel(names)
                 hFig = app.(['UIAxes',names{ii}]);
                 fileName = [filePath,filesep,names{ii}];
                 try
                     switch tag
                         case 'exportToTIFF'
                             fileName = [fileName,'.tiff'];
                             exportgraphics(hFig,fileName,"Resolution",300)
                         case 'exportToEMF'
                             fileName = [fileName,'.emf'];
                             exportgraphics(hFig,fileName,"Resolution",300)
                         case 'exportToPNG'
                             fileName = [fileName,'.png'];
                             exportgraphics(hFig,fileName,"Resolution",300)
                     end
                     waitbar(ii/numel(names),hWait)
                 catch
                     % keep going
                 end
             end
             delete(hWait)


        end

        % Button pushed function: exporttoCSVButton, exporttoEXCELButton, 
        % ...and 1 other component
        function exporttoMATButtonPushed(app, event)
            tag = event.Source.Tag;
            out = [];

            try
                out.DeltaFoverF = app.Out.F_raw;
                out.CentersROI = app.Out.ROI_center;
                out.MeanImage = app.Out.meanImage;
                out.ContourLines = app.Out.CC;
                out.SpatialComponents = app.Out.spatial_comp;
                out.SpatialMatrixImage = app.Out.Cn;
            catch
                disp('You must run the CNMF algorithm first before attempting to save')
                return
            end
            if isfield(app.Out,'Pairwise')
                out.Pairwise = app.Out.Pairwise;
            end
            if isfield(app.Out,'EMD')
                out.EMD = app.Out.EMD;
            end

            if isempty(out)
                disp('You must run some analyses first before attempting to save')
                return
            end
            
            
            hWait = waitbar(0,'Saving files ... please wait');
            switch tag
                case 'exportToMAT'
                    [fileName,filePath] = uiputfile('*.mat','Name file into which to save analyses');
                    app.savedirectoryEditField.Value = filePath;
                    if fileName==0
                        return
                    end
                    save([filePath,fileName],'out')

                case 'exportToExcel'
                    [fileName,filePath] = uiputfile('*.xlsx','Name Excel file into which to save analyses');
                    if fileName==0
                        return
                    end
                    fileName = [filePath,fileName];
                    out = rmfield(out,{'MeanImage','ContourLines','SpatialComponents','SpatialMatrixImage'}); % these are too big for Excel
                    fNames = fieldnames(out);
                    for ii = 1:numel(fNames)
                        data = out.(fNames{ii});
                        T = array2table(data);
                        writetable(T,fileName,'Sheet',fNames{ii},'WriteVariableNames',false);
                        waitbar(ii/numel(fNames),hWait)
                    end

                case 'exportToCSV'
                    filePath = uigetdir(app.SelectedPath,'Choose folder in which to save files');
                    figure(app.detectFigure)
                    app.savedirectoryEditField.Value = filePath;
                    if filePath==0
                        return
                    end
                    baseName = inputdlg('Specify base name for CSV files','CSV files');
                    if isempty(baseName)
                        return
                    end
                    out = rmfield(out,{'ContourLines','SpatialComponents','SpatialMatrixImage'});
                    fNames = fieldnames(out);
                    for ii = 1:numel(fNames)
                        data = out.(fNames{ii});
                        ff = [baseName{:},'_',fNames{ii},'.csv'];
                        writematrix(data,ff)
                        waitbar(ii/numel(fNames),hWait)
                    end
            end
            waitbar(1,hWait)
            delete(hWait)


        end

        % Button pushed function: chooseMCfolderButton_2
        function chooseMCfolderButton_2Pushed(app, event)
            filePath = uigetdir(app.SelectedPath,'Select folder with original files'); 
            figure(app.detectFigure)
            if filePath ~= 0
                app.originalMCfolderEditField.Value = filePath;
                populateMClistbox(app,1)
            end

        end

        % Value changed function: zscoreviewButton
        function zscoreviewButtonValueChanged(app, event)
            plotexamineresults(app)

        end

        % Button pushed function: calculatecrosscorrelationButton
        function calculatecrosscorrelationButtonPushed(app, event)
            plotcrosscorrelation(app)

        end

        % Button pushed function: applytootherAVIfilesButton, 
        % ...and 1 other component
        function choosedefaultCNMFfileButtonPushed(app, event)
            persistent fileNameCNMF filePathCNMF 

            tag = event.Source.Tag;
            if strcmp(tag,'choosedefaultcnmffile')
                cnmfOutputsFolder = [app.DetectFolder,filesep,'CNMF_outputs'];
                [fileNameCNMF,filePathCNMF] = uigetfile([cnmfOutputsFolder,filesep,'*.mat'],'select a CNMF file');
                figure(app.detectFigure)
            elseif strcmp(tag,'applytootheravifiles')
                if isempty(fileNameCNMF)
                    warndlg('Choose a default CNMF file first','NO CNMF FILE CHOSEN')
                    return
                end
                cnmfOutputsFolder = [app.DetectFolder,filesep,'CNMF_outputs'];
                aviFilesFolder = [app.DetectFolder,filesep,'AVI_files'];
                [fileNameAVI,filePathAVI] = uigetfile([aviFilesFolder,filesep,'*.avi'],'select one or more files', ...
                    'MultiSelect', 'on');
                if fileNameAVI==0
                    return
                end
                load([filePathCNMF,fileNameCNMF]) %#ok<LOAD>
                avifile = [filePathAVI,fileNameAVI];
                vidObj = VideoReader(avifile);
                hSize = size(Cn,1); %#ok<USENS>
                vSize = size(Cn,2);
                nROI = size(F_raw,1); %#ok<NODEF>
                F_temp = zeros(nROI,vidObj.NumFrames);
                smoothFrames = round(100*fps); %#ok<USENS>
                hWait = msgbox('Applying cell boundaries to new images','PLEASE WAIT','warn');
                for jj = 1:vidObj.NumFrames
                    img = read(vidObj,jj);
                    img = single(img(:,:,1));
                    for ii = 1:nROI
                        bw = spatial_comp(:,ii); %#ok<USENS>
                        bw = reshape(bw,hSize,vSize);
                        bw = full(bw);
                        img1 = bw.*img;
                        F_temp(ii,jj) = mean(img1(:));
                    end
                end
                F_raw = F_temp;
                for kk = 1:size(F_raw,1)
                    z = smooth(F_raw(kk,:),smoothFrames);
                    F_raw(kk,:) = F_raw(kk,:) - z';
                end
                F_inferred = [];
                [newFileName,newFilePath] = uiputfile([cnmfOutputsFolder,filesep,'*.mat'], ...
                    'Enter filename to save CNMF output',[cnmfOutputsFolder,filesep,'outCNMFnew.mat']);
                save([newFilePath,newFileName],'F_raw','F_inferred',...
                    'avifile','CC','Cn','center','fps','meanImage',...
                    'ROI_center','S_deconv','spatial_comp','t','YrA'); %#ok<USENS>
                delete(hWait)

            end


        end

        % Value changed function: clicktoselectSwitch
        function clicktoselectButtonValueChanged(app, event)
            persistent useClick roi

            if isempty(app.Out), return, end
            roi = [];
            useClick = app.clicktoselectSwitch.Value;

            hManager = uigetmodemanager(app.detectFigure);

            if strcmp(useClick,'Off')
                roi = [];
                [hManager.WindowListenerHandles.Enabled] = deal(true);
            else
                [hManager.WindowListenerHandles.Enabled] = deal(false);
                while strcmp(useClick,'On')
                    roi = drawpoint(app.UIAxesRefine1);
                    try
                        foo = roi.Position;
                        x = foo(1); y = foo(2);
                        delete(roi)
                    catch
                        return
                    end
                    sz = size(app.Out.meanImage);
                    if (x>sz(2)||x<1) || (y>sz(1)||y<1)
                        pause(0.01)
                    else
                        boo = app.Out.ROI_center-[x,y];
                        [~,bar] = min(boo(:,1).^2 + boo(:,2).^2);
                        app.cellnumberDropDown1.Value = bar;
                        cellnumberDropDownValueChanged(app)
                    end
                end
                [hManager.WindowListenerHandles.Enabled] = deal(true);
            end

        end

        % Button pushed function: placeacellmanuallyButton
        function placeacellmanuallyButtonPushed(app, event)

            app.clicktoselectSwitch.Value = 'Off';
            clicktoselectButtonValueChanged(app)
            zoom(app.UIAxesRefine1,'off')
            
            hManager = uigetmodemanager(app.detectFigure);      
            [hManager.WindowListenerHandles.Enabled] = deal(false);

            app.ROIHandle2 = drawpolygon(app.UIAxesRefine1);
            mask = createMask(app.ROIHandle2);
            vidObj = VideoReader(app.Out.avifile);
            F_raw = zeros(1,vidObj.NumFrames);
            app.ImageData = zeros(vidObj.Height,vidObj.Width,vidObj.NumFrames,'uint8');
            hWait = msgbox('Loading images -- may take a while','PLEASE WAIT','warn');
            for jj = 1:vidObj.NumFrames
                img = read(vidObj,jj);
                img = single(img(:,:,1));
                img1 = mask.*img;
                F_raw(jj) = mean(img1(:));
                app.ImageData(:,:,jj) = img;
            end
            F_raw = calculatedeltaFoverF(app,F_raw);
            delete(hWait)
            plot(app.UIAxesRefine2,app.Out.t,F_raw)
            addlistener(app.ROIHandle2,'ROIMoved',@app.neuronadded);


        end

        % Button pushed function: acceptmanualcellButton
        function acceptmanualcellButtonPushed(app, event)
            if ishandle(app.ROIHandle2)
                mask = createMask(app.ROIHandle2);
                nFrames = size(app.ImageData,3);
                F_raw = zeros(1,nFrames);
                for jj = 1:nFrames
                    img1 = single(app.ImageData(:,:,jj));
                    img1 = mask.*img1;
                    F_raw(jj) = mean(img1(:));
                end
                F_inferred = F_raw;
                app.Out.CC{end+1} = app.ROIHandle2.Position';
                app.AcceptROI(end+1) = true;
                app.Out.ROI_center(end+1,:) = mean(app.ROIHandle2.Position);
                app.Out.F_raw(end+1,:) = F_raw;
                app.Out.F_inferred(end+1,:) = F_inferred;
                foo = mask .* app.Out.meanImage;
                foo = foo(:,:,1);
                app.Out.spatial_comp(:,end+1) = foo(:);
                delete(app.ROIHandle2)
                cla(app.UIAxesRefine1)
                imshow(app.Out.meanImage,'Parent',app.UIAxesRefine1)
                hold(app.UIAxesRefine1,'on')
                plotcontours(app,app.UIAxesRefine1)
                app.cellnumberDropDown1.Value = size(app.Out.F_raw,1);
                cellnumberDropDownValueChanged(app)
                % hManager = uigetmodemanager(app.detectFigure);            
                % [hManager.WindowListenerHandles.Enabled] = deal(true);

            end


        end

        % Callback function: analyzeallButton, detectionthresholdDropDown, 
        % ...and 1 other component
        function analyzeallButtonPushed(app, event)
            
            F = app.Out.F_raw;
            foo = app.detectionthresholdDropDown.Value;
            stdF = std(F,[],2);
            minPeakWidth = round(0.5*app.Out.fps);
            detectThreshold = stdF*str2double(foo(1));
            warning off
            dt = 1/app.Out.fps;
            for ii = 1:size(F,1)
                detectThreshold(ii) = max(detectThreshold(ii),app.minthresholdEditField.Value);
                [~,locs{ii},w{ii},p{ii}] = findpeaks(F(ii,:),'MinPeakHeight',detectThreshold(ii),'MinPeakWidth',minPeakWidth,'WidthReference','halfprom');
                if isempty(locs{ii})
                    w{ii} = 0;
                    p{ii} = 0;
                else
                    w{ii} = w{ii}*dt;
                end
            end
            app.Transients.Locations = locs;
            app.Transients.Widths = w;
            app.Transients.Prominence = p;
            app.displaytraceSpinner.Limits = [1,numel(locs)];
            warning on
            analyzecalciumtransients(app)
            app.displaytraceSpinner.Value = 1;
            displayanalysistrace(app)

        end

        % Button pushed function: loadanalysisresults
        function loadbasicButtonPushed(app, event)
            cnmfOutputsFolder  = [app.DetectFolder,filesep,'CNMF_outputs'];
            [fileName,filePath] = uigetfile([cnmfOutputsFolder,filesep,'*mat'],'Choose a results file');
            figure(app.detectFigure)
            if fileName==0
                return
            end
            app.basicanalysisfilenameEditField.Value = fileName;
            out = load([filePath,fileName]);
            app.Out = out;
            cla(app.UIAxesBasic)
            app.FTemp = app.Out.F_raw;
            analyzeallButtonPushed(app)


        end

        % Value changed function: displaytraceSpinner
        function displaytraceSpinnerValueChanged(app, event)
            app.UIAxesBasic.XLabel.String = 'time (s)';
            app.UIAxesBasic.YLabel.String = '\DeltaF/F';
            displayanalysistrace(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.detectFigure = uifigure('Visible', 'off');
            app.detectFigure.Name = 'NINDS / LCSMS';
            app.TabGroup = uitabgroup(app.detectFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.loadimagesTab = uitab(app.TabGroup);
            app.loadimagesTab.Title = 'load images';
            app.UIAxesLoad = uiaxes(app.loadimagesTab);
            app.UIAxesLoad.XTick = [];
            app.UIAxesLoad.YTick = [];
            app.chooseButton = uibutton(app.loadimagesTab, 'push');
            app.chooseButton.ButtonPushedFcn = createCallbackFcn(app, @chooseButtonPushed, true);
            app.chooseButton.Text = 'choose';
            app.foldernameEditFieldLabel = uilabel(app.loadimagesTab);
            app.foldernameEditFieldLabel.Text = 'folder name';
            app.foldernameEditField = uieditfield(app.loadimagesTab, 'text');
            app.foldernameEditField.Editable = 'off';
            app.folderlocationEditFieldLabel = uilabel(app.loadimagesTab);
            app.folderlocationEditFieldLabel.Text = 'folder location';
            app.folderlocationEditField = uieditfield(app.loadimagesTab, 'text');
            app.folderlocationEditField.Editable = 'off';
            app.addROIButton = uibutton(app.loadimagesTab, 'push');
            app.addROIButton.ButtonPushedFcn = createCallbackFcn(app, @addROIButtonPushed, true);
            app.addROIButton.Enable = 'off';
            app.addROIButton.Text = 'add ROI';
            app.clearLoadButton = uibutton(app.loadimagesTab, 'push');
            app.clearLoadButton.ButtonPushedFcn = createCallbackFcn(app, @clearLoadButtonPushed, true);
            app.clearLoadButton.Enable = 'off';
            app.clearLoadButton.Text = 'clear';
            app.framerateEditFieldLabel = uilabel(app.loadimagesTab);
            app.framerateEditFieldLabel.Enable = 'off';
            app.framerateEditFieldLabel.Text = 'frame rate';
            app.framerateEditField = uieditfield(app.loadimagesTab, 'numeric');
            app.framerateEditField.Limits = [1 10000];
            app.framerateEditField.RoundFractionalValues = 'on';
            app.framerateEditField.ValueDisplayFormat = '%.0f';
            app.framerateEditField.Enable = 'off';
            app.framerateEditField.Value = 10;
            app.useallframesCheckBox = uicheckbox(app.loadimagesTab);
            app.useallframesCheckBox.ValueChangedFcn = createCallbackFcn(app, @useallframesCheckBoxValueChanged, true);
            app.useallframesCheckBox.Enable = 'off';
            app.useallframesCheckBox.Text = 'use all frames';
            app.useallframesCheckBox.Value = true;
            app.framesEditFieldLabel = uilabel(app.loadimagesTab);
            app.framesEditFieldLabel.Enable = 'off';
            app.framesEditFieldLabel.Text = 'frames';
            app.framesEditField = uieditfield(app.loadimagesTab, 'text');
            app.framesEditField.ValueChangedFcn = createCallbackFcn(app, @framesEditFieldValueChanged, true);
            app.framesEditField.Enable = 'off';
            app.previewButton = uibutton(app.loadimagesTab, 'push');
            app.previewButton.ButtonPushedFcn = createCallbackFcn(app, @previewButtonPushed, true);
            app.previewButton.Enable = 'off';
            app.previewButton.Text = 'preview';
            app.acceptROIButton = uibutton(app.loadimagesTab, 'push');
            app.acceptROIButton.ButtonPushedFcn = createCallbackFcn(app, @acceptROIButtonPushed, true);
            app.acceptROIButton.Enable = 'off';
            app.acceptROIButton.Text = 'accept ROI';
            app.adjustcontrastButton = uibutton(app.loadimagesTab, 'push');
            app.adjustcontrastButton.ButtonPushedFcn = createCallbackFcn(app, @adjustcontrastButtonPushed, true);
            app.adjustcontrastButton.Enable = 'off';
            app.adjustcontrastButton.Text = 'adjust contrast';
            app.minEditFieldLabel = uilabel(app.loadimagesTab);
            app.minEditFieldLabel.Enable = 'off';
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.loadimagesTab, 'numeric');
            app.minEditField.Limits = [0 65536];
            app.minEditField.RoundFractionalValues = 'on';
            app.minEditField.ValueDisplayFormat = '%.0f';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.minEditField.Enable = 'off';
            app.maxEditFieldLabel = uilabel(app.loadimagesTab);
            app.maxEditFieldLabel.Enable = 'off';
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.loadimagesTab, 'numeric');
            app.maxEditField.Limits = [0 65536];
            app.maxEditField.RoundFractionalValues = 'on';
            app.maxEditField.ValueDisplayFormat = '%.0f';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.maxEditField.Enable = 'off';
            app.maxEditField.Value = 65536;
            app.bitsEditFieldLabel = uilabel(app.loadimagesTab);
            app.bitsEditFieldLabel.Enable = 'off';
            app.bitsEditFieldLabel.Text = 'bits';
            app.bitsEditField = uieditfield(app.loadimagesTab, 'numeric');
            app.bitsEditField.Limits = [8 16];
            app.bitsEditField.RoundFractionalValues = 'on';
            app.bitsEditField.ValueDisplayFormat = '%.0f';
            app.bitsEditField.Editable = 'off';
            app.bitsEditField.Enable = 'off';
            app.bitsEditField.Value = 16;
            app.saveasaviButton = uibutton(app.loadimagesTab, 'push');
            app.saveasaviButton.ButtonPushedFcn = createCallbackFcn(app, @saveasaviButtonPushed, true);
            app.saveasaviButton.Enable = 'off';
            app.saveasaviButton.Text = 'save as avi';
            app.typeoffilesDropDownLabel = uilabel(app.loadimagesTab);
            app.typeoffilesDropDownLabel.Text = 'type of file(s)';
            app.typeoffilesDropDown = uidropdown(app.loadimagesTab);
            app.typeoffilesDropDown.Items = {'stacked tiff', 'individual tiffs', 'mp4', 'avi'};
            app.typeoffilesDropDown.ValueChangedFcn = createCallbackFcn(app, @typeoffilesDropDownValueChanged, true);
            app.typeoffilesDropDown.Value = 'stacked tiff';
            app.filenameEditFieldLabel = uilabel(app.loadimagesTab);
            app.filenameEditFieldLabel.Text = 'file name';
            app.filenameEditField = uieditfield(app.loadimagesTab, 'text');
            app.filenameEditField.Editable = 'off';
            app.usesavedROIButton = uibutton(app.loadimagesTab, 'push');
            app.usesavedROIButton.ButtonPushedFcn = createCallbackFcn(app, @usesavedROIButtonPushed, true);
            app.usesavedROIButton.Enable = 'off';
            app.usesavedROIButton.Text = 'use saved ROI';
            app.saveROIButton = uibutton(app.loadimagesTab, 'push');
            app.saveROIButton.ButtonPushedFcn = createCallbackFcn(app, @saveROIButtonPushed, true);
            app.saveROIButton.Enable = 'off';
            app.saveROIButton.Text = 'save ROI';
            app.motioncorrectionTab = uitab(app.TabGroup);
            app.motioncorrectionTab.Title = 'motion correction';
            app.UIAxesMC2 = uiaxes(app.motioncorrectionTab);
            zlabel(app.UIAxesMC2, 'Z')
            app.UIAxesMC2.XTick = [];
            app.UIAxesMC2.YTick = [];
            app.UIAxesMC1 = uiaxes(app.motioncorrectionTab);
            zlabel(app.UIAxesMC1, 'Z')
            app.UIAxesMC1.XTick = [];
            app.UIAxesMC1.YTick = [];
            app.specifyavifolderButton = uibutton(app.motioncorrectionTab, 'push');
            app.specifyavifolderButton.ButtonPushedFcn = createCallbackFcn(app, @specifyavifolderButtonPushed, true);
            app.specifyavifolderButton.Text = 'specify avi folder';
            app.motioncorrectionEditField = uieditfield(app.motioncorrectionTab, 'text');
            app.motioncorrectionEditField.Editable = 'off';
            app.runmotioncorrectionButton = uibutton(app.motioncorrectionTab, 'push');
            app.runmotioncorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @runmotioncorrectionButtonPushed, true);
            app.runmotioncorrectionButton.Text = 'run motion correction';
            app.acceptcorrectfilesButton = uibutton(app.motioncorrectionTab, 'push');
            app.acceptcorrectfilesButton.ButtonPushedFcn = createCallbackFcn(app, @acceptcorrectfilesButtonPushed, true);
            app.acceptcorrectfilesButton.Text = 'accept correct files';
            app.originalLabel = uilabel(app.motioncorrectionTab);
            app.originalLabel.Text = 'original';
            app.correctedLabel = uilabel(app.motioncorrectionTab);
            app.correctedLabel.Text = 'corrected';
            app.warpcorrectionDropDownLabel = uilabel(app.motioncorrectionTab);
            app.warpcorrectionDropDownLabel.Text = 'warp correction?';
            app.warpcorrectionDropDown = uidropdown(app.motioncorrectionTab);
            app.warpcorrectionDropDown.Items = {'No', 'Yes'};
            app.warpcorrectionDropDown.Value = 'No';
            app.rigidparametersPanel = uipanel(app.motioncorrectionTab);
            app.rigidparametersPanel.Title = 'rigid parameters';
            app.normalizationmethodDropDownLabel = uilabel(app.rigidparametersPanel);
            app.normalizationmethodDropDownLabel.Text = 'normalization method';
            app.normalizationmethodDropDown = uidropdown(app.rigidparametersPanel);
            app.normalizationmethodDropDown.Items = {'rank', 'local'};
            app.normalizationmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @normalizationmethodDropDownValueChanged, true);
            app.normalizationmethodDropDown.Value = 'rank';
            app.normalizationradiusEditFieldLabel = uilabel(app.rigidparametersPanel);
            app.normalizationradiusEditFieldLabel.Text = 'normalization radius';
            app.normalizationradiusEditField = uieditfield(app.rigidparametersPanel, 'numeric');
            app.normalizationradiusEditField.Limits = [10 300];
            app.normalizationradiusEditField.RoundFractionalValues = 'on';
            app.normalizationradiusEditField.ValueDisplayFormat = '%.0f';
            app.normalizationradiusEditField.Value = 32;
            app.thresholdEditFieldLabel = uilabel(app.rigidparametersPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.rigidparametersPanel, 'numeric');
            app.thresholdEditField.Limits = [0.1 1];
            app.thresholdEditField.ValueDisplayFormat = '%.1f';
            app.thresholdEditField.Value = 0.2;
            app.centralfractionEditFieldLabel = uilabel(app.rigidparametersPanel);
            app.centralfractionEditFieldLabel.Text = 'central fraction';
            app.centralfractionEditField = uieditfield(app.rigidparametersPanel, 'numeric');
            app.centralfractionEditField.Limits = [0.2 1];
            app.centralfractionEditField.ValueDisplayFormat = '%.1f';
            app.centralfractionEditField.Value = 0.8;
            app.fftdenoiseDropDownLabel = uilabel(app.rigidparametersPanel);
            app.fftdenoiseDropDownLabel.Text = 'fft denoise';
            app.fftdenoiseDropDown = uidropdown(app.rigidparametersPanel);
            app.fftdenoiseDropDown.Items = {'false', 'true'};
            app.fftdenoiseDropDown.ValueChangedFcn = createCallbackFcn(app, @fftdenoiseDropDownValueChanged, true);
            app.fftdenoiseDropDown.Value = 'false';
            app.Info_15 = uibutton(app.rigidparametersPanel, 'push');
            app.Info_15.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_15.Tag = 'patchwarp_normalizationMethod';
            app.Info_15.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_15.Text = '';
            app.Info_16 = uibutton(app.rigidparametersPanel, 'push');
            app.Info_16.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_16.Tag = 'patchwarp_normalizationRadius';
            app.Info_16.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_16.Text = '';
            app.Info_17 = uibutton(app.rigidparametersPanel, 'push');
            app.Info_17.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_17.Tag = 'patchwarp_threshold';
            app.Info_17.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_17.Text = '';
            app.Info_18 = uibutton(app.rigidparametersPanel, 'push');
            app.Info_18.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_18.Tag = 'patchwarp_centralFraction';
            app.Info_18.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_18.Text = '';
            app.Info_19 = uibutton(app.rigidparametersPanel, 'push');
            app.Info_19.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_19.Tag = 'patchwarp_fftDenoise';
            app.Info_19.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_19.Text = '';
            app.warpparametersPanel = uipanel(app.motioncorrectionTab);
            app.warpparametersPanel.Title = 'warp parameters';
            app.affineradiusEditFieldLabel = uilabel(app.warpparametersPanel);
            app.affineradiusEditFieldLabel.Text = 'affine radius';
            app.affineradiusEditField = uieditfield(app.warpparametersPanel, 'numeric');
            app.affineradiusEditField.Limits = [10 100];
            app.affineradiusEditField.RoundFractionalValues = 'on';
            app.affineradiusEditField.ValueDisplayFormat = '%.0f';
            app.affineradiusEditField.Value = 32;
            app.movingaveragesizeEditFieldLabel = uilabel(app.warpparametersPanel);
            app.movingaveragesizeEditFieldLabel.Text = 'moving average size';
            app.movingaveragesizeEditField = uieditfield(app.warpparametersPanel, 'numeric');
            app.movingaveragesizeEditField.Limits = [1 10];
            app.movingaveragesizeEditField.RoundFractionalValues = 'on';
            app.movingaveragesizeEditField.ValueDisplayFormat = '%.0f';
            app.movingaveragesizeEditField.Value = 1;
            app.blocksizeEditFieldLabel = uilabel(app.warpparametersPanel);
            app.blocksizeEditFieldLabel.Text = 'block size';
            app.blocksizeEditField = uieditfield(app.warpparametersPanel, 'numeric');
            app.blocksizeEditField.Limits = [1 10];
            app.blocksizeEditField.RoundFractionalValues = 'on';
            app.blocksizeEditField.ValueDisplayFormat = '%.0f';
            app.blocksizeEditField.Value = 2;
            app.Info_20 = uibutton(app.warpparametersPanel, 'push');
            app.Info_20.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_20.Tag = 'patchwarp_affineRadius';
            app.Info_20.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_20.Text = '';
            app.Info_21 = uibutton(app.warpparametersPanel, 'push');
            app.Info_21.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_21.Tag = 'patchwarp_movingAverageSize';
            app.Info_21.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_21.Text = '';
            app.Info_22 = uibutton(app.warpparametersPanel, 'push');
            app.Info_22.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_22.Tag = 'patchwarp_blockSize';
            app.Info_22.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_22.Text = '';
            app.correctedfilesListBoxLabel = uilabel(app.motioncorrectionTab);
            app.correctedfilesListBoxLabel.Text = 'corrected files';
            app.correctedfilesListBox = uilistbox(app.motioncorrectionTab);
            app.correctedfilesListBox.Items = {''};
            app.correctedfilesListBox.Value = '';
            app.playvideosButton = uibutton(app.motioncorrectionTab, 'push');
            app.playvideosButton.ButtonPushedFcn = createCallbackFcn(app, @playvideosButtonPushed, true);
            app.playvideosButton.Text = 'play videos';
            app.chooseMCfolderButton = uibutton(app.motioncorrectionTab, 'push');
            app.chooseMCfolderButton.ButtonPushedFcn = createCallbackFcn(app, @chooseMCfolderButtonPushed, true);
            app.chooseMCfolderButton.Text = 'choose correction folder';
            app.originalfilesListBoxLabel = uilabel(app.motioncorrectionTab);
            app.originalfilesListBoxLabel.Text = 'original files';
            app.originalfilesListBox = uilistbox(app.motioncorrectionTab);
            app.originalfilesListBox.Items = {''};
            app.originalfilesListBox.Value = '';
            app.chooseMCfolderButton_2 = uibutton(app.motioncorrectionTab, 'push');
            app.chooseMCfolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @chooseMCfolderButton_2Pushed, true);
            app.chooseMCfolderButton_2.Text = 'choose original folder';
            app.originalMCfolderEditField = uieditfield(app.motioncorrectionTab, 'text');
            app.originalMCfolderEditField.Editable = 'off';
            app.correctedMCfolderEditField = uieditfield(app.motioncorrectionTab, 'text');
            app.correctedMCfolderEditField.Editable = 'off';
            app.imageregistrationTab = uitab(app.TabGroup);
            app.imageregistrationTab.Title = 'image registration';
            app.applyregistrationparametersPanel = uipanel(app.imageregistrationTab);
            app.applyregistrationparametersPanel.Title = 'apply registration parameters';
            app.loadregistrationparametersButton = uibutton(app.applyregistrationparametersPanel, 'push');
            app.loadregistrationparametersButton.ButtonPushedFcn = createCallbackFcn(app, @loadregistrationparametersButtonPushed, true);
            app.loadregistrationparametersButton.Text = 'load registration parameters';
            app.registrationparametersEditField = uieditfield(app.applyregistrationparametersPanel, 'text');
            app.registrationparametersEditField.Editable = 'off';
            app.runregistrationalgorithmButton = uibutton(app.applyregistrationparametersPanel, 'push');
            app.runregistrationalgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @runregistrationalgorithmButtonPushed, true);
            app.runregistrationalgorithmButton.Text = 'run registration algorithm';
            app.specifyfiletoberegisteredButton = uibutton(app.applyregistrationparametersPanel, 'push');
            app.specifyfiletoberegisteredButton.ButtonPushedFcn = createCallbackFcn(app, @specifyfiletoberegisteredButtonPushed, true);
            app.specifyfiletoberegisteredButton.Text = 'specify file to be registered';
            app.filetoberegisteredEditBox = uieditfield(app.applyregistrationparametersPanel, 'text');
            app.filetoberegisteredEditBox.Editable = 'off';
            app.framerateEditField_2Label = uilabel(app.applyregistrationparametersPanel);
            app.framerateEditField_2Label.Text = 'frame rate';
            app.framerateregistrationEditField = uieditfield(app.applyregistrationparametersPanel, 'numeric');
            app.framerateregistrationEditField.Limits = [1 1000];
            app.framerateregistrationEditField.ValueDisplayFormat = '%.0f';
            app.framerateregistrationEditField.Value = 10;
            app.calculateimageregistrationPanel = uipanel(app.imageregistrationTab);
            app.calculateimageregistrationPanel.Title = 'calculate image registration';
            app.choosereferencefolderButton = uibutton(app.calculateimageregistrationPanel, 'push');
            app.choosereferencefolderButton.ButtonPushedFcn = createCallbackFcn(app, @choosereferencefolderButtonPushed, true);
            app.choosereferencefolderButton.Text = 'choose reference folder';
            app.choosetargetfolderButton = uibutton(app.calculateimageregistrationPanel, 'push');
            app.choosetargetfolderButton.ButtonPushedFcn = createCallbackFcn(app, @choosetargetfolderButtonPushed, true);
            app.choosetargetfolderButton.Text = 'choose target folder';
            app.referencefolderEditField = uieditfield(app.calculateimageregistrationPanel, 'text');
            app.referencefolderEditField.Editable = 'off';
            app.targetfolderEditField = uieditfield(app.calculateimageregistrationPanel, 'text');
            app.targetfolderEditField.Editable = 'off';
            app.calculateregistrationButton = uibutton(app.calculateimageregistrationPanel, 'push');
            app.calculateregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @calculateregistrationButtonPushed, true);
            app.calculateregistrationButton.Text = 'calculate registration';
            app.registrationresultsEditField = uieditfield(app.calculateimageregistrationPanel, 'text');
            app.registrationresultsEditField.Editable = 'off';
            app.initialconditionsTab = uitab(app.TabGroup);
            app.initialconditionsTab.Title = 'initial conditions';
            app.UIAxesInitial = uiaxes(app.initialconditionsTab);
            app.chooseaviButton = uibutton(app.initialconditionsTab, 'push');
            app.chooseaviButton.ButtonPushedFcn = createCallbackFcn(app, @chooseaviButtonPushed, true);
            app.chooseaviButton.Text = 'choose avi';
            app.avifilenameLabel = uilabel(app.initialconditionsTab);
            app.avifilenameLabel.Text = 'avi filename';
            app.avifilenameEditField = uieditfield(app.initialconditionsTab, 'text');
            app.avifilenameEditField.Editable = 'off';
            app.avifolderEditFieldLabel = uilabel(app.initialconditionsTab);
            app.avifolderEditFieldLabel.Text = 'avi folder';
            app.avifolderEditField = uieditfield(app.initialconditionsTab, 'text');
            app.avifolderEditField.Editable = 'off';
            app.estimatednumberEditFieldLabel = uilabel(app.initialconditionsTab);
            app.estimatednumberEditFieldLabel.Text = 'estimated number';
            app.estimatednumberEditField = uieditfield(app.initialconditionsTab, 'numeric');
            app.estimatednumberEditField.Limits = [0 Inf];
            app.estimatednumberEditField.RoundFractionalValues = 'on';
            app.estimatednumberEditField.ValueDisplayFormat = '%.0f';
            app.estimatednumberEditField.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.estimatednumberEditField.Value = 15;
            app.typicaldiameterpixelsEditFieldLabel = uilabel(app.initialconditionsTab);
            app.typicaldiameterpixelsEditFieldLabel.Text = 'typical diameter (pixels)';
            app.typicaldiameterpixelsEditField = uieditfield(app.initialconditionsTab, 'numeric');
            app.typicaldiameterpixelsEditField.Limits = [1 Inf];
            app.typicaldiameterpixelsEditField.RoundFractionalValues = 'on';
            app.typicaldiameterpixelsEditField.ValueDisplayFormat = '%.0f';
            app.typicaldiameterpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.typicaldiameterpixelsEditField.Value = 30;
            app.orderofARdynamicsLabel = uilabel(app.initialconditionsTab);
            app.orderofARdynamicsLabel.Text = 'order of AR dynamics';
            app.pDropDown = uidropdown(app.initialconditionsTab);
            app.pDropDown.Items = {'0', '1', '2'};
            app.pDropDown.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.pDropDown.Value = '1';
            app.spatialdownsamplingLabel = uilabel(app.initialconditionsTab);
            app.spatialdownsamplingLabel.Text = 'spatial downsampling';
            app.spatialdownsamplingDropDown = uidropdown(app.initialconditionsTab);
            app.spatialdownsamplingDropDown.Items = {'1', '2', '3', '4'};
            app.spatialdownsamplingDropDown.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.spatialdownsamplingDropDown.Value = '1';
            app.temporaldownsamplingLabel = uilabel(app.initialconditionsTab);
            app.temporaldownsamplingLabel.Text = 'temporal downsampling';
            app.temporaldownsamplingDropDown = uidropdown(app.initialconditionsTab);
            app.temporaldownsamplingDropDown.Items = {'1', '2', '3', '4'};
            app.temporaldownsamplingDropDown.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.temporaldownsamplingDropDown.Value = '1';
            app.initializationmethodLabel = uilabel(app.initialconditionsTab);
            app.initializationmethodLabel.Text = 'initialization method';
            app.initializationmethodDropDown = uidropdown(app.initialconditionsTab);
            app.initializationmethodDropDown.Items = {'greedy', 'sparse_NMF'};
            app.initializationmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.initializationmethodDropDown.Value = 'greedy';
            app.searchmethodDropDownLabel = uilabel(app.initialconditionsTab);
            app.searchmethodDropDownLabel.Text = 'search method';
            app.searchmethodDropDown = uidropdown(app.initialconditionsTab);
            app.searchmethodDropDown.Items = {'ellipse', 'dilate'};
            app.searchmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.searchmethodDropDown.Value = 'ellipse';
            app.ellipseexpansionfactorDropDownLabel = uilabel(app.initialconditionsTab);
            app.ellipseexpansionfactorDropDownLabel.Text = 'ellipse expansion factor';
            app.ellipseexpansionfactorDropDown = uidropdown(app.initialconditionsTab);
            app.ellipseexpansionfactorDropDown.Items = {'1', '2', '3', '4', '5'};
            app.ellipseexpansionfactorDropDown.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.ellipseexpansionfactorDropDown.Value = '3';
            app.deconvolutionmethodDropDownLabel = uilabel(app.initialconditionsTab);
            app.deconvolutionmethodDropDownLabel.Text = 'deconvolution method';
            app.deconvolutionmethodDropDown = uidropdown(app.initialconditionsTab);
            app.deconvolutionmethodDropDown.Items = {'constrained FOOPSI – CVX', 'constrained FOOPSI - SPGL1', 'MCEM FOOPSI – CVX', 'MCEM FOOPSI – SPGL1', 'MCMC'};
            app.deconvolutionmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.deconvolutionmethodDropDown.Value = 'constrained FOOPSI – CVX';
            app.temporaliterationsDropDownLabel = uilabel(app.initialconditionsTab);
            app.temporaliterationsDropDownLabel.Text = 'temporal iterations';
            app.temporaliterationsDropDown = uidropdown(app.initialconditionsTab);
            app.temporaliterationsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.temporaliterationsDropDown.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.temporaliterationsDropDown.Value = '5';
            app.fudgefactorEditFieldLabel = uilabel(app.initialconditionsTab);
            app.fudgefactorEditFieldLabel.Text = 'fudge factor';
            app.fudgefactorEditField = uieditfield(app.initialconditionsTab, 'numeric');
            app.fudgefactorEditField.Limits = [0.95 1];
            app.fudgefactorEditField.ValueDisplayFormat = '%.2f';
            app.fudgefactorEditField.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.fudgefactorEditField.Value = 0.95;
            app.mergingthresholdEditFieldLabel = uilabel(app.initialconditionsTab);
            app.mergingthresholdEditFieldLabel.Text = 'merging threshold';
            app.mergingthresholdEditField = uieditfield(app.initialconditionsTab, 'numeric');
            app.mergingthresholdEditField.Limits = [0.1 1];
            app.mergingthresholdEditField.ValueDisplayFormat = '%.2f';
            app.mergingthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.mergingthresholdEditField.Value = 0.9;
            app.backgroundcomponentsDropDownLabel = uilabel(app.initialconditionsTab);
            app.backgroundcomponentsDropDownLabel.Text = 'background components';
            app.backgroundcomponentsDropDown = uidropdown(app.initialconditionsTab);
            app.backgroundcomponentsDropDown.Items = {'1', '2', '3'};
            app.backgroundcomponentsDropDown.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.backgroundcomponentsDropDown.Value = '1';
            app.SNRthresholdDropDownLabel = uilabel(app.initialconditionsTab);
            app.SNRthresholdDropDownLabel.Text = 'SNR threshold';
            app.SNRthresholdDropDown = uidropdown(app.initialconditionsTab);
            app.SNRthresholdDropDown.Items = {'1', '2', '3', '4', '5'};
            app.SNRthresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @estimatednumberEditFieldValueChanged, true);
            app.SNRthresholdDropDown.Value = '3';
            app.saveparametersButton = uibutton(app.initialconditionsTab, 'push');
            app.saveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @saveparametersButtonPushed, true);
            app.saveparametersButton.Text = 'save parameters';
            app.loadparametersButton = uibutton(app.initialconditionsTab, 'push');
            app.loadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @loadparametersButtonPushed, true);
            app.loadparametersButton.Text = 'load parameters';
            app.Info_1 = uibutton(app.initialconditionsTab, 'push');
            app.Info_1.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_1.Tag = 'cnmf_orderOfARdynamics';
            app.Info_1.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_1.Text = '';
            app.Info_2 = uibutton(app.initialconditionsTab, 'push');
            app.Info_2.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_2.Tag = 'cnmf_spatialDownsampling';
            app.Info_2.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_2.Text = '';
            app.Info_3 = uibutton(app.initialconditionsTab, 'push');
            app.Info_3.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_3.Tag = 'cnmf_temporalDownsampling';
            app.Info_3.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_3.Text = '';
            app.Info_4 = uibutton(app.initialconditionsTab, 'push');
            app.Info_4.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_4.Tag = 'cnmf_initializationMethod';
            app.Info_4.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_4.Text = '';
            app.Info_5 = uibutton(app.initialconditionsTab, 'push');
            app.Info_5.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_5.Tag = 'cnmf_searchMethod';
            app.Info_5.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_5.Text = '';
            app.Info_6 = uibutton(app.initialconditionsTab, 'push');
            app.Info_6.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_6.Tag = 'cnmf_ellipseExpansionFactor';
            app.Info_6.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_6.Text = '';
            app.Info_7 = uibutton(app.initialconditionsTab, 'push');
            app.Info_7.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_7.Tag = 'cnmf_deconvolutionMethod';
            app.Info_7.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_7.Text = '';
            app.Info_8 = uibutton(app.initialconditionsTab, 'push');
            app.Info_8.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_8.Tag = 'cnmf_temporalIterations';
            app.Info_8.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_8.Text = '';
            app.Info_9 = uibutton(app.initialconditionsTab, 'push');
            app.Info_9.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_9.Tag = 'cnmf_fudgeFactor';
            app.Info_9.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_9.Text = '';
            app.Info_10 = uibutton(app.initialconditionsTab, 'push');
            app.Info_10.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_10.Tag = 'cnmf_mergingThreshold';
            app.Info_10.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_10.Text = '';
            app.Info_11 = uibutton(app.initialconditionsTab, 'push');
            app.Info_11.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_11.Tag = 'cnmf_backgroundComponents';
            app.Info_11.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_11.Text = '';
            app.Info_12 = uibutton(app.initialconditionsTab, 'push');
            app.Info_12.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_12.Tag = 'cnmf_snrThreshold';
            app.Info_12.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_12.Text = '';
            app.Info_29 = uibutton(app.initialconditionsTab, 'push');
            app.Info_29.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_29.Tag = 'cnmf_estimatedNumber';
            app.Info_29.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_29.Text = '';
            app.Info_30 = uibutton(app.initialconditionsTab, 'push');
            app.Info_30.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_30.Tag = 'cnmf_typicalDiameter';
            app.Info_30.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_30.Text = '';
            app.CNMFalgorithmTab = uitab(app.TabGroup);
            app.CNMFalgorithmTab.Title = 'CNMF algorithm';
            app.runButton = uibutton(app.CNMFalgorithmTab, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.chooseavifileButton = uibutton(app.CNMFalgorithmTab, 'push');
            app.chooseavifileButton.ButtonPushedFcn = createCallbackFcn(app, @chooseavifileButtonPushed, true);
            app.chooseavifileButton.Text = 'choose avi file';
            app.chooseparametersfileButton = uibutton(app.CNMFalgorithmTab, 'push');
            app.chooseparametersfileButton.ButtonPushedFcn = createCallbackFcn(app, @chooseparametersfileButtonPushed, true);
            app.chooseparametersfileButton.Text = 'choose parameters file';
            app.avifileEditFieldLabel = uilabel(app.CNMFalgorithmTab);
            app.avifileEditFieldLabel.Text = 'avi file';
            app.avifileEditField = uieditfield(app.CNMFalgorithmTab, 'text');
            app.avifileEditField.Editable = 'off';
            app.detectionparametersfileEditFieldLabel = uilabel(app.CNMFalgorithmTab);
            app.detectionparametersfileEditFieldLabel.Text = 'detection parameters file';
            app.detectionparametersfileEditField = uieditfield(app.CNMFalgorithmTab, 'text');
            app.detectionparametersfileEditField.Editable = 'off';
            app.loadimagesLabel = uilabel(app.CNMFalgorithmTab);
            app.loadimagesLabel.Text = 'load images';
            app.preprocessdataLabel = uilabel(app.CNMFalgorithmTab);
            app.preprocessdataLabel.Text = 'preprocess data';
            app.initializecomponentsLabel = uilabel(app.CNMFalgorithmTab);
            app.initializecomponentsLabel.Text = 'initialize components';
            app.updatespatialcomponentsILabel = uilabel(app.CNMFalgorithmTab);
            app.updatespatialcomponentsILabel.Text = 'update spatial components I';
            app.updatetemporalcomponentsILabel = uilabel(app.CNMFalgorithmTab);
            app.updatetemporalcomponentsILabel.Text = 'update temporal components I';
            app.updatespatialcomponentsIILabel = uilabel(app.CNMFalgorithmTab);
            app.updatespatialcomponentsIILabel.Text = 'update spatial components II';
            app.updatetemporalcomponentsIILabel = uilabel(app.CNMFalgorithmTab);
            app.updatetemporalcomponentsIILabel.Text = 'update temporal components II';
            app.constructtracesLabel = uilabel(app.CNMFalgorithmTab);
            app.constructtracesLabel.Text = 'construct traces';
            app.resetButton = uibutton(app.CNMFalgorithmTab, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.saveButton = uibutton(app.CNMFalgorithmTab, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.classifyneuronsLabel = uilabel(app.CNMFalgorithmTab);
            app.classifyneuronsLabel.Text = 'classify neurons';
            app.GaugeLabel = uilabel(app.CNMFalgorithmTab);
            app.GaugeLabel.Text = 'Gauge';
            app.CNMFGuage = uigauge(app.CNMFalgorithmTab, 'circular');
            app.PercentcompleteLabel = uilabel(app.CNMFalgorithmTab);
            app.PercentcompleteLabel.Text = 'Percent complete';
            app.TimesecLabel = uilabel(app.CNMFalgorithmTab);
            app.TimesecLabel.Text = 'run time (sec)';
            app.CNMFRunTimeEditField = uieditfield(app.CNMFalgorithmTab, 'numeric');
            app.CNMFRunTimeEditField.ValueDisplayFormat = '%.1f';
            app.CNMFRunTimeEditField.Editable = 'off';
            app.applytootherAVIfilesButton = uibutton(app.CNMFalgorithmTab, 'push');
            app.applytootherAVIfilesButton.ButtonPushedFcn = createCallbackFcn(app, @choosedefaultCNMFfileButtonPushed, true);
            app.applytootherAVIfilesButton.Tag = 'applytootheravifiles';
            app.applytootherAVIfilesButton.Text = 'apply to other AVI files';
            app.choosedefaultCNMFfileButton = uibutton(app.CNMFalgorithmTab, 'push');
            app.choosedefaultCNMFfileButton.ButtonPushedFcn = createCallbackFcn(app, @choosedefaultCNMFfileButtonPushed, true);
            app.choosedefaultCNMFfileButton.Tag = 'choosedefaultcnmffile';
            app.choosedefaultCNMFfileButton.Text = 'choose default CNMF file';
            app.refineresultsTab = uitab(app.TabGroup);
            app.refineresultsTab.Title = 'refine results';
            app.UIAxesRefine1 = uiaxes(app.refineresultsTab);
            zlabel(app.UIAxesRefine1, 'Z')
            app.UIAxesRefine1.XTick = [];
            app.UIAxesRefine1.XTickLabel = '';
            app.UIAxesRefine1.YTick = [];
            app.UIAxesRefine1.YTickLabel = '';
            app.UIAxesRefine2 = uiaxes(app.refineresultsTab);
            xlabel(app.UIAxesRefine2, 'Time (sec)')
            ylabel(app.UIAxesRefine2, '\DeltaF/F')
            zlabel(app.UIAxesRefine2, 'Z')
            app.loadresultsButton = uibutton(app.refineresultsTab, 'push');
            app.loadresultsButton.ButtonPushedFcn = createCallbackFcn(app, @loadresultsButtonPushed, true);
            app.loadresultsButton.Text = 'load results';
            app.cellnumberLabel = uilabel(app.refineresultsTab);
            app.cellnumberLabel.Text = 'cell number';
            app.cellnumberDropDown1 = uispinner(app.refineresultsTab);
            app.cellnumberDropDown1.Limits = [1 500];
            app.cellnumberDropDown1.RoundFractionalValues = 'on';
            app.cellnumberDropDown1.ValueDisplayFormat = '%.0f';
            app.cellnumberDropDown1.ValueChangedFcn = createCallbackFcn(app, @cellnumberDropDownValueChanged, true);
            app.cellnumberDropDown1.Value = 1;
            app.refineButtonGroup = uibuttongroup(app.refineresultsTab);
            app.refineButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @refineButtonGroupSelectionChanged, true);
            app.refineButtonGroup.Title = 'refine';
            app.acceptButton = uitogglebutton(app.refineButtonGroup);
            app.acceptButton.Text = 'accept';
            app.acceptButton.Value = true;
            app.rejectButton = uitogglebutton(app.refineButtonGroup);
            app.rejectButton.Text = 'reject';
            app.savechangesButton_2 = uibutton(app.refineresultsTab, 'push');
            app.savechangesButton_2.ButtonPushedFcn = createCallbackFcn(app, @savechangesButtonPushed, true);
            app.savechangesButton_2.Text = 'save changes';
            app.resetButton_2 = uibutton(app.refineresultsTab, 'push');
            app.resetButton_2.ButtonPushedFcn = createCallbackFcn(app, @resetButton_2Pushed, true);
            app.resetButton_2.Text = 'reset';
            app.filenameEditField_2Label = uilabel(app.refineresultsTab);
            app.filenameEditField_2Label.Text = 'file name';
            app.resultsfilenameEditField = uieditfield(app.refineresultsTab, 'text');
            app.resultsfilenameEditField.Editable = 'off';
            app.qualitycontrolPanel = uipanel(app.refineresultsTab);
            app.qualitycontrolPanel.Title = 'quality control';
            app.maxbleachrateEditFieldLabel = uilabel(app.qualitycontrolPanel);
            app.maxbleachrateEditFieldLabel.Text = 'max bleach rate';
            app.maxbleachrateEditField = uieditfield(app.qualitycontrolPanel, 'numeric');
            app.maxbleachrateEditField.Limits = [0.0001 Inf];
            app.maxbleachrateEditField.ValueDisplayFormat = '%.4f';
            app.maxbleachrateEditField.ValueChangedFcn = createCallbackFcn(app, @applyQCCheckBoxValueChanged, true);
            app.maxbleachrateEditField.Value = 0.05;
            app.applyQCCheckBox = uicheckbox(app.qualitycontrolPanel);
            app.applyQCCheckBox.ValueChangedFcn = createCallbackFcn(app, @applyQCCheckBoxValueChanged, true);
            app.applyQCCheckBox.Text = 'apply QC';
            app.minLabel = uilabel(app.qualitycontrolPanel);
            app.minLabel.Text = '%/min';
            app.minnumtransientsEditFieldLabel = uilabel(app.qualitycontrolPanel);
            app.minnumtransientsEditFieldLabel.Text = 'min num transients';
            app.minnumtransientsEditField = uieditfield(app.qualitycontrolPanel, 'numeric');
            app.minnumtransientsEditField.Limits = [0 1000];
            app.minnumtransientsEditField.ValueDisplayFormat = '%.0f';
            app.minnumtransientsEditField.ValueChangedFcn = createCallbackFcn(app, @applyQCCheckBoxValueChanged, true);
            app.minnumtransientsEditField.Value = 1;
            app.mintransientsizeEditFieldLabel = uilabel(app.qualitycontrolPanel);
            app.mintransientsizeEditFieldLabel.Text = 'min transient size';
            app.mintransientsizeEditField = uieditfield(app.qualitycontrolPanel, 'numeric');
            app.mintransientsizeEditField.Limits = [0 100];
            app.mintransientsizeEditField.ValueDisplayFormat = '%.2f';
            app.mintransientsizeEditField.ValueChangedFcn = createCallbackFcn(app, @applyQCCheckBoxValueChanged, true);
            app.mintransientsizeEditField.Value = 0.05;
            app.Label = uilabel(app.qualitycontrolPanel);
            app.Label.Text = '%';
            app.lowesssmoothingEditFieldLabel = uilabel(app.qualitycontrolPanel);
            app.lowesssmoothingEditFieldLabel.Text = 'lowess smoothing';
            app.lowesssmoothingEditField = uieditfield(app.qualitycontrolPanel, 'numeric');
            app.lowesssmoothingEditField.Limits = [0 1000];
            app.lowesssmoothingEditField.ValueDisplayFormat = '%.1f';
            app.lowesssmoothingEditField.ValueChangedFcn = createCallbackFcn(app, @applyQCCheckBoxValueChanged, true);
            app.secLabel = uilabel(app.qualitycontrolPanel);
            app.secLabel.Text = 'sec';
            app.bordersizeEditFieldLabel = uilabel(app.qualitycontrolPanel);
            app.bordersizeEditFieldLabel.Text = 'border size';
            app.bordersizeEditField = uieditfield(app.qualitycontrolPanel, 'numeric');
            app.bordersizeEditField.Limits = [0 1000];
            app.bordersizeEditField.RoundFractionalValues = 'on';
            app.bordersizeEditField.ValueDisplayFormat = '%.0f';
            app.bordersizeEditField.ValueChangedFcn = createCallbackFcn(app, @applyQCCheckBoxValueChanged, true);
            app.pixelsLabel = uilabel(app.qualitycontrolPanel);
            app.pixelsLabel.Text = 'pixels';
            app.placeacellmanuallyButton = uibutton(app.refineresultsTab, 'push');
            app.placeacellmanuallyButton.ButtonPushedFcn = createCallbackFcn(app, @placeacellmanuallyButtonPushed, true);
            app.placeacellmanuallyButton.Text = 'place a cell manually';
            app.acceptmanualcellButton = uibutton(app.refineresultsTab, 'push');
            app.acceptmanualcellButton.ButtonPushedFcn = createCallbackFcn(app, @acceptmanualcellButtonPushed, true);
            app.acceptmanualcellButton.Text = 'accept manual cell';
            app.clicktoselectSwitchLabel = uilabel(app.refineresultsTab);
            app.clicktoselectSwitchLabel.Text = 'click to select';
            app.clicktoselectSwitch = uiswitch(app.refineresultsTab, 'slider');
            app.clicktoselectSwitch.ValueChangedFcn = createCallbackFcn(app, @clicktoselectButtonValueChanged, true);
            app.examineresultsTab = uitab(app.TabGroup);
            app.examineresultsTab.Title = 'examine results';
            app.UIAxesExamine1 = uiaxes(app.examineresultsTab);
            zlabel(app.UIAxesExamine1, 'Z')
            app.UIAxesExamine1.XTick = [];
            app.UIAxesExamine1.XTickLabel = '';
            app.UIAxesExamine1.YTick = [];
            app.UIAxesExamine1.YTickLabel = '';
            app.UIAxesExamine2 = uiaxes(app.examineresultsTab);
            xlabel(app.UIAxesExamine2, 'Time (sec)')
            ylabel(app.UIAxesExamine2, '\DeltaF/F + \delta')
            zlabel(app.UIAxesExamine2, 'Z')
            app.examineresults = uibutton(app.examineresultsTab, 'push');
            app.examineresults.ButtonPushedFcn = createCallbackFcn(app, @examineresultsPushed, true);
            app.examineresults.Text = 'load results';
            app.filenameEditField_2Label_2 = uilabel(app.examineresultsTab);
            app.filenameEditField_2Label_2.Text = 'file name';
            app.resultsfilename2EditField = uieditfield(app.examineresultsTab, 'text');
            app.resultsfilename2EditField.Editable = 'off';
            app.zscoreviewButton = uibutton(app.examineresultsTab, 'state');
            app.zscoreviewButton.ValueChangedFcn = createCallbackFcn(app, @zscoreviewButtonValueChanged, true);
            app.zscoreviewButton.Text = 'z-score view';
            app.basicanalysisTab = uitab(app.TabGroup);
            app.basicanalysisTab.Title = 'basic analysis';
            app.analyzeallButton = uibutton(app.basicanalysisTab, 'push');
            app.analyzeallButton.ButtonPushedFcn = createCallbackFcn(app, @analyzeallButtonPushed, true);
            app.analyzeallButton.Text = 'analyze all';
            app.loadanalysisresults = uibutton(app.basicanalysisTab, 'push');
            app.loadanalysisresults.ButtonPushedFcn = createCallbackFcn(app, @loadbasicButtonPushed, true);
            app.loadanalysisresults.Text = 'load results';
            app.filenameEditField_2Label_8 = uilabel(app.basicanalysisTab);
            app.filenameEditField_2Label_8.Text = 'file name';
            app.basicanalysisfilenameEditField = uieditfield(app.basicanalysisTab, 'text');
            app.basicanalysisfilenameEditField.Editable = 'off';
            app.detectionthresholdDropDownLabel = uilabel(app.basicanalysisTab);
            app.detectionthresholdDropDownLabel.Text = 'detection threshold';
            app.detectionthresholdDropDown = uidropdown(app.basicanalysisTab);
            app.detectionthresholdDropDown.Items = {'1x STD', '2x STD', '3x STD', '4x STD', '5x STD'};
            app.detectionthresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @analyzeallButtonPushed, true);
            app.detectionthresholdDropDown.Value = '3x STD';
            app.minthresholdEditFieldLabel = uilabel(app.basicanalysisTab);
            app.minthresholdEditFieldLabel.Text = 'min threshold';
            app.minthresholdEditField = uieditfield(app.basicanalysisTab, 'numeric');
            app.minthresholdEditField.Limits = [0.01 0.4];
            app.minthresholdEditField.ValueDisplayFormat = '%.3f';
            app.minthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @analyzeallButtonPushed, true);
            app.minthresholdEditField.Value = 0.01;
            app.savechangesButton_4 = uibutton(app.basicanalysisTab, 'push');
            app.savechangesButton_4.ButtonPushedFcn = createCallbackFcn(app, @savechangesButtonPushed, true);
            app.savechangesButton_4.Text = 'save changes';
            app.displaytraceSpinnerLabel = uilabel(app.basicanalysisTab);
            app.displaytraceSpinnerLabel.Text = 'display trace';
            app.displaytraceSpinner = uispinner(app.basicanalysisTab);
            app.displaytraceSpinner.Limits = [1 Inf];
            app.displaytraceSpinner.RoundFractionalValues = 'on';
            app.displaytraceSpinner.ValueDisplayFormat = '%.0f';
            app.displaytraceSpinner.ValueChangedFcn = createCallbackFcn(app, @displaytraceSpinnerValueChanged, true);
            app.displaytraceSpinner.Value = 1;
            app.transientnumberEditFieldLabel = uilabel(app.basicanalysisTab);
            app.transientnumberEditFieldLabel.Text = 'transient number';
            app.transientnumberEditField = uieditfield(app.basicanalysisTab, 'numeric');
            app.transientnumberEditField.Limits = [0 Inf];
            app.transientnumberEditField.ValueDisplayFormat = '%.0f';
            app.transientamplitudeEditFieldLabel = uilabel(app.basicanalysisTab);
            app.transientamplitudeEditFieldLabel.Text = 'transient amplitude';
            app.transientamplitudeEditField = uieditfield(app.basicanalysisTab, 'numeric');
            app.transientamplitudeEditField.Limits = [0 Inf];
            app.transientamplitudeEditField.ValueDisplayFormat = '%.3f';
            app.transientwidthEditFieldLabel = uilabel(app.basicanalysisTab);
            app.transientwidthEditFieldLabel.Text = 'transient width';
            app.transientwidthEditField = uieditfield(app.basicanalysisTab, 'numeric');
            app.transientwidthEditField.Limits = [0 Inf];
            app.transientwidthEditField.ValueDisplayFormat = '%.3f';
            app.TabGroup2 = uitabgroup(app.basicanalysisTab);
            app.tracesTab = uitab(app.TabGroup2);
            app.tracesTab.Title = 'traces';
            app.UIAxesBasic = uiaxes(app.tracesTab);
            xlabel(app.UIAxesBasic, 'time (s)')
            ylabel(app.UIAxesBasic, '\DeltaF/F')
            zlabel(app.UIAxesBasic, 'Z')
            app.histogramsTab = uitab(app.TabGroup2);
            app.histogramsTab.Title = 'histograms';
            app.UIAxesNumber = uiaxes(app.histogramsTab);
            title(app.UIAxesNumber, 'number')
            zlabel(app.UIAxesNumber, 'Z')
            app.UIAxesAmplitude = uiaxes(app.histogramsTab);
            title(app.UIAxesAmplitude, 'amplitude')
            zlabel(app.UIAxesAmplitude, 'Z')
            app.UIAxesWidth = uiaxes(app.histogramsTab);
            title(app.UIAxesWidth, 'width')
            zlabel(app.UIAxesWidth, 'Z')
            app.UIAxesITI = uiaxes(app.histogramsTab);
            title(app.UIAxesITI, 'intertransient interval')
            zlabel(app.UIAxesITI, 'Z')
            app.intertransientintervalEditFieldLabel = uilabel(app.basicanalysisTab);
            app.intertransientintervalEditFieldLabel.Text = 'intertransient interval';
            app.transientITIEditField = uieditfield(app.basicanalysisTab, 'numeric');
            app.transientITIEditField.Limits = [0 Inf];
            app.transientITIEditField.ValueDisplayFormat = '%.3f';
            app.meanvaluesLabel = uilabel(app.basicanalysisTab);
            app.meanvaluesLabel.Text = 'mean values';
            app.pairwisecorrelationsTab = uitab(app.TabGroup);
            app.pairwisecorrelationsTab.Title = 'pairwise correlations';
            app.UIAxesPairwise1 = uiaxes(app.pairwisecorrelationsTab);
            zlabel(app.UIAxesPairwise1, 'Z')
            app.UIAxesPairwise1.XTick = [];
            app.UIAxesPairwise1.XTickLabel = '';
            app.UIAxesPairwise1.YTick = [];
            app.UIAxesPairwise1.YTickLabel = '';
            app.UIAxesPairwise2 = uiaxes(app.pairwisecorrelationsTab);
            title(app.UIAxesPairwise2, 'correlation coefficient dendrogram')
            xlabel(app.UIAxesPairwise2, 'neuron')
            ylabel(app.UIAxesPairwise2, 'distance')
            zlabel(app.UIAxesPairwise2, 'Z')
            app.UIAxesPairwise3 = uiaxes(app.pairwisecorrelationsTab);
            title(app.UIAxesPairwise3, 'correlation matrix')
            xlabel(app.UIAxesPairwise3, 'neuron')
            ylabel(app.UIAxesPairwise3, 'neuron')
            zlabel(app.UIAxesPairwise3, 'Z')
            app.loadpairwisefileButton = uibutton(app.pairwisecorrelationsTab, 'push');
            app.loadpairwisefileButton.ButtonPushedFcn = createCallbackFcn(app, @loadpairwisefileButtonPushed, true);
            app.loadpairwisefileButton.Text = 'load file';
            app.runPairwiseButton = uibutton(app.pairwisecorrelationsTab, 'push');
            app.runPairwiseButton.ButtonPushedFcn = createCallbackFcn(app, @runPairwiseButtonPushed, true);
            app.runPairwiseButton.Text = 'run pairwise';
            app.saveButton_3 = uibutton(app.pairwisecorrelationsTab, 'push');
            app.saveButton_3.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton_3.Text = 'save';
            app.filenameEditField_2Label_4 = uilabel(app.pairwisecorrelationsTab);
            app.filenameEditField_2Label_4.Text = 'file name';
            app.pairwisefilename = uieditfield(app.pairwisecorrelationsTab, 'text');
            app.pairwisefilename.Editable = 'off';
            app.cutoffLabel_2 = uilabel(app.pairwisecorrelationsTab);
            app.cutoffLabel_2.Text = 'cutoff';
            app.PairwiseSlider = uislider(app.pairwisecorrelationsTab);
            app.PairwiseSlider.Limits = [0 5];
            app.PairwiseSlider.ValueChangedFcn = createCallbackFcn(app, @PairwiseSliderValueChanged, true);
            app.zoomwindowButton_3 = uibutton(app.pairwisecorrelationsTab, 'push');
            app.zoomwindowButton_3.ButtonPushedFcn = createCallbackFcn(app, @zoomwindowButtonPushed, true);
            app.zoomwindowButton_3.Tag = 'pairwise';
            app.zoomwindowButton_3.Text = 'zoom window';
            app.crosscorrelationTab = uitab(app.TabGroup);
            app.crosscorrelationTab.Title = 'cross-correlation';
            app.UIAxesCross1 = uiaxes(app.crosscorrelationTab);
            title(app.UIAxesCross1, 'fluorescence')
            xlabel(app.UIAxesCross1, 'time (s)')
            ylabel(app.UIAxesCross1, '\DeltaF/F')
            zlabel(app.UIAxesCross1, 'Z')
            app.UIAxesCross2 = uiaxes(app.crosscorrelationTab);
            title(app.UIAxesCross2, 'cross correlation')
            xlabel(app.UIAxesCross2, 'lags (s)')
            zlabel(app.UIAxesCross2, 'Z')
            app.neuron1ListBoxLabel = uilabel(app.crosscorrelationTab);
            app.neuron1ListBoxLabel.Text = 'neuron 1';
            app.neuron1ListBox = uilistbox(app.crosscorrelationTab);
            app.neuron1ListBox.Items = {};
            app.neuron1ListBox.Multiselect = 'on';
            app.neuron1ListBox.Value = {};
            app.loadfileButton_2 = uibutton(app.crosscorrelationTab, 'push');
            app.loadfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @loadpairwisefileButtonPushed, true);
            app.loadfileButton_2.Text = 'load file';
            app.filenameEditField_2Label_7 = uilabel(app.crosscorrelationTab);
            app.filenameEditField_2Label_7.Text = 'file name';
            app.emdfilename_2 = uieditfield(app.crosscorrelationTab, 'text');
            app.emdfilename_2.Editable = 'off';
            app.saveButton_5 = uibutton(app.crosscorrelationTab, 'push');
            app.saveButton_5.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton_5.Text = 'save';
            app.zoomwindowButton_4 = uibutton(app.crosscorrelationTab, 'push');
            app.zoomwindowButton_4.ButtonPushedFcn = createCallbackFcn(app, @zoomwindowButtonPushed, true);
            app.zoomwindowButton_4.Tag = 'crosscorrelation';
            app.zoomwindowButton_4.Text = 'zoom window';
            app.neuron2ListBoxLabel = uilabel(app.crosscorrelationTab);

            app.neuron2ListBoxLabel.Text = 'neuron 2';
            app.neuron2ListBox = uilistbox(app.crosscorrelationTab);
            app.neuron2ListBox.Items = {''};
            app.neuron2ListBox.Multiselect = 'on';
            app.neuron2ListBox.Value = {''};
            app.calculatecrosscorrelationButton = uibutton(app.crosscorrelationTab, 'push');
            app.calculatecrosscorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @calculatecrosscorrelationButtonPushed, true);
            app.calculatecrosscorrelationButton.Text = 'calculate';
            app.EMDclusteringTab = uitab(app.TabGroup);
            app.EMDclusteringTab.Title = 'EMD clustering';
            app.UIAxesEMD1 = uiaxes(app.EMDclusteringTab);
            zlabel(app.UIAxesEMD1, 'Z')
            app.UIAxesEMD1.XTick = [];
            app.UIAxesEMD1.XTickLabel = '';
            app.UIAxesEMD1.YTick = [];
            app.UIAxesEMD1.YTickLabel = '';
            app.UIAxesEMD2 = uiaxes(app.EMDclusteringTab);
            title(app.UIAxesEMD2, 'EMD dendrogram')
            xlabel(app.UIAxesEMD2, 'X')
            ylabel(app.UIAxesEMD2, 'Y')
            zlabel(app.UIAxesEMD2, 'Z')
            app.UIAxesEMD3 = uiaxes(app.EMDclusteringTab);
            title(app.UIAxesEMD3, 'distance matrix')
            xlabel(app.UIAxesEMD3, 'neuron')
            ylabel(app.UIAxesEMD3, 'neuron')
            zlabel(app.UIAxesEMD3, 'Z')
            app.loadfileButton = uibutton(app.EMDclusteringTab, 'push');
            app.loadfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadpairwisefileButtonPushed, true);
            app.loadfileButton.Text = 'load file';
            app.filenameEditField_2Label_3 = uilabel(app.EMDclusteringTab);
            app.filenameEditField_2Label_3.Text = 'file name';
            app.emdfilename = uieditfield(app.EMDclusteringTab, 'text');
            app.emdfilename.Editable = 'off';
            app.runEMDButton = uibutton(app.EMDclusteringTab, 'push');
            app.runEMDButton.ButtonPushedFcn = createCallbackFcn(app, @runEMDButtonPushed, true);
            app.runEMDButton.Text = 'run EMD';
            app.cutoffLabel = uilabel(app.EMDclusteringTab);
            app.cutoffLabel.Text = 'cutoff';
            app.EMDSlider = uislider(app.EMDclusteringTab);
            app.EMDSlider.Limits = [0 1];
            app.EMDSlider.ValueChangedFcn = createCallbackFcn(app, @EMDSliderValueChanged, true);
            app.saveButton_2 = uibutton(app.EMDclusteringTab, 'push');
            app.saveButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton_2.Text = 'save';
            app.zoomwindowButton_5 = uibutton(app.EMDclusteringTab, 'push');
            app.zoomwindowButton_5.ButtonPushedFcn = createCallbackFcn(app, @zoomwindowButtonPushed, true);
            app.zoomwindowButton_5.Tag = 'emd';
            app.zoomwindowButton_5.Text = 'zoom window';
            app.waterfallplotsTab = uitab(app.TabGroup);
            app.waterfallplotsTab.Title = 'waterfall plots';
            app.UIAxesWaterfall1 = uiaxes(app.waterfallplotsTab);
            zlabel(app.UIAxesWaterfall1, 'Z')
            app.UIAxesWaterfall1.XTick = [];
            app.UIAxesWaterfall1.XTickLabel = '';
            app.UIAxesWaterfall1.YTick = [];
            app.UIAxesWaterfall1.YTickLabel = '';
            app.UIAxesWaterfall2 = uiaxes(app.waterfallplotsTab);
            title(app.UIAxesWaterfall2, 'waterfall')
            xlabel(app.UIAxesWaterfall2, 'time (s)')
            ylabel(app.UIAxesWaterfall2, 'neuron #')
            zlabel(app.UIAxesWaterfall2, '\DeltaF/F')
            app.loadwaterfallButton = uibutton(app.waterfallplotsTab, 'push');
            app.loadwaterfallButton.ButtonPushedFcn = createCallbackFcn(app, @loadpairwisefileButtonPushed, true);
            app.loadwaterfallButton.Text = 'load file';
            app.filenameEditField_2Label_5 = uilabel(app.waterfallplotsTab);
            app.filenameEditField_2Label_5.Text = 'file name';
            app.waterfallfilename = uieditfield(app.waterfallplotsTab, 'text');
            app.waterfallfilename.Editable = 'off';
            app.classificationtypeDropDownLabel = uilabel(app.waterfallplotsTab);
            app.classificationtypeDropDownLabel.Text = 'classification type';
            app.classificationtypeDropDown = uidropdown(app.waterfallplotsTab);
            app.classificationtypeDropDown.Items = {'none', 'pairwise correlation', 'EMD'};
            app.classificationtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @classificationtypeDropDownValueChanged, true);
            app.classificationtypeDropDown.Value = 'none';
            app.zoomwindowButton = uibutton(app.waterfallplotsTab, 'push');
            app.zoomwindowButton.ButtonPushedFcn = createCallbackFcn(app, @zoomwindowButtonPushed, true);
            app.zoomwindowButton.Tag = 'waterfall';
            app.zoomwindowButton.Text = 'zoom window';
            app.spikeestimationTab = uitab(app.TabGroup);
            app.spikeestimationTab.Title = 'spike estimation';
            app.UIAxesSpikes1 = uiaxes(app.spikeestimationTab);
            zlabel(app.UIAxesSpikes1, 'Z')
            app.UIAxesSpikes1.XTick = [];
            app.UIAxesSpikes1.XTickLabel = '';
            app.UIAxesSpikes1.YTick = [];
            app.UIAxesSpikes1.YTickLabel = '';
            app.UIAxesSpikes2 = uiaxes(app.spikeestimationTab);
            title(app.UIAxesSpikes2, 'estimated spike times')
            xlabel(app.UIAxesSpikes2, 'time (s)')
            ylabel(app.UIAxesSpikes2, 'neuron #')
            zlabel(app.UIAxesSpikes2, '% spike probability')
            app.UIAxesSpikes3 = uiaxes(app.spikeestimationTab);
            title(app.UIAxesSpikes3, 'spike train distances')
            xlabel(app.UIAxesSpikes3, 'neuron')
            ylabel(app.UIAxesSpikes3, 'neuron')
            zlabel(app.UIAxesSpikes3, 'Z')
            app.loadinferredButton = uibutton(app.spikeestimationTab, 'push');
            app.loadinferredButton.ButtonPushedFcn = createCallbackFcn(app, @loadpairwisefileButtonPushed, true);
            app.loadinferredButton.Text = 'load file';
            app.filenameEditField_2Label_6 = uilabel(app.spikeestimationTab);
            app.filenameEditField_2Label_6.Text = 'file name';
            app.inferredfilename = uieditfield(app.spikeestimationTab, 'text');
            app.inferredfilename.Editable = 'off';
            app.MLSpikePanel = uipanel(app.spikeestimationTab);
            app.MLSpikePanel.Title = 'ML Spike';
            app.AmplitudeAEditFieldLabel = uilabel(app.MLSpikePanel);
            app.AmplitudeAEditFieldLabel.Text = 'Amplitude A';
            app.AmplitudeAEditField = uieditfield(app.MLSpikePanel, 'numeric');
            app.AmplitudeAEditField.Value = 3;
            app.decaytimetauEditFieldLabel = uilabel(app.MLSpikePanel);
            app.decaytimetauEditFieldLabel.Text = 'decay time tau';
            app.decaytimetauEditField = uieditfield(app.MLSpikePanel, 'numeric');
            app.decaytimetauEditField.Value = 1;
            app.noisesigmaEditFieldLabel = uilabel(app.MLSpikePanel);
            app.noisesigmaEditFieldLabel.Text = 'noise sigma';
            app.noisesigmaEditField = uieditfield(app.MLSpikePanel, 'numeric');
            app.noisesigmaEditField.ValueDisplayFormat = '%.3f';
            app.noisesigmaEditField.Value = 0.01;
            app.nonlinearityDropDownLabel = uilabel(app.MLSpikePanel);
            app.nonlinearityDropDownLabel.Text = 'nonlinearity';
            app.nonlinearityDropDown = uidropdown(app.MLSpikePanel);
            app.nonlinearityDropDown.Items = {'Manual', 'GCaMP6s', 'GCaMP6f', 'OGB', 'Cal520'};
            app.nonlinearityDropDown.ValueChangedFcn = createCallbackFcn(app, @nonlinearityDropDownValueChanged, true);
            app.nonlinearityDropDown.Value = 'Manual';
            app.Info_23 = uibutton(app.MLSpikePanel, 'push');
            app.Info_23.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_23.Tag = 'mlspike_amplitudeA';
            app.Info_23.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_23.Text = '';
            app.Info_24 = uibutton(app.MLSpikePanel, 'push');
            app.Info_24.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_24.Tag = 'mlspike_decayTimeTau';
            app.Info_24.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_24.Text = '';
            app.Info_25 = uibutton(app.MLSpikePanel, 'push');
            app.Info_25.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_25.Tag = 'mlspike_noiseSigma';
            app.Info_25.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_25.Text = '';
            app.Info_26 = uibutton(app.MLSpikePanel, 'push');
            app.Info_26.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_26.Tag = 'mlspike_nonlinearity';
            app.Info_26.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_26.Text = '';
            app.runMLSpikeButton = uibutton(app.MLSpikePanel, 'push');
            app.runMLSpikeButton.ButtonPushedFcn = createCallbackFcn(app, @runMLSpikeButtonPushed, true);
            app.runMLSpikeButton.Text = 'run ML Spike';
            app.baselineminEditFieldLabel = uilabel(app.MLSpikePanel);
            app.baselineminEditFieldLabel.Text = 'baseline min';
            app.baselineminEditField = uieditfield(app.MLSpikePanel, 'numeric');
            app.baselineminEditField.Limits = [-1 10];
            app.baselineminEditField.ValueDisplayFormat = '%.3f';
            app.baselinemaxEditFieldLabel = uilabel(app.MLSpikePanel);
            app.baselinemaxEditFieldLabel.Text = 'baseline max';
            app.baselinemaxEditField = uieditfield(app.MLSpikePanel, 'numeric');
            app.baselinemaxEditField.Limits = [0.001 10];
            app.baselinemaxEditField.ValueDisplayFormat = '%.3f';
            app.baselinemaxEditField.Value = 0.01;
            app.Info_27 = uibutton(app.MLSpikePanel, 'push');
            app.Info_27.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_27.Tag = 'mlspike_baselineMin';
            app.Info_27.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_27.Text = '';
            app.Info_28 = uibutton(app.MLSpikePanel, 'push');
            app.Info_28.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_28.Tag = 'mlspike_baselineMax';
            app.Info_28.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_28.Text = '';
            app.autoMLButton = uibutton(app.MLSpikePanel, 'push');
            app.autoMLButton.ButtonPushedFcn = createCallbackFcn(app, @autoMLButtonPushed, true);
            app.autoMLButton.Text = 'autoML';
            app.Info_31 = uibutton(app.MLSpikePanel, 'push');
            app.Info_31.ButtonPushedFcn = createCallbackFcn(app, @info_ButtonPushed, true);
            app.Info_31.Tag = 'mlspike_autoML';
            app.Info_31.Icon = fullfile(pathToMLAPP, '1200px-Icon-round-Question_mark.svg.png');
            app.Info_31.Text = '';
            app.saveButton_4 = uibutton(app.spikeestimationTab, 'push');
            app.saveButton_4.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton_4.Text = 'save';
            app.spiketrainmetricsPanel = uipanel(app.spikeestimationTab);
            app.spiketrainmetricsPanel.Title = 'spike train metrics';
            app.metricDropDownLabel = uilabel(app.spiketrainmetricsPanel);
            app.metricDropDownLabel.Text = 'metric';
            app.metricDropDown = uidropdown(app.spiketrainmetricsPanel);
            app.metricDropDown.Items = {'Van Rossum', 'Victor & Purpura', 'ISI', 'SPIKE'};
            app.metricDropDown.Value = 'Van Rossum';
            app.runspiketrainmetricButton = uibutton(app.spiketrainmetricsPanel, 'push');
            app.runspiketrainmetricButton.ButtonPushedFcn = createCallbackFcn(app, @runspiketrainmetricButtonPushed, true);
            app.runspiketrainmetricButton.Text = 'run';
            app.timescaleEditFieldLabel = uilabel(app.spiketrainmetricsPanel);
            app.timescaleEditFieldLabel.Text = 'time scale';
            app.timescaleEditField = uieditfield(app.spiketrainmetricsPanel, 'numeric');
            app.timescaleEditField.Limits = [0 Inf];
            app.timescaleEditField.ValueDisplayFormat = '%.1f';
            app.timescaleEditField.Value = 1;
            app.zoomwindowButton_2 = uibutton(app.spikeestimationTab, 'push');
            app.zoomwindowButton_2.ButtonPushedFcn = createCallbackFcn(app, @zoomwindowButtonPushed, true);
            app.zoomwindowButton_2.Tag = 'spikeestimation';
            app.zoomwindowButton_2.Text = 'zoom window';
            app.exportTab = uitab(app.TabGroup);
            app.exportTab.Title = 'export';
            app.figuresPanel = uipanel(app.exportTab);
            app.figuresPanel.Title = 'figures';
            app.exporttoTIFFButton = uibutton(app.figuresPanel, 'push');
            app.exporttoTIFFButton.ButtonPushedFcn = createCallbackFcn(app, @exporttoFIGButtonPushed, true);
            app.exporttoTIFFButton.Tag = 'exportToTIFF';
            app.exporttoTIFFButton.Text = 'export to TIFF';
            app.exporttoEMFButton = uibutton(app.figuresPanel, 'push');
            app.exporttoEMFButton.ButtonPushedFcn = createCallbackFcn(app, @exporttoFIGButtonPushed, true);
            app.exporttoEMFButton.Tag = 'exportToEMF';
            app.exporttoEMFButton.Text = 'export to EMF';
            app.exporttoPNGButton = uibutton(app.figuresPanel, 'push');
            app.exporttoPNGButton.ButtonPushedFcn = createCallbackFcn(app, @exporttoFIGButtonPushed, true);
            app.exporttoPNGButton.Tag = 'exportToPNG';
            app.exporttoPNGButton.Text = 'export to PNG';
            app.analysisPanel = uipanel(app.exportTab);
            app.analysisPanel.Title = 'analysis';
            app.exporttoMATButton = uibutton(app.analysisPanel, 'push');
            app.exporttoMATButton.ButtonPushedFcn = createCallbackFcn(app, @exporttoMATButtonPushed, true);
            app.exporttoMATButton.Tag = 'exportToMAT';
            app.exporttoMATButton.Text = 'export to MAT';
            app.exporttoEXCELButton = uibutton(app.analysisPanel, 'push');
            app.exporttoEXCELButton.ButtonPushedFcn = createCallbackFcn(app, @exporttoMATButtonPushed, true);
            app.exporttoEXCELButton.Tag = 'exportToExcel';
            app.exporttoEXCELButton.Text = 'export to EXCEL';
            app.exporttoCSVButton = uibutton(app.analysisPanel, 'push');
            app.exporttoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @exporttoMATButtonPushed, true);
            app.exporttoCSVButton.Tag = 'exportToCSV';
            app.exporttoCSVButton.Text = 'export to CSV';
            app.savedirectoryEditField = uieditfield(app.exportTab, 'text');
            app.savedirectoryEditField.Editable = 'off';
            app.savedirectoryLabel = uilabel(app.exportTab);
            app.savedirectoryLabel.Text = 'save directory';
            app.DETECTLabel = uilabel(app.detectFigure);
            app.DETECTLabel.Text = 'DETECT';
            % Show the figure after all components are created
            app.detectFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detect(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.detectFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.detectFigure)
        end
    end
end