classdef informationparameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        explanationTextArea       matlab.ui.control.TextArea
        explanationTextAreaLabel  matlab.ui.control.Label
        InformationLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
    end
    
    methods (Access = public)
        
        function [] = fillininfotext(app,tag)
            foo = strfind(tag,'_');
            folderName1 = tag(1:foo-1);
            folderName2 = tag(foo+1:end);
            fileName = ['info_text',filesep,folderName1,filesep,folderName2,'.txt'];
            A = string(fileread(fileName));
            app.explanationTextArea.Value = A;
        end

    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, tag)
            app.CallingApp = mainapp;
            fillininfotext(app,tag)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InformationLabel = uilabel(app.UIFigure);
            app.InformationLabel.Text = 'Information';
            app.explanationTextAreaLabel = uilabel(app.UIFigure);
            app.explanationTextAreaLabel.Text = 'explanation';
            app.explanationTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = informationparameters(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end