classdef zoomwindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        zscoreButton             matlab.ui.control.StateButton
        selectindividualsButton  matlab.ui.control.StateButton
        clearButton              matlab.ui.control.Button
        zoomwindowLabel          matlab.ui.control.Label
        cluster12Button          matlab.ui.control.StateButton
        cluster11Button          matlab.ui.control.StateButton
        cluster10Button          matlab.ui.control.StateButton
        cluster9Button           matlab.ui.control.StateButton
        cluster8Button           matlab.ui.control.StateButton
        cluster7Button           matlab.ui.control.StateButton
        cluster6Button           matlab.ui.control.StateButton
        cluster5Button           matlab.ui.control.StateButton
        cluster4Button           matlab.ui.control.StateButton
        cluster3Button           matlab.ui.control.StateButton
        cluster2Button           matlab.ui.control.StateButton
        cluster1Button           matlab.ui.control.StateButton
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes1                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp
        ClusterNo
        ClusterColors
        TraceIdx
        ROICenter2
    end
    
    properties (Access = public)
        ROICenter % Description
    end
        
    methods (Access = private)
        
        function [] = populatebuttons(app)

            [C,IA,IC] = unique(app.CallingApp.WaterfallColors,'rows');
            for ii = 1:12
                clusterName = ['cluster',num2str(ii),'Button'];
                if ii <= numel(IA)
                    app.(clusterName).FontColor = C(ii,:);
                    app.(clusterName).Visible = true;
                    app.(clusterName).Enable = true;
                else
                    app.(clusterName).Visible = false;
                    app.(clusterName).Enable = false;
                end
            end
            app.ClusterNo = IC;
            app.ClusterColors = C;
            app.TraceIdx = false(numel(IC),1);

        end
        
        function [] = plottraces(app)

            zscore = app.zscoreButton.Value;
            cla(app.UIAxes2)
            idx = find(app.TraceIdx);
            col = app.CallingApp.WaterfallColors(idx,:);
            hold(app.UIAxes2,'on')
            for ii = 1:numel(idx)
                if zscore
                    foo = (app.CallingApp.Out.F_raw(idx(ii),:) - mean(app.CallingApp.Out.F_raw(idx(ii),:)))/std(app.CallingApp.Out.F_raw(idx(ii),:)); 
                    plot(app.UIAxes2,app.CallingApp.Out.t,foo+(ii-1)*4,'color',col(ii,:))                    
                else
                    plot(app.UIAxes2,app.CallingApp.Out.t,app.CallingApp.Out.F_raw(idx(ii),:)+(ii-1)*0.4,'color',col(ii,:))
                end
            end


        end
        
        
        function [] = plotcontours(app)

            ax = app.UIAxes1;
            if isempty(app.CallingApp.Out), return, end
            cla(ax)
            imshow(app.CallingApp.Out.meanImage,'Parent',ax)
            hold(ax,"on")
            CC = app.CallingApp.Out.CC;
            nROI = numel(CC);
            col = jet(nROI);
            for ii = 1:nROI
                idx = find(CC{ii}(1,2:end)<1);
                CC{ii}(:,idx+1) = CC{ii}(:,idx+2);
                plot(ax,CC{ii}(1,2:end),CC{ii}(2,2:end),'b');
                fill(ax,CC{ii}(1,2:end),CC{ii}(2,2:end),col(ii,:),'FaceAlpha',0.1);
            end

        end
    end
    
    methods (Access = public)
        
        function [] = OnBrushActionPostCallback(app,~,~)
            
            b = app.ROICenter2;
            x = b.BrushData;
            x = find(x);
            app.TraceIdx(x) = ~app.TraceIdx(x);
            b.BrushData = b.BrushData * 0;
            for ii = 1:numel(x)
                idx = app.TraceIdx(x(ii));
                if idx
                    set(app.ROICenter(x(ii)),'MarkerSize',12)
                else
                    set(app.ROICenter(x(ii)),'MarkerSize',6)
                end
            end
            plottraces(app)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            if isempty(app.CallingApp.Out), return, end
            ax1 = app.UIAxes1;
            plotcontours(app)
            hold(ax1,"on")
            N = numel(app.CallingApp.Out.ROI_center(:,1));
            for ii = 1:N
                col = app.CallingApp.WaterfallColors(ii,:);
                app.ROICenter(ii) = plot(ax1,app.CallingApp.Out.ROI_center(ii,1),app.CallingApp.Out.ROI_center(ii,2),'o','Color',col,'markersize',6,'markerfacecolor',col);
            end
            app.ROICenter2 = scatter(ax1,app.CallingApp.Out.ROI_center(:,1),app.CallingApp.Out.ROI_center(:,2),12);
            app.ROICenter2.MarkerEdgeAlpha = 0;
            populatebuttons(app)
            disableDefaultInteractivity(ax1)


        end

        % Value changed function: cluster10Button, cluster11Button, 
        % ...and 10 other components
        function clusterButton_1ValueChanged(app, event)
            name = split(event.Source.Text);
            num = str2double(name{2});
            idx = find(app.ClusterNo==num);
            app.TraceIdx(idx) = ~app.TraceIdx(idx);
            plottraces(app)

    
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            app.TraceIdx = false(numel(app.ClusterNo),1);
            cla(app.UIAxes2)
            N = numel(app.CallingApp.Out.ROI_center(:,1));
            for ii = 1:N
                set(app.ROICenter(ii),'MarkerSize',6);

            end
            [~,IA,~] = unique(app.CallingApp.WaterfallColors,'rows');
            for ii = 1:numel(IA)
                clusterName = ['cluster',num2str(ii),'Button'];
                app.(clusterName).Value = false;
            end
            app.selectindividualsButton.Value = false;

        end

        % Value changed function: selectindividualsButton
        function selectindividualsButtonValueChanged(app, event)
            persistent brushHandle

            value = app.selectindividualsButton.Value;
            if value
                ax = app.UIAxes1;
                brushHandle = brush(ax);
                brushHandle.Color = 'r';
                brushHandle.Enable = 'on';
                brushHandle.ActionPostCallback = @app.OnBrushActionPostCallback;
            else
                brushHandle.Enable = 'off';
            end


        end

        % Value changed function: zscoreButton
        function zscoreButtonPushed(app, event)
            plottraces(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'traces')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.cluster1Button = uibutton(app.UIFigure, 'state');
            app.cluster1Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cluster1Button.Tag = 'cluster1';
            app.cluster1Button.Text = 'cluster 1';
            app.cluster2Button = uibutton(app.UIFigure, 'state');
            app.cluster2Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cluster2Button.Tag = 'cluster2';
            app.cluster2Button.Text = 'cluster 2';
            app.cluster3Button = uibutton(app.UIFigure, 'state');
            app.cluster3Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cluster3Button.Tag = 'cluster3';
            app.cluster3Button.Text = 'cluster 3';
            app.cluster4Button = uibutton(app.UIFigure, 'state');
            app.cluster4Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cluster4Button.Tag = 'cluster4';
            app.cluster4Button.Text = 'cluster 4';
            app.cluster5Button = uibutton(app.UIFigure, 'state');
            app.cluster5Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cluster5Button.Tag = 'cluster5';
            app.cluster5Button.Text = 'cluster 5';
            app.cluster6Button = uibutton(app.UIFigure, 'state');
            app.cluster6Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cluster6Button.Tag = 'cluster6';
            app.cluster6Button.Text = 'cluster 6';
            app.cluster7Button = uibutton(app.UIFigure, 'state');
            app.cluster7Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cluster7Button.Tag = 'cluster7';
            app.cluster7Button.Text = 'cluster 7';
            app.cluster8Button = uibutton(app.UIFigure, 'state');
            app.cluster8Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cluster8Button.Tag = 'cluster8';
            app.cluster8Button.Text = 'cluster 8';
            app.cluster9Button = uibutton(app.UIFigure, 'state');
            app.cluster9Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cluster9Button.Tag = 'cluster9';
            app.cluster9Button.Text = 'cluster 9';
            app.cluster10Button = uibutton(app.UIFigure, 'state');
            app.cluster10Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cluster10Button.Tag = 'cluster10';
            app.cluster10Button.Text = 'cluster 10';
            app.cluster11Button = uibutton(app.UIFigure, 'state');
            app.cluster11Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cluster11Button.Tag = 'cluster11';
            app.cluster11Button.Text = 'cluster 11';
            app.cluster12Button = uibutton(app.UIFigure, 'state');
            app.cluster12Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cluster12Button.Tag = 'cluster12';
            app.cluster12Button.Text = 'cluster 12';
            app.zoomwindowLabel = uilabel(app.UIFigure);
            app.zoomwindowLabel.Text = 'zoom window';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.selectindividualsButton = uibutton(app.UIFigure, 'state');
            app.selectindividualsButton.ValueChangedFcn = createCallbackFcn(app, @selectindividualsButtonValueChanged, true);
            app.selectindividualsButton.Text = 'select individuals';
            app.zscoreButton = uibutton(app.UIFigure, 'state');
            app.zscoreButton.ValueChangedFcn = createCallbackFcn(app, @zscoreButtonPushed, true);
            app.zscoreButton.Text = 'z score';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zoomwindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end