classdef zoomwindow1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        zscoreButton             matlab.ui.control.StateButton
        cellNDropDown            matlab.ui.control.DropDown
        cellNButton              matlab.ui.control.Button
        cell42Button             matlab.ui.control.StateButton
        cell41Button             matlab.ui.control.StateButton
        cell40Button             matlab.ui.control.StateButton
        cell39Button             matlab.ui.control.StateButton
        cell38Button             matlab.ui.control.StateButton
        cell37Button             matlab.ui.control.StateButton
        cell36Button             matlab.ui.control.StateButton
        cell35Button             matlab.ui.control.StateButton
        cell34Button             matlab.ui.control.StateButton
        cell33Button             matlab.ui.control.StateButton
        cell32Button             matlab.ui.control.StateButton
        cell31Button             matlab.ui.control.StateButton
        cell30Button             matlab.ui.control.StateButton
        cell29Button             matlab.ui.control.StateButton
        cell28Button             matlab.ui.control.StateButton
        cell27Button             matlab.ui.control.StateButton
        cell26Button             matlab.ui.control.StateButton
        cell25Button             matlab.ui.control.StateButton
        cell24Button             matlab.ui.control.StateButton
        cell23Button             matlab.ui.control.StateButton
        cell22Button             matlab.ui.control.StateButton
        cell21Button             matlab.ui.control.StateButton
        cell20Button             matlab.ui.control.StateButton
        cell19Button             matlab.ui.control.StateButton
        cell18Button             matlab.ui.control.StateButton
        cell17Button             matlab.ui.control.StateButton
        cell12Button             matlab.ui.control.StateButton
        cell11Button             matlab.ui.control.StateButton
        selectindividualsButton  matlab.ui.control.StateButton
        cell6Button              matlab.ui.control.StateButton
        cell5Button              matlab.ui.control.StateButton
        cell16Button             matlab.ui.control.StateButton
        cell15Button             matlab.ui.control.StateButton
        cell14Button             matlab.ui.control.StateButton
        cell13Button             matlab.ui.control.StateButton
        cell10Button             matlab.ui.control.StateButton
        cell9Button              matlab.ui.control.StateButton
        cell8Button              matlab.ui.control.StateButton
        cell7Button              matlab.ui.control.StateButton
        cell4Button              matlab.ui.control.StateButton
        cell3Button              matlab.ui.control.StateButton
        cell2Button              matlab.ui.control.StateButton
        cell1Button              matlab.ui.control.StateButton
        clearButton              matlab.ui.control.Button
        zoomwindowLabel          matlab.ui.control.Label
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes1                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp
        CellNo
        CellColors
        TraceIdx
        ROICenter2
        TextNumbers
        Property8 % Description
    end
    
    properties (Access = public)
        ROICenter % Description
    end
        
    methods (Access = private)
        
        function [] = populatebuttons(app)

            N = size(app.CallingApp.Out.F_raw,1);
            for ii = 1:42
                cellName = ['cell',num2str(ii),'Button'];
                if ii <= N
                    app.(cellName).Visible = true;
                    app.(cellName).Enable = true;
                else
                    app.(cellName).Visible = false;
                    app.(cellName).Enable = false;
                end
            end
            if N>42
                app.cellNButton.Visible = true;
                app.cellNButton.Enable = true;
                app.cellNDropDown.Items = string(num2cell(43:68,1));
                app.cellNDropDown.Value = '43';
            else
                app.cellNButton.Visible = false;
                app.cellNButton.Enable = false;
                app.cellNDropDown.Visible = false;
                app.cellNDropDown.Enable = false;
            end 
            app.TraceIdx = false(N,1);
            app.CellNo = 1:N;

        end
        
        function [hPlot] = plottraces(app)

            cla(app.UIAxes2)
            idx = find(app.TraceIdx);
            hold(app.UIAxes2,'on')
            N = numel(app.CallingApp.Out.ROI_center(:,1));
            col = jet(N);
            zscore = app.zscoreButton.Value;
            spkEst = isfield(app.CallingApp.Out,'spikeEstimate');
            hPlot = [];
            for ii = 1:numel(idx)
                if zscore
                    foo = (app.CallingApp.Out.F_raw(idx(ii),:) - mean(app.CallingApp.Out.F_raw(idx(ii),:)))/std(app.CallingApp.Out.F_raw(idx(ii),:)); 
                    hPlot(ii) = plot(app.UIAxes2,app.CallingApp.Out.t,foo+(ii-1)*4,'color',col(idx(ii),:)); %#ok<*AGROW>
                else
                    hPlot(ii) = plot(app.UIAxes2,app.CallingApp.Out.t,app.CallingApp.Out.F_raw(idx(ii),:)+(ii-1)*0.4,'Color',col(idx(ii),:));            
                end
                if spkEst
                    spk = app.CallingApp.Out.spikeEstimate{idx(ii)};
                    if ~isempty(spk)
                        plot(app.UIAxes2,spk,(ii-1)*0.4,'o','color',col(idx(ii),:),'markerfacecolor',col(idx(ii),:),'MarkerSize',4)
                    end
                end
            end

        end
        
        
        function [] = plotcontours(app)

            ax = app.UIAxes1;
            if isempty(app.CallingApp.Out), return, end
            cla(ax)
            imshow(app.CallingApp.Out.meanImage,'Parent',ax)
            hold(ax,"on")
            CC = app.CallingApp.Out.CC;
            nROI = numel(CC);
            col = jet(nROI);
            for ii = 1:nROI
                idx = find(CC{ii}(1,2:end)<1);
                CC{ii}(:,idx+1) = CC{ii}(:,idx+2);
                plot(ax,CC{ii}(1,2:end),CC{ii}(2,2:end),'b');
                fill(ax,CC{ii}(1,2:end),CC{ii}(2,2:end),col(ii,:),'FaceAlpha',0.1);
            end
        end

    end
    
    methods (Access = public)
        
        function [] = OnBrushActionPostCallback(app,~,~)
            
            b = app.ROICenter2;
            x = b.BrushData;
            x = find(x);
            app.TraceIdx(x) = ~app.TraceIdx(x);
            b.BrushData = b.BrushData * 0;
            for ii = 1:numel(x)
                idx = app.TraceIdx(x(ii));
                if idx
                    set(app.ROICenter(x(ii)),'MarkerSize',12)
                else
                    set(app.ROICenter(x(ii)),'MarkerSize',8)
                end
                cellName = ['cell',num2str(x(ii)),'Button'];
                app.(cellName).Value = true;
            end
            plottraces(app);

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            if isempty(app.CallingApp.Out), return, end
            ax1 = app.UIAxes1;
            plotcontours(app)
            hold(ax1,'on')
            N = numel(app.CallingApp.Out.ROI_center(:,1));
            col = jet(N);
            for ii = 1:N
                app.ROICenter(ii) = plot(ax1,app.CallingApp.Out.ROI_center(ii,1),app.CallingApp.Out.ROI_center(ii,2),'o','markersize',8,'Color',col(ii,:),'MarkerFaceColor',col(ii,:));
            end
            app.ROICenter2 = scatter(ax1,app.CallingApp.Out.ROI_center(:,1),app.CallingApp.Out.ROI_center(:,2),12);
            app.ROICenter2.MarkerEdgeAlpha = 0;
            populatebuttons(app)
            disableDefaultInteractivity(ax1)
            app.TextNumbers = [];

        end

        % Callback function: cell10Button, cell11Button, cell12Button, 
        % ...and 40 other components
        function clusterButton_1ValueChanged(app, event)
            name = split(event.Source.Text);
            num = str2double(name{2});
            if isnan(num)   % cell N button pushed
                num = str2num(app.cellNDropDown.Value); %#ok<ST2NM>
            end
            idx = find(app.CellNo==num);
            app.TraceIdx(idx) = ~app.TraceIdx(idx);
            hPlot = plottraces(app); %#ok<NASGU>
            axes(app.UIAxes1)
            hold on
            tx = text(app.UIAxes1,app.CallingApp.Out.ROI_center(idx,1),app.CallingApp.Out.ROI_center(idx,2),num2str(num));
            tx.FontSize = 20;
            tx.FontWeight = 'bold';
            app.TextNumbers(end+1) = tx;

        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            app.TraceIdx = false(numel(app.CellNo),1);
            cla(app.UIAxes2)
            N = size(app.CallingApp.Out.F_raw,1);
            for ii = 1:N
                cellName = ['cell',num2str(ii),'Button'];
                if ii <= N
                    app.(cellName).Value = false;
                end
                set(app.ROICenter(ii),'MarkerSize',8)
            end
            app.selectindividualsButton.Value = false;
            delete(app.TextNumbers)
            app.TextNumbers = [];


        end

        % Value changed function: selectindividualsButton
        function selectindividualsButtonValueChanged(app, event)
            persistent brushHandle

            value = app.selectindividualsButton.Value;
            if value
                ax = app.UIAxes1;
                brushHandle = brush(ax);
                brushHandle.Color = 'r';
                brushHandle.Enable = 'on';
                brushHandle.ActionPostCallback = @app.OnBrushActionPostCallback;
            else
                brushHandle.Enable = 'off';
            end


        end

        % Value changed function: zscoreButton
        function zscoreButtonValueChanged(app, event)
            plottraces(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'traces')
            xlabel(app.UIAxes2, 'time (s)')
            ylabel(app.UIAxes2, '\DeltaF/F')
            zlabel(app.UIAxes2, 'Z')
            app.zoomwindowLabel = uilabel(app.UIFigure);
            app.zoomwindowLabel.Text = 'zoom window';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.cell1Button = uibutton(app.UIFigure, 'state');
            app.cell1Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell1Button.Tag = 'cell1';
            app.cell1Button.Text = 'cell 1';
            app.cell2Button = uibutton(app.UIFigure, 'state');
            app.cell2Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell2Button.Tag = 'cell2';
            app.cell2Button.Text = 'cell 2';
            app.cell3Button = uibutton(app.UIFigure, 'state');
            app.cell3Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell3Button.Tag = 'cell3';
            app.cell3Button.Text = 'cell 3';
            app.cell4Button = uibutton(app.UIFigure, 'state');
            app.cell4Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell4Button.Tag = 'cell4';
            app.cell4Button.Text = 'cell 4';
            app.cell7Button = uibutton(app.UIFigure, 'state');
            app.cell7Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell7Button.Tag = 'cluster5';
            app.cell7Button.Text = 'cell 7';
            app.cell8Button = uibutton(app.UIFigure, 'state');
            app.cell8Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell8Button.Tag = 'cluster6';
            app.cell8Button.Text = 'cell 8';
            app.cell9Button = uibutton(app.UIFigure, 'state');
            app.cell9Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell9Button.Tag = 'cluster7';
            app.cell9Button.Text = 'cell 9';
            app.cell10Button = uibutton(app.UIFigure, 'state');
            app.cell10Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell10Button.Tag = 'cluster8';
            app.cell10Button.Text = 'cell 10';
            app.cell13Button = uibutton(app.UIFigure, 'state');
            app.cell13Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell13Button.Tag = 'cluster9';
            app.cell13Button.Text = 'cell 13';
            app.cell14Button = uibutton(app.UIFigure, 'state');
            app.cell14Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell14Button.Tag = 'cluster10';
            app.cell14Button.Text = 'cell 14';
            app.cell15Button = uibutton(app.UIFigure, 'state');
            app.cell15Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell15Button.Tag = 'cluster11';
            app.cell15Button.Text = 'cell 15';
            app.cell16Button = uibutton(app.UIFigure, 'state');
            app.cell16Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell16Button.Tag = 'cluster12';
            app.cell16Button.Text = 'cell 16';
            app.cell5Button = uibutton(app.UIFigure, 'state');
            app.cell5Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell5Button.Tag = 'cell5';
            app.cell5Button.Text = 'cell 5';
            app.cell6Button = uibutton(app.UIFigure, 'state');
            app.cell6Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell6Button.Tag = 'cell6';
            app.cell6Button.Text = 'cell 6';
            app.selectindividualsButton = uibutton(app.UIFigure, 'state');
            app.selectindividualsButton.ValueChangedFcn = createCallbackFcn(app, @selectindividualsButtonValueChanged, true);
            app.selectindividualsButton.Text = 'select individuals';
            app.cell11Button = uibutton(app.UIFigure, 'state');
            app.cell11Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell11Button.Tag = 'cluster3';
            app.cell11Button.Text = 'cell 11';
            app.cell12Button = uibutton(app.UIFigure, 'state');
            app.cell12Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell12Button.Tag = 'cluster4';
            app.cell12Button.Text = 'cell 12';
            app.cell17Button = uibutton(app.UIFigure, 'state');
            app.cell17Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell17Button.Tag = 'cluster3';
            app.cell17Button.Text = 'cell 17';
            app.cell18Button = uibutton(app.UIFigure, 'state');
            app.cell18Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell18Button.Tag = 'cluster4';
            app.cell18Button.Text = 'cell 18';
            app.cell19Button = uibutton(app.UIFigure, 'state');
            app.cell19Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell19Button.Tag = 'cluster9';
            app.cell19Button.Text = 'cell 19';
            app.cell20Button = uibutton(app.UIFigure, 'state');
            app.cell20Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell20Button.Tag = 'cluster10';
            app.cell20Button.Text = 'cell 20';
            app.cell21Button = uibutton(app.UIFigure, 'state');
            app.cell21Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell21Button.Tag = 'cluster11';
            app.cell21Button.Text = 'cell 21';
            app.cell22Button = uibutton(app.UIFigure, 'state');
            app.cell22Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell22Button.Tag = 'cluster12';
            app.cell22Button.Text = 'cell 22';
            app.cell23Button = uibutton(app.UIFigure, 'state');
            app.cell23Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell23Button.Tag = 'cluster3';
            app.cell23Button.Text = 'cell 23';
            app.cell24Button = uibutton(app.UIFigure, 'state');
            app.cell24Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell24Button.Tag = 'cluster4';
            app.cell24Button.Text = 'cell 24';
            app.cell25Button = uibutton(app.UIFigure, 'state');
            app.cell25Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell25Button.Tag = 'cluster9';
            app.cell25Button.Text = 'cell 25';
            app.cell26Button = uibutton(app.UIFigure, 'state');
            app.cell26Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell26Button.Tag = 'cluster10';
            app.cell26Button.Text = 'cell 26';
            app.cell27Button = uibutton(app.UIFigure, 'state');
            app.cell27Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell27Button.Tag = 'cluster11';
            app.cell27Button.Text = 'cell 27';
            app.cell28Button = uibutton(app.UIFigure, 'state');
            app.cell28Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell28Button.Tag = 'cluster12';
            app.cell28Button.Text = 'cell 28';
            app.cell29Button = uibutton(app.UIFigure, 'state');
            app.cell29Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell29Button.Tag = 'cluster3';
            app.cell29Button.Text = 'cell 29';
            app.cell30Button = uibutton(app.UIFigure, 'state');
            app.cell30Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell30Button.Tag = 'cluster4';
            app.cell30Button.Text = 'cell 30';
            app.cell31Button = uibutton(app.UIFigure, 'state');
            app.cell31Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell31Button.Tag = 'cluster9';
            app.cell31Button.Text = 'cell 31';
            app.cell32Button = uibutton(app.UIFigure, 'state');
            app.cell32Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell32Button.Tag = 'cluster10';
            app.cell32Button.Text = 'cell 32';
            app.cell33Button = uibutton(app.UIFigure, 'state');
            app.cell33Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell33Button.Tag = 'cluster11';
            app.cell33Button.Text = 'cell 33';
            app.cell34Button = uibutton(app.UIFigure, 'state');
            app.cell34Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell34Button.Tag = 'cluster12';
            app.cell34Button.Text = 'cell 34';
            app.cell35Button = uibutton(app.UIFigure, 'state');
            app.cell35Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell35Button.Tag = 'cluster3';
            app.cell35Button.Text = 'cell 35';
            app.cell36Button = uibutton(app.UIFigure, 'state');
            app.cell36Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell36Button.Tag = 'cluster4';
            app.cell36Button.Text = 'cell 36';
            app.cell37Button = uibutton(app.UIFigure, 'state');
            app.cell37Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell37Button.Tag = 'cluster9';
            app.cell37Button.Text = 'cell 37';
            app.cell38Button = uibutton(app.UIFigure, 'state');
            app.cell38Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell38Button.Tag = 'cluster10';
            app.cell38Button.Text = 'cell 38';
            app.cell39Button = uibutton(app.UIFigure, 'state');
            app.cell39Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell39Button.Tag = 'cluster11';
            app.cell39Button.Text = 'cell 39';
            app.cell40Button = uibutton(app.UIFigure, 'state');
            app.cell40Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell40Button.Tag = 'cluster12';
            app.cell40Button.Text = 'cell 40';
            app.cell41Button = uibutton(app.UIFigure, 'state');
            app.cell41Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell41Button.Tag = 'cluster3';
            app.cell41Button.Text = 'cell 41';
            app.cell42Button = uibutton(app.UIFigure, 'state');
            app.cell42Button.ValueChangedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cell42Button.Tag = 'cluster4';
            app.cell42Button.Text = 'cell 42';
            app.cellNButton = uibutton(app.UIFigure, 'push');
            app.cellNButton.ButtonPushedFcn = createCallbackFcn(app, @clusterButton_1ValueChanged, true);
            app.cellNButton.Text = 'cell N';
            app.cellNDropDown = uidropdown(app.UIFigure);
            app.cellNDropDown.Items = {'43'};
            app.cellNDropDown.Value = '43';
            app.zscoreButton = uibutton(app.UIFigure, 'state');
            app.zscoreButton.ValueChangedFcn = createCallbackFcn(app, @zscoreButtonValueChanged, true);
            app.zscoreButton.Text = 'z score';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zoomwindow1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end